classdef makeIDfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIMakeId                     matlab.ui.Figure
        button_save                  matlab.ui.control.Button
        chk_Format                   matlab.ui.control.CheckBox
        select_Type2                 matlab.ui.control.DropDown
        Probe2typeareaDropDownLabel  matlab.ui.control.Label
        edit_Area2                   matlab.ui.control.EditField
        edit_Area1                   matlab.ui.control.EditField
        select_Type1                 matlab.ui.control.DropDown
        Probe1typeareaDropDownLabel  matlab.ui.control.Label
        edit_ExpName                 matlab.ui.control.EditField
        ExpnameEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainGui % Description
    end
    
    methods (Access = private)
        
        function recprobe = makeProbe(app,type)
            %run probe function to get info
            %need to sort these according to intan channel for the rest to
            %make sense
            probewiring=feval(['probeConfig_' type]);
            probewiring=sortrows(probewiring);
            recprobe.channels = probewiring(:,1);
            recprobe.x = probewiring(:,2);
            recprobe.y = probewiring(:,3);
            recprobe.z = probewiring(:,4);
            recprobe.shaft = probewiring(:,5);

            recprobe.type = type;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            [sDir,~]=fileparts(mfilename('fullpath'));
            
            addpath(fullfile(sDir,'probeConfig'))
            
            %get possible probes
            pDir=dir(fullfile(sDir,'probeConfig','probeConfig*'));
            probe1Name{1}='select';
            for i=1:length(pDir)
                pTmp=strsplit(pDir(i).name,'_');
                probe1Name{i+1}=pTmp{2}(1:end-2); %need to get rid of the .m
            end
            probe2Name=probe1Name;
            probe2Name{1}='none';
           
            app.select_Type1.Items=probe1Name;
            app.select_Type2.Items=probe2Name;

            app.mainGui=mainapp;

        end

        % Button pushed function: button_save
        function button_saveButtonPushed(app, event)
            %construct id file
            %name
            id.exptId=app.edit_ExpName.Value;
            if isempty(id.exptId)
                uialert(app.UIMakeId,'Enter information in all fields!','Error');
                return;
            end

            %probe 1 info
            if strcmp(app.select_Type1.Value,'select')
                uialert(app.UIMakeId,'Enter information in all fields!','Error');
                return;
            else
                ptype=app.select_Type1.Value;
                id.probes(1)=app.makeProbe(ptype);
                id.probes(1).area=app.edit_Area1.Value;
                id.probes(1).type=ptype;
                id.probes(1).nChannels=length(id.probes(1).channels);
            end

            %probe 2 info
            if ~strcmp(app.select_Type2.Value,'none')
                ptype=app.select_Type2.Value;
                ptemp=app.makeProbe(ptype);
                ptemp.area=app.edit_Area2.Value;
                ptemp.type=ptype;
                ptemp.nChannels=length(ptemp.channels);
                id.probes(2)=ptemp;
            end

            %file format
            id.isBR=app.chk_Format.Value;

            %save
            [file,path]=uiputfile([id.exptId '_id.mat'],'Save Id file');
            if file~=0
                save(fullfile(path,file),'id');

                makeIdfile(app.mainGui,id,id.exptId,path);
                delete(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIMakeId = uifigure('Visible', 'off');
            app.UIMakeId.Name = 'MATLAB App';
            app.ExpnameEditFieldLabel = uilabel(app.UIMakeId);
            app.ExpnameEditFieldLabel.Text = 'Exp name';
            app.edit_ExpName = uieditfield(app.UIMakeId, 'text');
            app.Probe1typeareaDropDownLabel = uilabel(app.UIMakeId);
            app.Probe1typeareaDropDownLabel.Text = 'Probe 1 type/area';
            app.select_Type1 = uidropdown(app.UIMakeId);
            app.select_Type1.Items = {};
            app.select_Type1.Value = {};
            app.edit_Area1 = uieditfield(app.UIMakeId, 'text');
            app.edit_Area2 = uieditfield(app.UIMakeId, 'text');
            app.Probe2typeareaDropDownLabel = uilabel(app.UIMakeId);
            app.Probe2typeareaDropDownLabel.Text = 'Probe 2 type/area';
            app.select_Type2 = uidropdown(app.UIMakeId);
            app.select_Type2.Items = {};
            app.select_Type2.Value = {};
            app.chk_Format = uicheckbox(app.UIMakeId);
            app.chk_Format.Text = 'Not intan';
            app.button_save = uibutton(app.UIMakeId, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @button_saveButtonPushed, true);
            app.button_save.Text = 'Save';
            % Show the figure after all components are created
            app.UIMakeId.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = makeIDfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIMakeId)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIMakeId)
        end
    end
end