classdef extractSpikesSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISpikeSettings  matlab.ui.Figure
        NoteSavethesettingsfileinalocalfolderLabel  matlab.ui.control.Label
        SaveButton       matlab.ui.control.Button
        LoadButton       matlab.ui.control.Button
        DoneButton       matlab.ui.control.Button
        SettingsTable    matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            %initialize settings
            settings.name='XXX';
            settings.expFolder='c:\kristina\ephys';
            settings.extData='_amplifier.dat';
            settings.extThres='_threshold.mat';
            settings.extId='_id.mat';
            settings.extSpike='_spike.mat';
            settings.copyToZ=1;
            settings.refrTime=1; %timeout before and after large minima in ms
            %settings.useRefrSpace=0;
            %settings.refrSpace=100;
            settings.refrCross=0.5; %timeout after threshold crossing in ms
            settings.spikeSamples=15; %number of sample points per spike before and after the minimum
            settings.spikeRadius=100; %distance radius over which to extract spike waveform


            %turn into table
            parname=fieldnames(settings);
            for i=1:length(parname)
                parval{i}=settings.(parname{i});
            end
            
            t=table(parname,parval');
            
            %add explanations
            pardoc={'name or initials';...
                'experiment folder';...
                'extension intan data';...
                'extension threshold data';...
                'extension id file';...
                'extension spike output file';...
                'copy id file to Z?';...
                'art. refr. time (ms)';...
                %'implement refr space?';...
                %'art. refr. space (um)';...
                'timeout around threshold crossing (ms)';...
                'samples per spike waveform (samples)';...
                'channel distance per spike waveforms (um)'};
            t=addvars(t,pardoc);
            
            app.SettingsTable.Data=t;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %close app
            delete(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %load settings file
            [file,path]=uigetfile('*.mat','Select settings file');
            if ~isequal(file,0)
                load(fullfile(path,file)); %this will load SpikeSettings
                
                %get current settings
                currSettings=app.SettingsTable.Data;
                
                parname=fieldnames(SpikeSettings);
                for i=1:length(parname)
                    idx=find(strcmp(currSettings.parname,parname{i}));
                    currSettings{idx,2}={SpikeSettings.(parname{i})};
                end
                
                %t=table(parname,parval');
                app.SettingsTable.Data=currSettings;
            end
            figure(app.UISpikeSettings)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %save settings file
            [file,path] = uiputfile('*.mat','Save settings file');
            if ~isequal(file,0)
                %get settings and parse into structure
                currSettings=app.SettingsTable.Data;
                pn=currSettings.parname;
                for i=1:length(pn)
                    SpikeSettings.(pn{i})=currSettings{i,2}{:};
                end
                save(fullfile(path,file),'SpikeSettings');
            end
            figure(app.UISpikeSettings)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISpikeSettings = uifigure('Visible', 'off');
            app.UISpikeSettings.Name = 'MATLAB App';
            app.SettingsTable = uitable(app.UISpikeSettings);
            app.SettingsTable.ColumnName = {'Setting'; 'Value'; 'Explanation'};
            app.SettingsTable.RowName = {};
            app.SettingsTable.ColumnEditable = [false true];
            app.DoneButton = uibutton(app.UISpikeSettings, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.LoadButton = uibutton(app.UISpikeSettings, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load settings';
            app.SaveButton = uibutton(app.UISpikeSettings, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save settings';
            app.NoteSavethesettingsfileinalocalfolderLabel = uilabel(app.UISpikeSettings);
            app.NoteSavethesettingsfileinalocalfolderLabel.Text = 'Note: Save the settings file in a local folder';
            % Show the figure after all components are created
            app.UISpikeSettings.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extractSpikesSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISpikeSettings)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISpikeSettings)
        end
    end
end