classdef selectCh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProbeAxes                      matlab.ui.control.UIAxes
        AddChButton                    matlab.ui.control.Button
        DoneButton                     matlab.ui.control.Button
        SelectedchannelsTextAreaLabel  matlab.ui.control.Label
        SelChTxt                       matlab.ui.control.TextArea
        RemChButton                    matlab.ui.control.Button
        DoubleclickinsiderectangletofinishselectionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        probes % from id file
        selectedCh %selected channel
        sortGui %handle to main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, probes, selCh)
            %get handle to main app
            app.sortGui=mainapp;
            
            %get probes data
            app.probes=probes;
            app.selectedCh=selCh;
            
            %initial plot - first plot all
            plot(app.ProbeAxes,app.probes.x,app.probes.z,'sqr','MarkerSize',11)
            for i=1:app.probes.nChannels
                text(app.probes.x(i),app.probes.z(i),...
                    num2str(app.probes.channels(i)+1),'FontSize',9,'HorizontalAlignment','center','parent',app.ProbeAxes);
            end
            axis(app.ProbeAxes,[min(app.probes.x)-50 max(app.probes.x)+50 min(app.probes.z)-50 max(app.probes.z)+50]);
            app.ProbeAxes.XLabel.String='X';
            app.ProbeAxes.YLabel.String='Z';
            
            %now change the ones that have already been selected (if any)
            if ~isempty(app.selectedCh)
                
                selCh=ismember(app.probes.channels+1,app.selectedCh);
                selChId=find(selCh==1);
                
                hold(app.ProbeAxes,'on')
                plot(app.ProbeAxes,app.probes.x(selChId),app.probes.z(selChId),'s','MarkerSize',11,'MarkerFaceColor','r')
                for i=1:length(selChId)
                    text(app.probes.x(selChId(i)),app.probes.z(selChId(i)),...
                        num2str(app.probes.channels(selChId(i))+1),'FontSize',9,'Color','w','HorizontalAlignment','center','parent',app.ProbeAxes);
                end
                
                app.SelChTxt.Value=cellstr(num2str(sort(app.selectedCh)));
            end
            
        end

        % Button pushed function: AddChButton
        function AddChButtonPushed(app, event)
            %rectangle for channel selection
            roi=drawrectangle(app.ProbeAxes);
            wait(roi) %waits for double click
            
            %figure out which channels are inside the rectangle
            selCh=inROI(roi,app.probes.x,app.probes.z);
            selChId=find(selCh==1);
            
            %highlight selected channels
            hold(app.ProbeAxes,'on')
            plot(app.ProbeAxes,app.probes.x(selChId),app.probes.z(selChId),'s','MarkerSize',11,'MarkerFaceColor','r')
            for i=1:length(selChId)
                text(app.probes.x(selChId(i)),app.probes.z(selChId(i)),...
                    num2str(app.probes.channels(selChId(i))+1),'FontSize',9,'Color','w','HorizontalAlignment','center','parent',app.ProbeAxes);
            end
            delete(roi);
            
            %save in variable, update list
            app.selectedCh=[app.selectedCh;app.probes.channels(selChId)+1];
            app.SelChTxt.Value=cellstr(num2str(sort(app.selectedCh)));
            
        end

        % Button pushed function: RemChButton
        function RemChButtonPushed(app, event)
            %fraw rectangle
            roi=drawrectangle(app.ProbeAxes);
            wait(roi) %waits for double click
            
            %figure out which channels are in the rectangle
            selCh=inROI(roi,app.probes.x,app.probes.z);
            selChId=find(selCh==1);
            selChNr=app.probes.channels(selChId)+1;
            
            %make sure these were actually selected before
            tf=ismember(selChNr,app.selectedCh);
            selChId=selChId(tf);
            selChNr=selChNr(tf);
            
            %update plot
            hold(app.ProbeAxes,'on')
            plot(app.ProbeAxes,app.probes.x(selChId),app.probes.z(selChId),'sr','MarkerSize',11,'MarkerFaceColor','w')
            for i=1:length(selChId)
                text(app.probes.x(selChId(i)),app.probes.z(selChId(i)),...
                    num2str(app.probes.channels(selChId(i))+1),'FontSize',9,'HorizontalAlignment','center','parent',app.ProbeAxes);
            end
            delete(roi);
            
            %remove from list and update
            tf=~ismember(app.selectedCh,selChNr);
            app.selectedCh=app.selectedCh(tf);
            app.SelChTxt.Value=cellstr(num2str(sort(app.selectedCh)));
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            getSelCh(app.sortGui,app.selectedCh);
            %close app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProbeAxes = uiaxes(app.UIFigure);
            title(app.ProbeAxes, '')
            xlabel(app.ProbeAxes, 'X')
            ylabel(app.ProbeAxes, 'Y')
            app.AddChButton = uibutton(app.UIFigure, 'push');
            app.AddChButton.ButtonPushedFcn = createCallbackFcn(app, @AddChButtonPushed, true);
            app.AddChButton.Text = 'Add channel(s)';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SelectedchannelsTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedchannelsTextAreaLabel.Text = 'Selected channels';
            app.SelChTxt = uitextarea(app.UIFigure);
            app.RemChButton = uibutton(app.UIFigure, 'push');
            app.RemChButton.ButtonPushedFcn = createCallbackFcn(app, @RemChButtonPushed, true);
            app.RemChButton.Text = 'Remove channel(s)';
            app.DoubleclickinsiderectangletofinishselectionLabel = uilabel(app.UIFigure);
            app.DoubleclickinsiderectangletofinishselectionLabel.Text = {'Double click inside'; 'rectangle to finish'; 'selection'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectCh(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end