classdef setArtefact < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISetArt                     matlab.ui.Figure
        ThreshEdit                   matlab.ui.control.NumericEditField
        ThreshEditFieldLabel         matlab.ui.control.Label
        RefreshButton                matlab.ui.control.Button
        RedArtefactLabel             matlab.ui.control.Label
        afterLabel                   matlab.ui.control.Label
        AddThreshCheckBox            matlab.ui.control.CheckBox
        ThreshSlider                 matlab.ui.control.Slider
        NrWfSpinner                  matlab.ui.control.Spinner
        PlotNrWaveformsSpinnerLabel  matlab.ui.control.Label
        AcceptButton                 matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        NrChSpinner                  matlab.ui.control.Spinner
        MinnrchannelsLabel           matlab.ui.control.Label
        WfAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainGui
        spkInfo
        jobEdges
        filepath
        tSuffix
        lineHandle
        threshValue
    end
    
    methods (Access = private)
        
        function plotWf(app)
            wb=uiprogressdlg(app.UISetArt,'Title','Extracting waveforms','Message','Reading...','Indeterminate','on');
            
            %identify artefacts
            artIdx = find(app.spkInfo.NDuplicate>=app.NrChSpinner.Value);
            
            if ~isempty(artIdx)
                
                %randomly subselect if there are more than nrSpk
                if length(artIdx)>app.NrWfSpinner.Value
                    ridx=randperm(length(artIdx),app.NrWfSpinner.Value);
                    artIdx=artIdx(ridx);
                end
                
                %get timestamps and channels for these events
                ts=app.spkInfo.spkTimesDet(artIdx);
                chidx=app.spkInfo.detCh(artIdx); 
                
                %now read waveforms - map timestamps onto job files
                tsbin=discretize(ts,app.jobEdges); %this works even if not all jobs were merged for spike file generation
                tsunique=unique(tsbin);
                
                %need loop to open files and read waveforms - first loop over
                %job files
                
                count=1;
                for i=1:length(tsunique)
                    sDir='SpikeFiles';
                    if ~isempty(app.tSuffix)
                        sDir=[sDir '_' app.tSuffix];
                    end
                    load(fullfile(app.filepath,sDir,[app.spkInfo.expname  '_j' num2str(tsunique(i)-1) ...
                      '_p' num2str(app.spkInfo.probeId) '_spike'])); %generates spikeData
                    
                    %within job files loop over waveforms
                    tsidx=find(tsbin==tsunique(i));
                    for j=1:length(tsidx)
                        widx=find(spikeData(chidx(tsidx(j))).spikeTimes==ts(tsidx(j))); %find which waveform
                        
                        wv=spikeData(chidx(tsidx(j))).Wvfrms(widx,:,1); %get waveform
                        waveformData(count,:)=squeeze(wv);
                        
                        %need to keep track of original index to sort out
                        %artefacts based on threshold
                        oIdx(count)=artIdx(tsidx(j));
                        
                        count=count+1;
                    end
                end
                close(wb);
                
                %now plot - implement threshold criterion
                %we are not using the raw waveforms here, since that will not
                %be used in the main gui, rather use min or max amplitude based
                %on sign of threshold
                if app.AddThreshCheckBox.Value==1
                    tval=app.threshValue;
                    if tval<0
                        tidx=app.spkInfo.AmpMinDet(oIdx)<=tval;
                    else
                        tidx=app.spkInfo.AmpMaxDet(oIdx)>=tval;
                    end
                    
                    plot(app.WfAxes,waveformData(~tidx,:)','k-');
                    hold(app.WfAxes,'on')
                    plot(app.WfAxes,waveformData(tidx,:)','r-');
                    hold(app.WfAxes,'off')
                else
                    plot(app.WfAxes,waveformData','r-');
                end
                
                %update threshold slider
                minAmp=round(min(waveformData(:))-100,-2);
                maxAmp=round(max(waveformData(:))+100,-2);

                %need to adjust slider to work with manually edited
                %threshold if necessary
                if app.threshValue<minAmp
                    minAmp=app.threshValue;
                elseif app.threshValue>maxAmp
                    maxAmpl=app.threshValue;
                end

                app.ThreshSlider.Limits=[minAmp maxAmp];
                
                %add threshold line
                if ~isempty(app.lineHandle)
                    if isvalid(app.lineHandle)
                        delete(app.lineHandle);
                    end
                end
                
                hold(app.WfAxes,'on');
                xlim=app.WfAxes.XLim;
                app.lineHandle=plot(app.WfAxes,xlim,[app.threshValue app.threshValue],'b-');
                hold(app.WfAxes,'off');
            else
                %no artefacts found - just plot threshold
                %add threshold line
                if ~isempty(app.lineHandle)
                    if isvalid(app.lineHandle)
                        delete(app.lineHandle);
                    end
                end
                xlim=app.WfAxes.XLim;
                app.lineHandle=plot(app.WfAxes,xlim,[app.threshValue app.threshValue],'b-');

            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, spkInfo, jobEdges, filepath, tSuffix)
            
            app.mainGui=mainapp;
            app.spkInfo=spkInfo;
            app.jobEdges=jobEdges;
            app.filepath=filepath;
            app.tSuffix=tSuffix;
            app.threshValue=0;
            
            app.plotWf();
            %close(wb);
        end

        % Value changed function: NrWfSpinner
        function NrWfSpinnerValueChanged(app, event)
            app.plotWf();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.plotWf();
        end

        % Value changed function: NrChSpinner
        function NrChSpinnerValueChanged(app, event)
            app.plotWf();
        end

        % Value changed function: AddThreshCheckBox
        function AddThreshCheckBoxValueChanged(app, event)
            app.plotWf();
        end

        % Value changed function: ThreshSlider
        function ThreshSliderValueChanged(app, event)
            %get new value
            app.threshValue = app.ThreshSlider.Value;
            app.ThreshEdit.Value=app.threshValue;
            app.plotWf(); %actually update waveforms
            
        end

        % Value changed function: ThreshEdit
        function ThreshEditValueChanged(app, event)
            app.threshValue = app.ThreshEdit.Value;
            if app.threshValue<app.ThreshSlider.Limits(1)
                app.ThreshSlider.Limits(1)=app.threshValue;
            elseif app.threshValue>app.ThreshSlider.Limits(2)
                app.ThreshSlider.Limits(2)=app.threshValue;
            end
                               
            app.ThreshSlider.Value=app.threshValue;
            app.plotWf(); %actually update waveforms
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            if app.AddThreshCheckBox.Value==1
                getArtefacts(app.mainGui,app.NrChSpinner.Value,app.threshValue);
            else
                getArtefacts(app.mainGui,app.NrChSpinner.Value,NaN);
            end
            %close app
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            getArtefacts(app.mainGui,NaN,NaN);
            %close app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISetArt = uifigure('Visible', 'off');
            app.UISetArt.Name = 'MATLAB App';
            app.WfAxes = uiaxes(app.UISetArt);
            xlabel(app.WfAxes, 'Samples')
            ylabel(app.WfAxes, 'Amp')
            app.MinnrchannelsLabel = uilabel(app.UISetArt);
            app.MinnrchannelsLabel.Text = 'Min nr channels:';
            app.NrChSpinner = uispinner(app.UISetArt);
            app.NrChSpinner.Limits = [1 Inf];
            app.NrChSpinner.ValueChangedFcn = createCallbackFcn(app, @NrChSpinnerValueChanged, true);
            app.NrChSpinner.Value = 10;
            app.CancelButton = uibutton(app.UISetArt, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AcceptButton = uibutton(app.UISetArt, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.PlotNrWaveformsSpinnerLabel = uilabel(app.UISetArt);
            app.PlotNrWaveformsSpinnerLabel.Text = 'Plot Nr Waveforms';
            app.NrWfSpinner = uispinner(app.UISetArt);
            app.NrWfSpinner.Limits = [1 Inf];
            app.NrWfSpinner.ValueChangedFcn = createCallbackFcn(app, @NrWfSpinnerValueChanged, true);
            app.NrWfSpinner.Value = 100;
            app.ThreshSlider = uislider(app.UISetArt);
            app.ThreshSlider.ValueChangedFcn = createCallbackFcn(app, @ThreshSliderValueChanged, true);
            app.AddThreshCheckBox = uicheckbox(app.UISetArt);
            app.AddThreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddThreshCheckBoxValueChanged, true);
            app.AddThreshCheckBox.Text = 'Add threshold criterion';
            app.afterLabel = uilabel(app.UISetArt);
            app.afterLabel.Text = {'Note for positive thresholds: '; 'Detection based on maximum occuring'; 'after minimum'};
            app.RedArtefactLabel = uilabel(app.UISetArt);
            app.RedArtefactLabel.Text = 'Red: Artefact';
            app.RefreshButton = uibutton(app.UISetArt, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh waveforms';
            app.ThreshEditFieldLabel = uilabel(app.UISetArt);
            app.ThreshEditFieldLabel.Text = 'Thresh:';
            app.ThreshEdit = uieditfield(app.UISetArt, 'numeric');
            app.ThreshEdit.ValueChangedFcn = createCallbackFcn(app, @ThreshEditValueChanged, true);
            % Show the figure after all components are created
            app.UISetArt.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setArtefact(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISetArt)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISetArt)
        end
    end
end