classdef setIDfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISetID                 matlab.ui.Figure
        JobFilesEditFieldLabel  matlab.ui.control.Label
        titleLabel              matlab.ui.control.Label
        ProbeIDSpinner          matlab.ui.control.Spinner
        ProbeIDSpinnerLabel     matlab.ui.control.Label
        JIDStartSpinner         matlab.ui.control.Spinner
        JIDStopSpinner          matlab.ui.control.Spinner
        JobIDStopLabel          matlab.ui.control.Label
        JobIDStartLabel         matlab.ui.control.Label
        JIDPercEditField        matlab.ui.control.NumericEditField
        DoneButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainGui % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainGui=mainapp;
            %movegui(app.UISetID,'center');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)

            probe=app.ProbeIDSpinner.Value;
            jobIDstart=app.JIDStartSpinner.Value;
            jobIDstop=app.JIDStopSpinner.Value;
            percJobs=app.JIDPercEditField.Value;

            getIDfile(app.mainGui,probe,jobIDstart,jobIDstop,percJobs);
            delete(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISetID = uifigure('Visible', 'off');
            app.UISetID.Name = 'MATLAB App';
            app.DoneButton = uibutton(app.UISetID, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.JIDPercEditField = uieditfield(app.UISetID, 'numeric');
            app.JIDPercEditField.Limits = [1 100];
            app.JIDPercEditField.Value = 100;
            app.JobIDStartLabel = uilabel(app.UISetID);
            app.JobIDStartLabel.Text = 'Job ID Start';
            app.JobIDStopLabel = uilabel(app.UISetID);
            app.JobIDStopLabel.Text = 'Job ID Stop';
            app.JIDStopSpinner = uispinner(app.UISetID);
            app.JIDStopSpinner.Value = 199;
            app.JIDStartSpinner = uispinner(app.UISetID);
            app.ProbeIDSpinnerLabel = uilabel(app.UISetID);
            app.ProbeIDSpinnerLabel.Text = 'Probe ID';
            app.ProbeIDSpinner = uispinner(app.UISetID);
            app.ProbeIDSpinner.Value = 1;
            app.titleLabel = uilabel(app.UISetID);
            app.titleLabel.Text = 'Set file parameters';
            app.JobFilesEditFieldLabel = uilabel(app.UISetID);
            app.JobFilesEditFieldLabel.Text = '% Job Files';
            % Show the figure after all components are created
            app.UISetID.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setIDfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISetID)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISetID)
        end
    end
end