classdef sortGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISortGui                     matlab.ui.Figure
        FilePanel                     matlab.ui.container.Panel
        RefreshJobsButton             matlab.ui.control.Button
        FileTab                       matlab.ui.container.TabGroup
        LoadId                        matlab.ui.container.Tab
        toLabel                       matlab.ui.control.Label
        LoadIDButton                  matlab.ui.control.Button
        PercjobsLabel                 matlab.ui.control.Label
        ProbeSpinner                  matlab.ui.control.Spinner
        JobStartSpinner               matlab.ui.control.Spinner
        JobsfromLabel                 matlab.ui.control.Label
        ProbeIDLabel                  matlab.ui.control.Label
        JobStopSpinner                matlab.ui.control.Spinner
        PercJobSpinner                matlab.ui.control.Spinner
        LoadJobs                      matlab.ui.control.Button
        LoadSort                      matlab.ui.container.Tab
        LoadSortButton                matlab.ui.control.Button
        SaveFile                      matlab.ui.container.Tab
        InitialsField                 matlab.ui.control.EditField
        SuffixField                   matlab.ui.control.EditField
        SuffixLabel                   matlab.ui.control.Label
        LinkDBCheckBox                matlab.ui.control.CheckBox
        CopyZCheckBox                 matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        InitialsEditFieldLabel        matlab.ui.control.Label
        FileInfo                      matlab.ui.container.Tab
        FileTable                     matlab.ui.control.Table
        PreprocessingPanel            matlab.ui.container.Panel
        ArtRejSwitch                  matlab.ui.control.Switch
        ArtefactrejectionSwitchLabel  matlab.ui.control.Label
        PlotPanel                     matlab.ui.container.Panel
        ShowROICheckBox               matlab.ui.control.CheckBox
        HideAssignmentCheckBox        matlab.ui.control.CheckBox
        PlotUnitOnlyCheckBox          matlab.ui.control.CheckBox
        PlotUnitOnlySpinner           matlab.ui.control.Spinner
        ModeTabGroup                  matlab.ui.container.TabGroup
        ProbeTab                      matlab.ui.container.Tab
        SelChLabel                    matlab.ui.control.Label
        ResetChButton                 matlab.ui.control.Button
        LimitChButton                 matlab.ui.control.Button
        YaxisDropDown                 matlab.ui.control.DropDown
        YaxisDropDownLabel            matlab.ui.control.Label
        XaxisDropDown                 matlab.ui.control.DropDown
        XaxisDropDownLabel            matlab.ui.control.Label
        TetrodeTab                    matlab.ui.container.Tab
        TetInclNBCheckBox             matlab.ui.control.CheckBox
        TetDetChSpinner               matlab.ui.control.Spinner
        TetChYDropDown                matlab.ui.control.DropDown
        ChYLabel                      matlab.ui.control.Label
        TetChXDropDown                matlab.ui.control.DropDown
        ChXLabel                      matlab.ui.control.Label
        TetYaxisDropDown              matlab.ui.control.DropDown
        YaxisDropDown_2Label          matlab.ui.control.Label
        TetXaxisDropDown              matlab.ui.control.DropDown
        XaxisDropDown_2Label          matlab.ui.control.Label
        DetchannelSpinnerLabel        matlab.ui.control.Label
        ChannelTab                    matlab.ui.container.Tab
        ChInclNBCheckBox              matlab.ui.control.CheckBox
        ChTypeButton                  matlab.ui.control.StateButton
        ChDetChSpinner                matlab.ui.control.Spinner
        ChYaxisDropDown               matlab.ui.control.DropDown
        YaxisDropDown_2Label_2        matlab.ui.control.Label
        ChXaxisDropDown               matlab.ui.control.DropDown
        XaxisDropDown_2Label_2        matlab.ui.control.Label
        MarkerSzSpinner               matlab.ui.control.Spinner
        MarkersizeSpinnerLabel        matlab.ui.control.Label
        UnitsPanel                    matlab.ui.container.Panel
        ApplyHistoryButton            matlab.ui.control.Button
        UnitMetricsButton             matlab.ui.control.Button
        UndoButton                    matlab.ui.control.Button
        EditunitPanel                 matlab.ui.container.Panel
        MergeUnitSpinner              matlab.ui.control.Spinner
        wunitSpinnerLabel             matlab.ui.control.Label
        MergeButton                   matlab.ui.control.Button
        DeleteUnitButton              matlab.ui.control.Button
        UnitSpinner                   matlab.ui.control.Spinner
        UnitSpinnerLabel              matlab.ui.control.Label
        AddMROIButton                 matlab.ui.control.Button
        AddPROIButton                 matlab.ui.control.Button
        TetherPlotsCheckBox           matlab.ui.control.CheckBox
        AutoCatButton                 matlab.ui.control.Button
        UnitTable                     matlab.ui.control.Table
        AddUnitButton                 matlab.ui.control.Button
        UnitwaveformsPanel            matlab.ui.container.Panel
        WfReaderButton                matlab.ui.control.StateButton
        RefreshWfButton               matlab.ui.control.Button
        NrSpikesSpinner               matlab.ui.control.Spinner
        NrSpikesSpinnerLabel          matlab.ui.control.Label
        AddWfButton                   matlab.ui.control.Button
        Unit2WfCheckBox               matlab.ui.control.CheckBox
        Unit2WfSpinner                matlab.ui.control.Spinner
        UnitWfSpinner                 matlab.ui.control.Spinner
        Unit1Label                    matlab.ui.control.Label
        WfAxes                        matlab.ui.control.UIAxes
        UnitfootprintPanel            matlab.ui.container.Panel
        UnitFpSpinner                 matlab.ui.control.Spinner
        UnitSpinner_2Label_2          matlab.ui.control.Label
        FpAxes                        matlab.ui.control.UIAxes
        MainAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        jobIDstart %start of jobs to read
        jobIDstop %stop of jobs to read
        probeID %probe ID
        percJobs %percent jobs to read
        jobsList %full list of jobs to read
        spkInfo % properties for each spike
        spkProp % properties for probe mode
        spkPropTet % properties for tetrode mode
        spkPropCh % properties for channel mode
        probes %probes info
        probeSort %sorted channels (for channel view mode)
        plotmode %1 normal, 2 tetrode
        xProp %property for x axis
        yProp %property for y axis
        xPropTet %x axis tetrode
        yPropTet %y axis tetrode
        xPropCh %x axis channel
        yPropCh %y axis channel
        detChTet %detection channel tetrode
        detChNbTet %include neighborhood in tetrode mode
        xChTet %x channel
        yChTet %y channel
        detChCh %detection channel, channel mode
        detChType %detection channel type, channel mode
        detChNbCh %including neighboring channels in singe channel mode?
        selectedCh %subset of channels to work on - these are relative to the probe (e.g., 1-64 even for second probe)
        units %color for each unit (could hold other info too)
        unitInfo %info table for units
        unitMetric %table with quality metric per unit
        nrUnits %nr units
        sampleFreq %sampling frequency
        jobEdges %start and stop of samples in jobs
        filepath %path of spkfile
        filename %filename of spkfile
        filetype %type of file (id versus sort)
        fileinfo %table with information about current file
        extractName %extractSpk file (possibly with suffix)
        tSuffix %suffix indicating spike file version
        threshinfo %information about threshold file for book keeping
        artThreshold % threshold for artefacts
        artNCh %threshold for artefacts ((in number of channels)
        tbhandle %handle for toolbar in main axes
        roihandle %handle to the roi
        singleWfRoi %handle for point ROI for single waveform display
        singleWfPlot %handle to the line plot for single waveform
        singleFpPlot %handle to symbol in footprint plot for single waveform
        sortActions %record of all ROI actions
        sortActionsCount %counter for current actions
        sortActionsStart %counter for previous actions
        undoUnitId %copy of unitid for undo
        undoUnitInfo %copy of unitinfo
        undoUnits %copy of units
    end

    methods (Access = private)

        function loadSpkInfo(app)
            %loops through all of the job files to generate spkInfo

            wb=uiprogressdlg(app.UISortGui,'Title','Please wait','Message','Generating spkInfo','Indeterminate','on');

            %construct spkInfo - parfor makes reading signficantly faster,
            %even if we then need another loop to build output because of
            %parfor restrictions on indexing into variables
            fpath=app.filepath;
            probeId=app.probeID;
            expname=app.filename;
            sDir='SpikeFiles';
            if ~isempty(app.tSuffix)
                sDir=[sDir '_' app.tSuffix];
            end
           
            jobsL=app.jobsList; %parfor doesn't like using this directly
            parfor i=1:length(jobsL)
                jId=num2str(jobsL(i));
                fname=fullfile(fpath,sDir,[expname '_j' jId '_p' num2str(probeId) '_spkinfo']);
                out(i)=load(fname); %generates spk
            end

            app.spkInfo=struct;
            for i=1:length(app.jobsList)
                dataVar=fieldnames(out(i).spk);

                for n=1:length(dataVar)
                    %eliminate all but the spike properties here; some of
                    %this is here to be backward compatible
                    if ~any(strcmp(dataVar{n},{'expname','probeId','settings'}))
                        if i==1
                            app.spkInfo.(dataVar{n})=out(i).spk.(dataVar{n});
                        else
                            app.spkInfo.(dataVar{n})=[app.spkInfo.(dataVar{n}) out(i).spk.(dataVar{n})];
                        end
                    end
                end
            end
            app.spkInfo.probeId=probeId;
            app.spkInfo.expname=expname;

            clear out;
            close(wb);

        end

        function unitid=adjustSpkSort(app,spkSort)

            if length(spkSort.spktimes)>length(app.spkInfo.spkTimesDet)
                %more in spkSort: find the ones that need to be deleted in
                %spkSort based on spike times and channel
                %ismember with rows compares the entire row
                idx=~ismember([spkSort.spktimes' spkSort.detCh'],[app.spkInfo.spkTimesDet' app.spkInfo.detCh'],'rows');

                unitid=spkSort.unitid;
                unitid(idx)=[];
                uialert(app.UISortGui,'spkSort file had more spikes than in spike files. Adjusted to match.','Warning')
            else
                %less in spkSort: add entries with the missing times and
                %unitid set to 0
                unitid=zeros(size(app.spkInfo.spkTimesDet));

                %copy the unitid for the ones that exist - locb gives the
                %location in spkInfo for the matching entries in spkSort
                [~,locb]=ismember([spkSort.spktimes' spkSort.detCh'],[app.spkInfo.spkTimesDet' app.spkInfo.detCh'],'rows');
                for i=1:length(locb)
                    if locb(i)~=0
                        unitid(locb(i))=spkSort.unitid(i);
                    end
                end
                uialert(app.UISortGui,'spkSort file had less spikes than in spike files. Adjusted to match.','Warning')
            end
        end

        function [xData,yData,unitData,idxData] = getSpikeData(app,datamode)
            %get data for a set of properties

            %generate indices into full spk array
            idxData=[1:length(app.spkInfo.unitid)];

            %remove any artefacts (labeled as -1)
            tf=app.spkInfo.unitid~=-1;

             %also remove all duplicates
             tf = tf & app.spkInfo.flagDuplicate==0;
           
            %remove everything but one unit if selected
            if app.PlotUnitOnlyCheckBox.Value==1
                tf = tf & app.spkInfo.unitid==app.PlotUnitOnlySpinner.Value;
            end

            %datamode 1: probe, 2: tetrode, 3: channel
            switch datamode
                case 1
                    %reduce to selected channels or to correct probe only
                    if ~isempty(app.selectedCh) %all data from one probe
                        tf = tf & ismember(app.spkInfo.detCh,app.selectedCh);
                    end

                    %remove units if selected
                    if ~isempty(app.unitInfo)
                        visUnit=double(app.unitInfo.Vis)-1; %returns vector with entry per unit, -1 b/c of categories
                        visIdx=find(visUnit==0); %might result in vector
                        tf = tf& ~ismember(app.spkInfo.unitid,visIdx);
                    end

                    %get data
                    xData=app.spkInfo.(app.xProp)(tf);
                    yData=app.spkInfo.(app.yProp)(tf);
                    unitData=app.spkInfo.unitid(tf);
                    idxData=idxData(tf);

                case 2
                    %we either need the spikes detected on the detection channel, or the ones detected
                    %on this channel and its neighbors
                    %for now we're using detCh rather than detChSort since
                    %this is most useful for tetrode data where it doesn't
                    %matter

                    %find correct spikes
                    if app.detChNbTet==1 %use neighbors
                        idx=find(app.spkInfo.detCh==app.detChTet); %find spikes on detection channel
                        if ~isempty(idx) %use first spike to get list of neighbor channels for the det ch
                            chListDet=app.spkInfo.chListAll{idx(1)};
                        else %there are no spikes, so have to stick to channel itself
                            chListDet=app.detChTet;
                        end
                    else %no neighbors
                        chListDet=app.detChTet;
                    end
                    tf=tf & ismember(app.spkInfo.detCh,chListDet);

                    %no matter the neighborhood selection, need channel list for the selection of the right
                    %matrix columns below
                    idx=find(app.spkInfo.detCh==app.detChTet); %find spikes on detection channel
                    if ~isempty(idx) %use first spike to get list of neighbor channels for the det ch
                        chList=app.spkInfo.chListAll{idx(1)};
                    else %there are no spikes, so have to stick to channel itself
                        chList=app.detChTet;
                    end                    

                    xData=app.spkInfo.(app.xPropTet)(tf);
                    yData=app.spkInfo.(app.yPropTet)(tf);
                    unitData=app.spkInfo.unitid(tf);
                    idxData=idxData(tf);

                    if ~isempty(idxData)

                        %turn into regular array and reshape
                        nCh=size(xData{1},2);
                        xData=cell2mat(xData);
                        yData=cell2mat(yData);
                        xData=reshape(xData,nCh,sum(tf));
                        yData=reshape(yData,nCh,sum(tf));

                        %now get the data for the channels selected for x and y
                        xidx=find(chList==app.xChTet);
                        yidx=find(chList==app.yChTet);
                        xData=xData(xidx,:);
                        yData=yData(yidx,:);


                        %remove units if selected
                        if ~isempty(app.unitInfo)
                            visUnit=double(app.unitInfo.Vis)-1; %returns vector with entry per unit
                            visIdx=find(visUnit==0); %might result in vector
                            tf=~ismember(unitData,visIdx);
                            xData=xData(tf);
                            yData=yData(tf);
                            unitData=unitData(tf);
                            idxData=idxData(tf);
                        end
                    end
                case 3
                   %we need the data for events that are detected on the
                    %detection channel (possibly including its neighbors)

                    if app.detChType==1 %use detChSort
                        if app.detChNbCh==1 %find neighbors
                            idx=find(app.spkInfo.detChSort==app.detChCh);
                            if ~isempty(idx)
                                chList=app.spkInfo.chListAll{idx(1)};
                            else
                                chList=app.detChCh;
                            end
                            %need to translate into sorted channels
                            chList=find(ismember(app.probeSort(:,3),chList));
                        else
                            chList=app.detChCh;
                        end

                        tf=tf & ismember(app.spkInfo.detChSort,chList); %works with a list of 1 as well
                    else %use detCh
                        if app.detChNbCh==1 %find neighbors
                            idx=find(app.spkInfo.detCh==app.detChCh);
                            if ~isempty(idx)
                                chList=app.spkInfo.chListAll{idx(1)};
                            else
                                chList=app.detChCh;
                            end
                        else
                            chList=app.detChCh;
                        end
                        tf=tf & ismember(app.spkInfo.detCh,chList);
                    end

                    %remove units if selected
                    if ~isempty(app.unitInfo)
                        visUnit=double(app.unitInfo.Vis)-1; %returns vector with entry per unit
                        visIdx=find(visUnit==0); %might result in vector
                        tf = tf& ~ismember(app.spkInfo.unitid,visIdx);
                    end

                    xData=app.spkInfo.(app.xPropCh)(tf);
                    yData=app.spkInfo.(app.yPropCh)(tf);
                    unitData=app.spkInfo.unitid(tf);
                    idxData=idxData(tf);
            end
        end

        function updateMainPlot(app,plotmode)
            %plotmode - 1: normal, 2: tetrode, 3: channel
            %get data
            [xData,yData,unitData,~]=app.getSpikeData(plotmode);

            if isempty(xData) || isempty(yData) %empty channel
                cla(app.MainAxes)
            else
                %plot
                c=colororder;

                neuronId=unique(unitData);
                ms=app.MarkerSzSpinner.Value;

                for i=neuronId
                    if app.HideAssignmentCheckBox.Value==1 || i==0
                        cidx=c(1,:);
                    else
                        cidx=app.units(i).color;
                    end

                    h=plot(app.MainAxes,xData(unitData==i),yData(unitData==i),'o','MarkerSize',ms);
                    set(h,'MarkerFaceColor',cidx,'MarkerEdgeColor',cidx);
                    hold(app.MainAxes,'on')

                end
                hold(app.MainAxes,'off')

                %label axes
                switch plotmode
                    case 1
                        app.MainAxes.XLabel.String=app.xProp;
                        app.MainAxes.YLabel.String=app.yProp;
                    case 2
                        app.MainAxes.XLabel.String=app.xPropTet;
                        app.MainAxes.YLabel.String=app.yPropTet;
                    case 3
                        app.MainAxes.XLabel.String=app.xPropCh;
                        app.MainAxes.YLabel.String=app.yPropCh;
                end

                if app.ShowROICheckBox.Value==1
                    app.plotROI(app.plotmode);
                end

            end
        end

        function plotROI(app,plotmode)
            %find actions made in the current plot mode
            tf=[app.sortActions.plotmode]==plotmode;

            %no polygons to plot for merge, delete and artefact rejection
            %tf = tf & ~strcmp({app.sortActions.type},'rem');
            %tf = tf & ~strcmp({app.sortActions.type},'merge');
            tf = tf & ~ismember({app.sortActions.type},{'rem','merge','artrejOn','artrejOff'});


            %don't proceed if there's nothing to plot already
            if sum(tf)~=0
                %are there roi in the current view
                switch plotmode
                    case 1
                        tf = tf & strcmp({app.sortActions.viewX},app.xProp);
                        tf = tf & strcmp({app.sortActions.viewY},app.yProp);
                    case 2
                        tf = tf & strcmp({app.sortActions.viewXTet},app.xPropTet);
                        tf = tf & strcmp({app.sortActions.viewYTet},app.yPropTet);
                        tf = tf & [app.sortActions.detChTet]==app.detChTet;
                        tf = tf & [app.sortActions.xChTet]==app.xChTet;
                        tf = tf & [app.sortActions.yChTet]==app.yChTet;

                    case 3
                        tf = tf & strcmp({app.sortActions.viewXCh},app.xPropCh);
                        tf = tf & strcmp({app.sortActions.viewYCh},app.yPropCh);
                        tf = tf & [app.sortActions.detChCh]==app.detChCh;
                        tf = tf & [app.sortActions.detChType]==app.detChType;
                end

                hold(app.MainAxes,'on')
                idx=find(tf==1);
                for i=1:length(idx) %will simply not run if there's nothing to plot
                    ps=polyshape(app.sortActions(idx(i)).roi{:});
                    h=plot(app.MainAxes,ps);
                    if strcmp(app.sortActions(idx(i)).type,'addM') %draw minus roi differently
                        h.FaceColor='#D95319';
                    else
                        h.FaceColor='#4DBEEE';
                    end
                    h.FaceAlpha=0.2;
                    app.roihandle=[app.roihandle h];
                end
                hold(app.MainAxes,'off')
            end

        end

        function assignUnit(app,plotmode,unitid,roihandle,actionflag)
            %edit unitid vector
            %actionflag:
            % 1: add a positive roi
            % -1: add a negative roi
            % this operates on the data as shown (i.e.,
            % artefacts are not included)

            %get data
            [xData,yData,~,idxData]=app.getSpikeData(plotmode);


            %get data in ROI
            %selUnit=inROI(roihandle,xData,yData); %logical array
            selUnit=inpolygon(xData,yData,roihandle.Position(:,1),roihandle.Position(:,2)); %logical array

            %edit unitid: add unit for all but negative rois
            if actionflag~=-1
                app.spkInfo.unitid(idxData(selUnit))=unitid;
            else
                %limit to unit chosen by spinner
                tf=selUnit & app.spkInfo.unitid(idxData)==unitid;
                app.spkInfo.unitid(idxData(tf))=0;
            end

        end

        function removeUnit(app,remUnit)
            %delete and renumber units

            %remove the deleted unit from unitid
            app.spkInfo.unitid(app.spkInfo.unitid==remUnit)=0;

            %renumber the remainder
            for i=remUnit+1:app.nrUnits
                app.spkInfo.unitid(app.spkInfo.unitid==i)=i-1;
            end
        end

        function removeUnitTable(app,remUnit)
            %remove unit info from tables
            %update color table - simply delete last element since all ids
            %shift one forward
            app.units(app.nrUnits)=[];

            %remove unit from unitinfo table
            app.unitInfo(remUnit,:)=[];

            %update unit numbers for the rest
            for i=remUnit:app.nrUnits-1
                app.unitInfo.UnitId(i)=i;
            end


        end

        function mergeUnit(app, delUnit,keepUnit)
            %smaller unit number remains, larger gets removed
            app.spkInfo.unitid(app.spkInfo.unitid==delUnit)=keepUnit;

            %renumber the remainder
            for i=delUnit+1:app.nrUnits
                app.spkInfo.unitid(app.spkInfo.unitid==i)=i-1;
            end
        end

        function updateUnitTable(app)
            %update table with unit info
            app.UnitTable.Data=app.unitInfo;

            %set colors for units correctly
            for i=1:app.nrUnits
                cidx=app.units(i).color;
                s=uistyle('BackgroundColor',cidx);
                addStyle(app.UnitTable,s,'cell',[i,1])
            end
        end


        function computeMetrics(app)
            %generate metrics table
            app.unitMetric=table;

            %compute L ratio - do this out here because it requires creating
            %a large matrix, so better not to loop over units
            LRatio=app.computeLRatio();

            %compute metrics for every unit
            for i=1:app.nrUnits
                ISIv=app.computeISI(i);
                FP=app.computeFP(i);

                val={i,ISIv,FP,LRatio(i)};
                if i==1
                    tnames={'UnitId','ISIv','FP','LRatio'};
                    app.unitMetric=cell2table(val,'VariableNames',tnames);
                else
                    app.unitMetric=[app.unitMetric;val];
                end

            end

        end

        function ISIv=computeISI(app,unitIsi)
            %compute ISI violations for a unit
            %minimum isi for now set to 1.2ms
            minISI=0.0012;
            minISIsample=round(minISI*app.sampleFreq);

            %compute minimum ISI violations for a particular unit
            ts=sort(app.spkInfo.spkTimesDet(app.spkInfo.unitid==unitIsi));
            diffTs=diff(ts); %difference between spikes in samples


            perISIv=sum(diffTs<minISIsample)/length(ts);
            ISIv=perISIv*100;

        end

        function FP=computeFP(app,unitFP)
            %compute number of channels over which a unit is dispersed
            chidx=app.spkInfo.detCh(app.spkInfo.unitid==unitFP);
            FP=length(unique(chidx));
        end

        function LRatio=computeLRatio(app)
            %turn singular matrix warning off
            warning('off','MATLAB:singularMatrix');
            %compute L ratio for unit
            %needs feature vector for each spike - for now we will use the
            %following
            featureDim={'EnDet','AmpMinDet','AmpMaxDet','PksDet','WidthDet','detCh'};
            if isfield(app.spkInfo,'PC1Det') %PCs have been computed, so include
                featureDim{end+1}='PC1Det';
                featureDim{end+1}='PC2Det';
                featureDim{end+1}='PC3Det';
            end

            %construct feature matrix
            featureMat=[];
            for i=1:length(featureDim)
                featureMat(:,i)=app.spkInfo.(featureDim{i});
            end

            %loop through units
            nDim=size(featureMat,2);
            for i=1:app.nrUnits

                %compute mean of cluster - first find cells in cluster
                tfClust=app.spkInfo.unitid==i;
                nClust=sum(tfClust);

                %get all other observations, excluding artefacts and
                %duplicates
                tfNoise=app.spkInfo.unitid~=-1;
                tfNoise = tfNoise & app.spkInfo.flagDuplicate==0;
                tfNoise = tfNoise & app.spkInfo.unitid ~=i;

                %get squared mahalonobis distance - vector
                d2=mahal(featureMat(tfNoise,:),featureMat(tfClust,:));

                %enter into cumulative chi2 distribution
                pChi=chi2cdf(d2,nDim);

                %sum to get L
                L=sum(1-pChi);

                %ratio
                LRatio(i)=L/nClust;

            end

        end

        function computeMainCh(app,unitCh)
            %find channels for the unit
            chidx=app.spkInfo.detCh(app.spkInfo.unitid==unitCh);

            %figure out proportion for each channel
            %accumarray sums up number of entries for each bin
            chdist=accumarray(chidx',1,[app.probes.nChannels 1]);

            %find channel with the maximum nr of events
            [~,mxCh]=max(chdist);

            app.unitInfo.Ch(unitCh)=mxCh;

        end

        function spkProps=computeSpkProps(app)

            %compute average spike properties for every unit
            for unit=1:app.nrUnits
                idx=(app.spkInfo.unitid==unit);

                %waveform properties
                MaxMinRatio = abs(app.spkInfo.AmpMaxDet(idx)./app.spkInfo.AmpMinDet(idx));
                WidthI=app.spkInfo.WidthIDet(idx)/app.sampleFreq*1000;
                
                spkProps.avgEn(unit) = mean(app.spkInfo.EnDet(idx));
                spkProps.avgAmpMin(unit) = mean(app.spkInfo.AmpMinDet(idx));
                spkProps.avgAmpMax(unit) = mean(app.spkInfo.AmpMaxDet(idx));
                spkProps.avgAmpMaxBefore(unit) = mean(app.spkInfo.AmpMaxBeforeDet(idx));
                spkProps.avgMaxMinRatio(unit) = mean(MaxMinRatio);
                spkProps.avgPks(unit) = mean(app.spkInfo.PksDet(idx));
                spkProps.avgPksBefore(unit) = mean(app.spkInfo.PksBeforeDet(idx));
                spkProps.avgWidthI(unit) = mean(WidthI);

                spkProps.stdEn(unit) = std(app.spkInfo.EnDet(idx));
                spkProps.stdAmpMin(unit) = std(app.spkInfo.AmpMinDet(idx));
                spkProps.stdAmpMax(unit) = std(app.spkInfo.AmpMaxDet(idx));
                spkProps.stdAmpMaxBefore(unit) = std(app.spkInfo.AmpMaxBeforeDet(idx));
                spkProps.stdMaxMinRatio(unit) = std(MaxMinRatio);
                spkProps.stdPks(unit) = std(app.spkInfo.PksDet(idx));
                spkProps.stdPksBefore(unit) = std(app.spkInfo.PksBeforeDet(idx));
                spkProps.stdWidthI(unit) = std(WidthI);

                %position info
                spkProps.comXMin(unit)=mean(app.spkInfo.comXMinDet(idx));
                spkProps.comZMin(unit)=mean(app.spkInfo.comZMinDet(idx));
                spkProps.comXEn(unit)=mean(app.spkInfo.comXEnDet(idx));
                spkProps.comZEn(unit)=mean(app.spkInfo.comZEnDet(idx));
                spkProps.detChMain(unit)=app.unitInfo.Ch(unit);
                spkProps.detChMainX(unit)=app.probes.x(spkProps.detChMain(unit));
                spkProps.detChMainZ(unit)=app.probes.z(spkProps.detChMain(unit));
                               
            end

        end


        function updateWfPlot(app)
            %plot waveforms

            %determine first unit
            unitID1=app.UnitWfSpinner.Value;

            %get timestamps and detection channel for unit
            ts=app.spkInfo.spkTimesDet(app.spkInfo.unitid==unitID1);
            chidx=app.spkInfo.detCh(app.spkInfo.unitid==unitID1);
            uidx=ones(size(chidx))*unitID1;

            %determine how many spikes should be plotted
            nrSpk=app.NrSpikesSpinner.Value;

            %randomly subselect if there are more than nrSpk
            if length(ts)>nrSpk
                ridx=randperm(length(ts),nrSpk);
                ts=ts(ridx);
                chidx=chidx(ridx);
                uidx=uidx(ridx);
            end

            %do the same for the second unit
            if app.Unit2WfCheckBox.Value==1
                unitID2=app.Unit2WfSpinner.Value;

                ts2=app.spkInfo.spkTimesDet(app.spkInfo.unitid==unitID2);
                chidx2=app.spkInfo.detCh(app.spkInfo.unitid==unitID2);
                uidx2=ones(size(chidx2))*unitID2;

                %randomly subselect if there are more than nrSpk
                if length(ts2)>nrSpk
                    ridx=randperm(length(ts2),nrSpk);
                    ts2=ts2(ridx);
                    chidx2=chidx2(ridx);
                    uidx2=uidx2(ridx);
                end

                %append to first unit (we're joining them to save time
                %while reading data)
                ts=[ts ts2];
                chidx=[chidx chidx2];
                uidx=[uidx uidx2];
            end

            %now read waveforms - map timestamps onto job files
            tsbin=discretize(ts,app.jobEdges); %this works even if not all jobs were used 
            tsunique=unique(tsbin);

            %need loop to open files and read waveforms - first loop over
            %job files
            wb=uiprogressdlg(app.UISortGui,'Title','Extracting waveforms','Message','Reading...','Indeterminate','on');

            fpath=app.filepath;
            exname=app.spkInfo.expname;
            sDir='SpikeFiles';
            if ~isempty(app.tSuffix)
                sDir=[sDir '_' app.tSuffix];
            end
            pId=app.spkInfo.probeId;
            parfor i=1:length(tsunique)
                out(i)=load(fullfile(fpath,sDir,[exname '_j' num2str(tsunique(i)-1)...
                    '_p' num2str(pId) '_spike'])); %generates spikeData
            end

            count=1;
            for i=1:length(tsunique)
                %within job files loop over waveforms
                tsidx=find(tsbin==tsunique(i));
                for j=1:length(tsidx)
                    widx=find(out(i).spikeData(chidx(tsidx(j))).spikeTimes==ts(tsidx(j))); %find which waveform

                    wv=out(i).spikeData(chidx(tsidx(j))).Wvfrms(widx,:,1); %get waveform
                    waveformData(count,:)=squeeze(wv);

                    unitData(count)=uidx(tsidx(j));

                    count=count+1;
                end
            end
            close(wb);
            clear out;

            %now plot
            cidx=app.units(unitID1).color;
            tf=(unitData==unitID1);
            plot(app.WfAxes,waveformData(tf,:)','-','Color',cidx)


            if app.Unit2WfCheckBox.Value==1
                cidx2=app.units(unitID2).color;
                tf=(unitData==unitID2);
                hold(app.WfAxes,'on')
                plot(app.WfAxes,waveformData(tf,:)','-','Color',cidx2)
                hold(app.WfAxes,'off')
            end

        end

        function addSelWf(app,roihandle)
            %need to first figure out which points were selected
            %get data
            [xData,yData,~,idxData]=app.getSpikeData(app.plotmode);

            %get data in ROI
            selUnit=inROI(roihandle,xData,yData);

            %get timestamps and detection channel for these spikes
            ts=app.spkInfo.spkTimesDet(idxData(selUnit));
            chidx=app.spkInfo.detCh(idxData(selUnit));

            %read waveforms - map timestamps onto job files
            tsbin=discretize(ts,app.jobEdges); %this works even if not all jobs were merged for spike file generation
            tsunique=unique(tsbin);

            %need loop to open files and read waveforms - first loop over
            %job files
            sDir='SpikeFiles';
            if ~isempty(app.tSuffix)
                sDir=[sDir '_' app.tSuffix];
            end
            wb=uiprogressdlg(app.UISortGui,'Title','Extracting waveforms','Message','Reading...','Indeterminate','on');
            count=1;
            for i=1:length(tsunique)
                load(fullfile(app.filepath,sDir,[app.spkInfo.expname '_j' num2str(tsunique(i)-1)...
                    '_p' num2str(app.spkInfo.probeId) '_spike'])); %generates spikeData

                %within job files loop over waveforms
                tsidx=find(tsbin==tsunique(i));
                for j=1:length(tsidx)
                    widx=find(spikeData(chidx(tsidx(j))).spikeTimes==ts(tsidx(j))); %find which waveform

                    wv=spikeData(chidx(tsidx(j))).Wvfrms(widx,:,1); %get waveform
                    waveformData(count,:)=squeeze(wv);

                    count=count+1;
                end
            end
            close(wb);

            %now plot
            hold(app.WfAxes,'on')
            plot(app.WfAxes,waveformData','b-')
            hold(app.WfAxes,'off')

        end

        function plotSingleWf(app,roihandle)
            %need to first figure out which point was selected
            %get data
            [xData,yData,~,idxData]=app.getSpikeData(app.plotmode);

            %find closest
            [~,selWf]=min(sqrt((xData-roihandle.Position(1)).^2+(yData-roihandle.Position(2)).^2));


            %get timestamps and detection channel for the spike
            ts=app.spkInfo.spkTimesDet(idxData(selWf));
            chidx=app.spkInfo.detCh(idxData(selWf));

            %read waveforms - map timestamp onto job files
            tsbin=discretize(ts,app.jobEdges); %this works even if not all jobs were merged for spike file generation

            %read waveform
            sDir='SpikeFiles';
            if ~isempty(app.tSuffix)
                sDir=[sDir '_' app.tSuffix];
            end
            load(fullfile(app.filepath,sDir,[app.spkInfo.expname '_j' num2str(tsbin-1)...
                '_p' num2str(app.spkInfo.probeId) '_spike'])); %generates spikeData

            widx=find(spikeData(chidx).spikeTimes==ts); %find which waveform
            wv=spikeData(chidx).Wvfrms(widx,:,1); %get waveform
            waveformData=squeeze(wv);

            %now plot
            if isempty(app.singleWfPlot) %first time plotting
                hold(app.WfAxes,'on')
                app.singleWfPlot=plot(app.WfAxes,waveformData,'k-','Linewidth',2);
                hold(app.WfAxes,'off')
            else
                set(app.singleWfPlot,'YData',waveformData);
            end

            if isempty(app.singleFpPlot)
                hold(app.FpAxes,'on')
                app.singleFpPlot=plot(app.FpAxes,app.probes.x(chidx),app.probes.z(chidx),'rp','MarkerSize',8,'MarkerFaceColor','r');
                hold(app.FpAxes,'off')
            else
                set(app.singleFpPlot,'XData',app.probes.x(chidx),'YData',app.probes.z(chidx));
            end

        end

        function singleWfEvent(app,roi)
            %need to first figure out which point was selected
            app.plotSingleWf(roi);

        end

        function updateFpPlot(app,nrunit)
            %figure out footprint of a unit
            pr=app.probes;

            %get channels
            chidx=app.spkInfo.detCh(app.spkInfo.unitid==nrunit);

            %figure out proportion for each channel
            %accumarray sums up number of entries for each bin
            chdist=accumarray(chidx',1,[pr.nChannels 1]);
            chdist=chdist/length(chidx)*100;

            %plot
            tf=(chdist==0);
            scatter(app.FpAxes,pr.x(tf),pr.z(tf),2,'k','filled')
            hold(app.FpAxes,'on')
            scatter(app.FpAxes,pr.x(~tf),pr.z(~tf),[],chdist(~tf),'filled')
            hold(app.FpAxes,'off')
            axis(app.FpAxes,[min(pr.x)-50 max(pr.x)+50 min(pr.z)-50 max(pr.z)+50]);
            app.FpAxes.XLabel.String='X';
            app.FpAxes.YLabel.String='Z';
        end

        function setButtonsUnits(app,nrUnits,selUnit)
            %function to handle enabling/disabling of buttons based on nr
            %units
            %nrUnits=-1: just turns buttons on, but does not change unit
            %assignments of spinners etc

            %no units - disable all of the unit related things
            if nrUnits==0
                app.DeleteUnitButton.Enable=0;
                app.UnitSpinner.Enable=0;
                app.UnitWfSpinner.Enable=0;
                app.Unit2WfSpinner.Enable=0;
                app.Unit2WfCheckBox.Enable=0;
                app.UnitFpSpinner.Enable=0;
                app.AddMROIButton.Enable=0;
                app.AddPROIButton.Enable=0;
                app.NrSpikesSpinner.Enable=0;
                app.RefreshWfButton.Enable=0;
                app.AddWfButton.Enable=0;
                app.PlotUnitOnlySpinner.Enable=0;
                app.PlotUnitOnlyCheckBox.Enable=0;
                app.HideAssignmentCheckBox.Enable=0;
                %app.SaveViewButton.Enable=0;
                app.UnitMetricsButton.Enable=0;
                app.AutoCatButton.Enable=0;
                app.MergeButton.Enable=0;
                app.MergeUnitSpinner.Enable=0;
                app.SaveButton.Enable=0;
            elseif nrUnits==-1
                app.DeleteUnitButton.Enable=1;
                app.UnitSpinner.Enable=1;
                app.UnitWfSpinner.Enable=1;
                app.Unit2WfSpinner.Enable=1;
                app.Unit2WfCheckBox.Enable=1;
                app.UnitFpSpinner.Enable=1;
                app.AddMROIButton.Enable=1;
                app.AddPROIButton.Enable=1;
                app.NrSpikesSpinner.Enable=1;
                app.RefreshWfButton.Enable=1;
                app.AddWfButton.Enable=1;
                app.PlotUnitOnlySpinner.Enable=1;
                app.PlotUnitOnlyCheckBox.Enable=1;
                app.HideAssignmentCheckBox.Enable=1;
                %app.SaveViewButton.Enable=1;
                app.UnitMetricsButton.Enable=1;
                app.AutoCatButton.Enable=1;
                app.MergeButton.Enable=1;
                app.MergeUnitSpinner.Enable=1;
                app.SaveButton.Enable=1;
            elseif nrUnits==1
                app.DeleteUnitButton.Enable=1;
                app.UnitSpinner.Value=1;
                app.UnitSpinner.Enable=0;
                app.UnitWfSpinner.Value=1;
                app.UnitWfSpinner.Enable=0;
                app.Unit2WfSpinner.Enable=0;
                app.Unit2WfCheckBox.Enable=0;
                app.UnitFpSpinner.Value=1;
                app.UnitFpSpinner.Enable=0;
                app.AddMROIButton.Enable=1;
                app.AddPROIButton.Enable=1;
                app.NrSpikesSpinner.Enable=1;
                app.RefreshWfButton.Enable=1;
                app.AddWfButton.Enable=1;
                app.PlotUnitOnlySpinner.Enable=0;
                app.PlotUnitOnlySpinner.Value=1;
                app.PlotUnitOnlyCheckBox.Enable=1;
                app.HideAssignmentCheckBox.Enable=1;
                %app.SaveViewButton.Enable=1;
                %app.ApplyViewButton.Enable=1;
                app.UnitMetricsButton.Enable=1;
                app.AutoCatButton.Enable=1;
                app.MergeButton.Enable=0;
                app.MergeUnitSpinner.Enable=0;
                app.SaveButton.Enable=1;
            else
                app.DeleteUnitButton.Enable=1;
                app.UnitSpinner.Enable=1;
                app.UnitSpinner.Limits=[1 nrUnits];
                app.UnitSpinner.Value=selUnit;

                app.MergeButton.Enable=1;
                app.MergeUnitSpinner.Enable=1;
                app.MergeUnitSpinner.Limits=[1 nrUnits];

                app.UnitWfSpinner.Enable=1;
                app.UnitWfSpinner.Limits=[1 nrUnits];
                app.UnitWfSpinner.Value=selUnit;
                app.Unit2WfSpinner.Enable=1;
                app.Unit2WfSpinner.Limits=[1 nrUnits];
                app.Unit2WfCheckBox.Enable=1;
                %app.Unit2WfSpinner.Value=nrUnits;
                app.NrSpikesSpinner.Enable=1;
                app.RefreshWfButton.Enable=1;
                app.AddWfButton.Enable=1;

                app.UnitFpSpinner.Enable=1;
                app.UnitFpSpinner.Limits=[1 nrUnits];
                app.UnitFpSpinner.Value=selUnit;

                app.AddMROIButton.Enable=1;
                app.AddPROIButton.Enable=1;

                app.PlotUnitOnlySpinner.Enable=1;
                app.PlotUnitOnlySpinner.Limits=[1 nrUnits];
                app.PlotUnitOnlyCheckBox.Enable=1;
                app.PlotUnitOnlySpinner.Value=selUnit;
                app.HideAssignmentCheckBox.Enable=1;

                app.UnitMetricsButton.Enable=1;
                app.AutoCatButton.Enable=1;

                %app.SaveViewButton.Enable=1;
                app.SaveButton.Enable=1;
            end

        end

        function setButtons(app,buttonState)
            app.ApplyHistoryButton.Enable=buttonState;
           
            app.MarkerSzSpinner.Enable=buttonState;
            app.XaxisDropDown.Enable=buttonState;
            app.YaxisDropDown.Enable=buttonState;
            app.LimitChButton.Enable=buttonState;
            app.ResetChButton.Enable=buttonState;

            if app.probes.nChannels>1
                app.TetDetChSpinner.Enable=buttonState;
                app.TetXaxisDropDown.Enable=buttonState;
                app.TetYaxisDropDown.Enable=buttonState;
                app.TetChXDropDown.Enable=buttonState;
                app.TetChYDropDown.Enable=buttonState;
                app.ChInclNBCheckBox.Enable=buttonState;
            end

            app.ChTypeButton.Enable=buttonState;
            if app.probes.nChannels>1
                app.ChDetChSpinner.Enable=buttonState;
            end
            app.ChXaxisDropDown.Enable=buttonState;
            app.ChYaxisDropDown.Enable=buttonState;

            app.AddUnitButton.Enable=buttonState;
            app.ArtRejSwitch.Enable=buttonState;
        end

        function setTetDropDowns(app)
            %set the channel dropdowns for the tetrode mode based on the detection
            %channels
            app.TetDetChSpinner.Value=app.detChTet;

            %update dropdowns
            idx=find(app.spkInfo.detCh==app.detChTet);
            if ~isempty(idx)
                chList=app.spkInfo.chListAll{idx(1)};
                chList=cellstr(num2str(chList'));

                app.TetChXDropDown.Items=chList;
                app.TetChXDropDown.Value=num2str(chList{1});
                app.xChTet=str2num(app.TetChXDropDown.Value);

                app.TetChYDropDown.Items=chList;
                app.TetChYDropDown.Value=num2str(chList{length(chList)});
                app.yChTet=str2num(app.TetChYDropDown.Value);
            end
        end


        function iniProbePlot(app)
            %get property names - for plotting purposes, only retain the ones
            %without the all
            sp=fieldnames(app.spkInfo);
            app.spkProp=sp(~contains(sp,'All'));

            %need to remove dateprops and expname
            %app.spkProp=app.spkProp(~strcmp(app.spkProp,'jobRange'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'expname'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'flagDuplicate'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'NDuplicate'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'probeId'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'duplicateMxCh'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'duplicateMxIdx'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'spkWindow'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'spkWindowI'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'spkTol'));
            app.spkProp=app.spkProp(~strcmp(app.spkProp,'spkInterp'));

            %set dropdowns for probe view
            app.XaxisDropDown.Items=app.spkProp;
            app.YaxisDropDown.Items=app.spkProp;

            app.XaxisDropDown.Value='detCh'; %this has to be there for other stuff, so no check
            app.xProp=app.XaxisDropDown.Value;

            if any(strcmp(app.spkProp,'AmpMinDet'))
                app.YaxisDropDown.Value='AmpMinDet';
            else
                app.YaxisDropDown.Value=app.spkProp{2};
            end
            app.yProp=app.YaxisDropDown.Value;
        end


        function iniTetrodePlot(app)
            sp=fieldnames(app.spkInfo);
            app.spkPropTet=sp(contains(sp,'All'));
            app.spkPropTet=app.spkPropTet(~strcmp(app.spkPropTet,'chListAll'));

            app.TetXaxisDropDown.Items=app.spkPropTet;
            app.TetYaxisDropDown.Items=app.spkPropTet;

            app.TetXaxisDropDown.Value=app.spkPropTet{1};
            app.TetYaxisDropDown.Value=app.spkPropTet{1};
            app.xPropTet=app.TetXaxisDropDown.Value;
            app.yPropTet=app.TetYaxisDropDown.Value;


            app.TetDetChSpinner.Value=1;
            app.TetDetChSpinner.Limits=[1 app.probes.nChannels];

            app.detChNbCh=0;
            app.ChInclNBCheckBox.Value=0;

            %to get channels for initial selection in tetrode mode, need to find chlist for
            %one channel; using the lowest one that exists
            app.detChTet=min(app.spkInfo.detCh);
            idx=find(app.spkInfo.detCh==min(app.spkInfo.detCh));
            chList=app.spkInfo.chListAll{idx(1)};
            chList=cellstr(num2str(chList'));

            app.TetChXDropDown.Items=chList;
            app.TetChXDropDown.Value=num2str(chList{1});
            app.xChTet=str2num(app.TetChXDropDown.Value);

            app.TetChYDropDown.Items=chList;
            app.TetChYDropDown.Value=num2str(chList{length(chList)}); %this works even if there is just a single channel per neighborhood
            app.yChTet=str2num(app.TetChYDropDown.Value);
        end


        function iniChannelPlot(app)
            %depends on iniProbePlot being run first to populate
            %app.spkProp
            app.spkPropCh=app.spkProp;
            app.spkPropCh=app.spkPropCh(~strcmp(app.spkPropCh,'detCh'));
            app.spkPropCh=app.spkPropCh(~strcmp(app.spkPropCh,'detChSort'));

            app.ChXaxisDropDown.Items=app.spkPropCh;
            app.ChYaxisDropDown.Items=app.spkPropCh;

            app.ChXaxisDropDown.Value=app.spkPropCh{1};
            app.ChYaxisDropDown.Value=app.spkPropCh{2};
            app.xPropCh=app.ChXaxisDropDown.Value;
            app.yPropCh=app.ChYaxisDropDown.Value;

            app.detChType=1;
            app.detChCh=1;
            app.ChDetChSpinner.Value=1;
            if app.probes.nChannels>1
                app.ChDetChSpinner.Limits=[1 app.probes.nChannels];
            end
        end


        function addRoiAction(app,actionType,newRoi,unitnr)
            %record action affecting the spike sorting
            app.sortActionsCount=app.sortActionsCount+1;
            count=app.sortActionsCount;

            app.sortActions(count).type=actionType;
            app.sortActions(count).unitid=unitnr;
            if ~isempty(newRoi)
                app.sortActions(count).roi{1}=newRoi.Position;
            end

            app.sortActions(count).plotmode=app.plotmode;

            %saving all viewing parameters even for the inappropriate
            %plotmodes to keep all structures identical
            %for the same reason, even save them for artefact changes
            app.sortActions(count).viewX=app.xProp;
            app.sortActions(count).viewY=app.yProp;
            app.sortActions(count).selectedCh=app.selectedCh;

            app.sortActions(count).viewXTet=app.xPropTet;
            app.sortActions(count).viewYTet=app.yPropTet;
            app.sortActions(count).detChTet=app.detChTet;
            app.sortActions(count).xChTet=app.xChTet;
            app.sortActions(count).yChTet=app.yChTet;

            app.sortActions(count).viewXCh=app.xPropCh;
            app.sortActions(count).viewYCh=app.yPropCh;
            app.sortActions(count).detChType=app.detChType;
            app.sortActions(count).detChCh=app.detChCh;
            app.sortActions(count).detChNb=app.detChNbCh;

            %save visibility settings
            app.sortActions(count).plotUnitOnly=app.PlotUnitOnlyCheckBox.Value;
            app.sortActions(count).plotUnitValue=app.PlotUnitOnlySpinner.Value;

            if ~isempty(app.unitInfo)
                app.sortActions(count).visUnit=app.unitInfo.Vis;
            else
                app.sortActions(count).visUnit=[];
            end

            %save artefact selections
            if strcmp(actionType,'artrejOn')
                app.sortActions(count).artNCh=app.artNCh;
                app.sortActions(count).artThreshold=app.artThreshold;
            else
                app.sortActions(count).artNCh=NaN;
                app.sortActions(count).artThreshold=NaN;
            end

            %book keeping
            app.sortActions(count).date=date;
        end

        function saveState(app)
            %preserve current settings for undo
            app.undoUnitId=app.spkInfo.unitid;
            app.undoUnitInfo=app.unitInfo;
            app.undoUnits=app.units;
        end


        function applyHistory(app)
            %go through and apply the steps per the history
            for i=1:app.sortActionsCount
                %apply view parameters (set everything, independent
                %of selected mode)
                app.xProp=app.sortActions(i).viewX;
                app.yProp=app.sortActions(i).viewY;
                app.selectedCh=app.sortActions(i).selectedCh;

                app.xPropTet=app.sortActions(i).viewXTet;
                app.yPropTet=app.sortActions(i).viewYTet;
                app.detChTet=app.sortActions(i).detChTet;
                app.xChTet=app.sortActions(i).xChTet;
                app.yChTet=app.sortActions(i).yChTet;

                app.xPropCh=app.sortActions(i).viewXCh;
                app.yPropCh=app.sortActions(i).viewYCh;
                app.detChCh=app.sortActions(i).detChCh;
                app.detChType=app.sortActions(i).detChType;
                app.detChNbCh=app.sortActions(i).detChNb;


                %apply visibility
                if app.nrUnits>1
                    app.unitInfo.Vis=app.sortActions(i).visUnit;
                end

                %set unitid
                unitid=app.sortActions(i).unitid;

                switch app.sortActions(i).type
                    case 'new'
                        roi.Position=app.sortActions(i).roi{:};
                        app.assignUnit(app.sortActions(i).plotmode,unitid,roi ,1);

                        %add properties
                        c=colororder;
                        c=c(2:end,:); %first one reserved for unassigned units
                        cidx=mod(unitid-1,size(c,1))+1;
                        app.units(unitid).color=c(cidx,:);

                        %add to table info
                        val={unitid,NaN,1,'None'};
                        if app.nrUnits==0
                            tnames={'UnitId','Ch','Vis','Cat'};
                            app.unitInfo=cell2table(val,'VariableNames',tnames);
                            %add categories
                            app.unitInfo.Cat=categorical(app.unitInfo.Cat,{'Noise','None','SU','MU','SU?'},'Protected',true);
                            app.unitInfo.Vis=categorical(app.unitInfo.Vis,[0 1],'Protected',true);
                        else
                            %need to make a temp table first
                            tnames={'UnitId','Ch','Vis','Cat'};
                            tmpTbl=cell2table(val,'VariableNames',tnames);
                            %add categories
                            tmpTbl.Cat=categorical(tmpTbl.Cat,{'Noise','None','SU','MU','SU?'},'Protected',true);
                            tmpTbl.Vis=categorical(tmpTbl.Vis,[0 1],'Protected',true);
                            app.unitInfo=[app.unitInfo;tmpTbl];
                        end

                        %increase counter
                        app.nrUnits=app.nrUnits+1; %starts with 0

                    case 'rem'
                        app.removeUnit(unitid);
                        app.remUnitTable(unitid);

                        app.nrUnits=app.nrUnits-1;

                    case 'addP'
                        roi.Position=app.sortActions(i).roi{:};
                        app.assignUnit(app.sortActions(i).plotmode,unitid,roi,1);

                    case 'addM'
                        roi.Position=app.sortActions(i).roi{:};
                        app.assignUnit(app.plotmode,unitid,roi,-1);

                    case 'merge'
                        delUnit=max(unitid);
                        keepUnit=min(unitid);
                        app.mergeUnit(delUnit,keepUnit);
                        app.remUnitTable(delUnit);
                        app.nrUnits=app.nrUnits-1;

                    case 'artrejOn'
                        if isnan(app.sortActions(i).artThreshold) %only using channel criterion
                            artIdx = find(app.spkInfo.NDuplicate>=app.sortActions(i).artNCh);
                        else %channel and threshold
                            if app.sortActions(i).artThreshold<0
                                artIdx = find(app.spkInfo.NDuplicate>=app.sortActions(i).artNCh & ...
                                    app.spkInfo.AmpMinDet<=app.sortActions(i).artThreshold);
                            else
                                artIdx = find(app.spkInfo.NDuplicate>=app.sortActions(i).artNCh & ...
                                    app.spkInfo.AmpMaxDet>=app.sortActions(i).artThreshold);
                            end
                        end
                        app.spkInfo.unitid(artIdx)=-1;
                        %we don't need to do anything about potentialy
                        %deleted units, as that is recorded as a separate
                        %action

                    case 'artrejOff'
                        tf=(app.spkInfo.unitid==-1);
                        app.spkInfo.unitid(tf)=0;
                end
            end
        end


    end

    methods (Access = public)

        function getSelCh(app,selCh)
            app.selectedCh=selCh;
        end

        function getArtefacts(app,NCh,threshold)
            app.artNCh=NCh;
            app.artThreshold=threshold;
        end

        function getIDfile(app,probe,jobIDstart,jobIDstop,percJobs)
            app.jobIDstart=jobIDstart;
            app.jobIDstop=jobIDstop;
            app.probeID=probe;
            app.percJobs=percJobs;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIDButton
        function LoadIDButtonPushed(app, event)
            %first get id file
            [IDname,p] = uigetfile('*id.mat','Select ID file');
            figure(app.UISortGui)

            if ~isequal(p,0)

                %basic file info
                %filename will be basic experiment name that is shared
                %across all files
                fpart=strsplit(IDname,'_');
                expname=[fpart{1} '_' fpart{2} '_' fpart{3}];

                app.filepath=p;
                app.filename=expname;
                %app.FilenameField.Value=app.filename;
                app.filetype='id';

                %set probe ID
                app.probeID=app.ProbeSpinner.Value;

                %check whether there are multiple versions - look for
                %extractSpike parameter files, because we need that next
                tmpname=replace(IDname,'id',['p' num2str(app.probeID) '_extractSpk*']);
                extractFiles=dir(fullfile(app.filepath,tmpname));
                %this will catch extractSpkProp as well, eliminate first
                extractFilesId=~contains({extractFiles.name},'extractSpkProp');

                %load extractSpk settings
                if sum(extractFilesId)==1
                    %just one set of spike files
                    eid=find(extractFilesId==1);
                    app.extractName=extractFiles(eid).name;
                else
                    [f2,p2] = uigetfile({'*extractSpk.mat; *extractSpk_*.mat'},'Select extractSpk file',app.filepath);
                    figure(app.UISortGui)
                    if isequal(p2,0)
                        return;
                    end
                    app.extractName=f2;
                end
                load(fullfile(app.filepath,app.extractName)); %generates extractSpk

                %extract suffix - last thing before .mat
                fp1=strsplit(app.extractName,'_');
                fp2=strsplit(fp1{end},'.');
                if ~contains(fp2{1},'extractSpk')
                    app.tSuffix=fp2{1};
                else
                    app.tSuffix='';
                end
                app.SuffixField.Value=app.tSuffix;
                
                %copy over threshold file information (to be saved later)
                app.threshinfo=extractSpk.threshold;
 
                %load id data
                load(fullfile(app.filepath,IDname));

                %get general info
                app.probes=id.probes(app.probeID);
                probeOrg=[app.probes.z app.probes.shaft app.probes.channels+1];
                app.probeSort=sortrows(probeOrg,[2 1]);
                app.sampleFreq=id.sampleFreq;
                app.jobEdges=extractSpk.jobEdges;

                %preset and enable jobs buttons 
                maxJob=length(extractSpk.jobStop)-1;
                app.JobStopSpinner.Value=maxJob;
                app.JobStartSpinner.Limits=[0,maxJob];
                app.JobStopSpinner.Limits=[0,maxJob];
                app.JobStartSpinner.Enable=1;
                app.JobStopSpinner.Enable=1;
                app.PercJobSpinner.Enable=1;
                app.LoadJobs.Enable=1;

            end
        end

        % Button pushed function: LoadJobs
        function LoadJobsButtonPushed(app, event)

            %generate list of jobs
            app.jobIDstart=app.JobStartSpinner.Value;
            app.jobIDstop=app.JobStopSpinner.Value;
            app.percJobs=app.PercJobSpinner.Value;
            if app.percJobs==100
                app.jobsList=[app.jobIDstart:app.jobIDstop];
            else
                nTotal=(app.jobIDstop-app.jobIDstart+1);
                nJobs=round(nTotal*app.percJobs/100);
                selId=sort(randperm(nTotal,nJobs)); %starts with 1
                app.jobsList=selId-1+app.jobIDstart;
            end

            %construct spkInfo
            app.loadSpkInfo();

            %update file info table
            app.fileinfo=table;
            tnames={'Param.','Value'};
            app.fileinfo=cell2table({'Name',app.filename},'VariableNames',tnames);
            app.fileinfo=[app.fileinfo;{'SpikeFiles suffix',app.tSuffix}];
            app.fileinfo=[app.fileinfo;{'Probe',num2str(app.probeID)}];
            app.fileinfo=[app.fileinfo;{'Job start',num2str(app.jobIDstart)}];
            app.fileinfo=[app.fileinfo;{'Job stop',num2str(app.jobIDstop)}];
            app.fileinfo=[app.fileinfo;{'Perc jobs',num2str(app.percJobs)}];
            app.FileTable.Data=app.fileinfo;
            %app.FileTable.ColumnName=app.fileinfo.Properties.VariableNames;

            %initialize channel selection
            app.selectedCh=[];

            %initialize plot mode to normal
            app.plotmode=1;

            %initialize all 3 plots (configure drop downs)
            app.iniProbePlot();
            app.iniChannelPlot(); %needs iniProbePlot to run first
            if app.probes.nChannels>1 %no point doing this fir single electrodes
                app.iniTetrodePlot();
            end

            %initialize unit stuff
            app.units=struct;
            app.spkInfo.unitid=zeros(size(app.spkInfo.detCh));
            app.nrUnits=0;
            app.unitInfo=table;

            %initialize sortActions: since we loaded an id file, no
            %history file will be loaded
            app.sortActions=struct;
            app.sortActionsCount=0;
            app.sortActionsStart=1;

            %plot the spikes
            app.updateMainPlot(app.plotmode);

            %update buttons
            app.setButtons(1);
            app.WfReaderButton.Enable=1; %need to keep this separate to be able to use setButtons in its callback
            if app.percJobs<100
                app.RefreshJobsButton.Enable=1;
                app.LinkDBCheckBox.Enable=0; %do not enter partial results
                app.LinkDBCheckBox.Value=0;
            end

            %update buttons for units
            app.setButtonsUnits(app.nrUnits,1);


            %need access to toolbar to be able to deselect buttons
            app.tbhandle=axtoolbar(app.MainAxes,'default');

        end

        % Button pushed function: LoadSortButton
        function LoadSortButtonPushed(app, event)
            [sortName,p] = uigetfile({'*spkSort*.mat;*partSpkSort*.mat'},'Select sort file');
            figure(app.UISortGui)
            if ~isequal(p,0)
                
                fpart=strsplit(sortName,'_');
                expname=[fpart{1} '_' fpart{2} '_' fpart{3}];
                   
                app.filepath=p;
                app.filename=expname;  %filename is shared part across all files
                app.filetype='sort';

                %load sort file - generates spkSort
                load(fullfile(p,sortName));

                %we'll allow the spkSort files to have filenames that do
                %not match onto the SpikeFiles they have used
                %so rather than using the filename, use the saved suffix
                %here
                app.tSuffix=spkSort.info.spikeFilesSuffix;
                app.SuffixField.Value=app.tSuffix;

                %set initials field
                app.InitialsField.Value=spkSort.info.name;

                %set probe, job start and stop correctly (still need to
                %read data)
                app.probeID=spkSort.info.probeid;
                app.jobIDstart=spkSort.info.jobStart;
                app.jobIDstop=spkSort.info.jobStop;
                app.percJobs=spkSort.info.percJobs;
                app.jobsList=spkSort.info.jobsList;

                %for book keeping only
                app.threshinfo=spkSort.info.thresholdInfo;
     
                %load id file               
                load(fullfile(p,[app.filename '_id.mat'])); %generates id

                %get general info
                app.probes=id.probes(app.probeID);
                probeOrg=[app.probes.z app.probes.shaft app.probes.channels+1];
                app.probeSort=sortrows(probeOrg,[2 1]);
                app.sampleFreq=id.sampleFreq;
                app.jobEdges=spkSort.info.jobEdges;
                
                
                %load history file if it exists, otherwise initialize
                if app.percJobs==100
                    hname=replace(sortName,'spkSort','sortHist');
                else
                    hname=replace(sortName,'partSpkSort','partSortHist');
                end
                if isfile(fullfile(p,hname))
                    load(fullfile(p,hname)); %generates sortHist
                    app.sortActions=sortHist;
                    app.sortActionsStart=length(app.sortActions);
                    app.sortActionsCount=app.sortActionsStart;
                    app.ShowROICheckBox.Enable=1;
                else
                    app.sortActions=struct;
                    app.sortActionsCount=0;
                    app.sortActionsStart=-1; %need to note somewhere that the sort history is incomplete
                end

                %construct spkInfo
                app.loadSpkInfo();

                %it is possible that the spike files and sort file don't
                %match (usually if a sort is redone later) - adjust here
                if length(spkSort.spktimes)~=length(app.spkInfo.spkTimesDet)
                    spkSort.unitid=app.adjustSpkSort(spkSort);
                end

               
                %populate file info table
                app.fileinfo=table;
                tnames={'Param.','Value'};
                app.fileinfo=cell2table({'Name',app.filename},'VariableNames',tnames);
                app.fileinfo=[app.fileinfo;{'SpikeFiles suffix',app.tSuffix}];
                app.fileinfo=[app.fileinfo;{'Probe',num2str(app.probeID)}];
                app.fileinfo=[app.fileinfo;{'Job start',num2str(app.jobIDstart)}];
                app.fileinfo=[app.fileinfo;{'Job stop',num2str(app.jobIDstop)}];
                app.fileinfo=[app.fileinfo;{'Perc jobs',num2str(app.percJobs)}];
                app.FileTable.Data=app.fileinfo;


                %initialize channel selection
                app.selectedCh=[];

                %initialize plot mode to normal
                app.plotmode=1;

                %initialize all 3 plots (configure drop downs)
                app.iniProbePlot();
                app.iniChannelPlot(); %needs iniProbePlot to run first
                if app.probes.nChannels>1 %no point doing this fir single electrodes
                    app.iniTetrodePlot();
                end

                %copy sort info into unit
                app.spkInfo.unitid=spkSort.unitid;
                app.nrUnits=max(app.spkInfo.unitid);

                %set up nr units, unit tables, colors
                c=colororder;
                c=c(2:end,:); %first one reserved for unassigned units
                tnames={'UnitId','Ch','Vis','Cat'};

                app.unitInfo=table;
                val={};
                for i=1:app.nrUnits
                    val(i,:)={i,NaN,1,spkSort.unitinfo{i}};
                    cidx=mod(i-1,size(c,1))+1;
                    app.units(i).color=c(cidx,:);
                end
                app.unitInfo=cell2table(val,'VariableNames',tnames);
                app.unitInfo.Cat=categorical(app.unitInfo.Cat,{'Noise','None','SU','MU','SU?'},'Protected',true);
                app.unitInfo.Vis=categorical(app.unitInfo.Vis,[0 1],'Protected',true);
                %function updates existing table, so needs to be in a
                %separate loop
                for i=1:app.nrUnits
                    app.computeMainCh(i);
                end

                %set artefact slider correctly
                if strcmp(spkSort.info.artRej,'On')
                    app.ArtRejSwitch.Value='On';
                    app.artNCh=spkSort.info.artNCh;
                    app.artThreshold=spkSort.info.artThreshold;
                end


                if app.nrUnits>0
                    %update waveform plot
                    app.updateWfPlot();

                    %update footprint plot
                    app.updateFpPlot(1);
                end

                %update table
                app.updateUnitTable();

                %plot the spikes
                app.updateMainPlot(app.plotmode);

                %update buttons
                app.setButtons(1);
                app.WfReaderButton.Enable=1;
                if app.percJobs<100
                    app.RefreshJobsButton.Enable=1;
                    app.LinkDBCheckBox.Enable=0; %do not enter partial results
                    app.LinkDBCheckBox.Value=0;
                end

                %update buttons for units
                app.setButtonsUnits(app.nrUnits,1);
                if app.nrUnits>0
                    app.SaveButton.Enable=1;
                end

                %need access to toolbar to be able to deselect buttons
                app.tbhandle=axtoolbar(app.MainAxes,'default');

            end
        end

        % Button pushed function: RefreshJobsButton
        function RefreshJobsButtonPushed(app, event)
            %generate new list of jobs (this will only allowed to happen if
            %less than 100 percent jobs were chosen, so do not need to
            %check
            nTotal=(app.jobIDstop-app.jobIDstart+1);
            nJobs=round(nTotal*app.percJobs/100);
            selId=sort(randperm(nTotal,nJobs)); %starts with 1
            app.jobsList=selId-1+app.jobIDstart;

            %refresh spkInfo
            app.loadSpkInfo();
            app.spkInfo.unitid=zeros(size(app.spkInfo.detCh));
            app.nrUnits=0;
            app.units=struct;
            oldUnitInfo=app.unitInfo;
            app.unitInfo=table;

            %apply current sort history
            app.applyHistory();

            %revert settings back to current view - use drop down
            %values to keep everything in sync
            app.xProp=app.XaxisDropDown.Value;
            app.yProp=app.YaxisDropDown.Value;
            app.selectedCh=[];
            app.SelChLabel.Text='';

            app.xPropTet=app.TetXaxisDropDown.Value;
            app.yPropTet=app.TetYaxisDropDown.Value;
            app.detChTet=app.TetDetChSpinner.Value;
            app.xChTet=app.TetChXDropDown.Value;
            app.yChTet=app.TetChXDropDown.Value;

            app.xPropCh=app.ChXaxisDropDown.Value;
            app.yPropCh=app.ChYaxisDropDown.Value;
            app.detChCh=app.ChDetChSpinner.Value;
            if app.ChTypeButton.Value==0 %sort channel
                app.detChType=1;
            else
                app.detChType=2;
            end

            %update unit info - will bump down any SU that no longer meet
            %the criterion
            app.computeMetrics(); %update metrics first
            for i=1:app.nrUnits
                app.computeMainCh(i);
                app.unitInfo.Vis(i)=categorical(1);
                idx=find(oldUnitInfo.UnitId==i);
                if strcmp(oldUnitInfo.Cat(idx),'SU') || strcmp(oldUnitInfo.Cat(idx),'SU?')
                    if app.unitMetric.ISIv(i)==0 %can stay as SU
                        app.unitInfo.Cat(i)=oldUnitInfo.Cat(idx);
                    else
                        app.unitInfo.Cat(i)='MU';
                    end
                else
                    app.unitInfo.Cat(i)=oldUnitInfo.Cat(idx);
                end
            end
            %update table
            app.updateUnitTable();
            app.updateMainPlot(app.plotmode);
            app.setButtonsUnits(app.nrUnits,1);
            app.updateWfPlot();
            app.updateFpPlot(1);
            app.ShowROICheckBox.Enable=1;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.InitialsField.Value)
                uialert(app.UISortGui,'Set initials before saving','Error');
                return;
            end

            %save the sorted data necessary for further analysis

            %relevant data
            spkSort.unitid=app.spkInfo.unitid; %units per spike
            spkSort.spktimes=app.spkInfo.spkTimesDet; %times of every spike
            spkSort.detCh=app.spkInfo.detCh; %channel of every spike
            spkSort.detChSort=app.spkInfo.detChSort; %channel as sorted according to probe
            spkSort.flagDuplicate=app.spkInfo.flagDuplicate;
            spkSort.unitinfo=cellstr(app.unitInfo.Cat); %categories of the different units


            %make sure metrics are up to date
            app.computeMetrics();
            spkSort.unitisi=app.unitMetric.ISIv; %isi per unit
            spkSort.unitFP=app.unitMetric.FP; %nr of channels per unit
            spkSort.unitLRatio=app.unitMetric.LRatio;

            %quality check - make sure SU are not incorrectly assigned
            if any(strcmp(spkSort.unitinfo,'SU') & app.unitMetric.ISIv~=0)
                uialert(app.UISortGui,'There are SU with ISI violations - change assignments','Error');
                return;
            end

            %get spike properties
            spkSort.spkProps=app.computeSpkProps();

            %extra info for book kepping
            spkSort.info.probeid=app.spkInfo.probeId;
            spkSort.info.jobStart=app.jobIDstart;
            spkSort.info.jobStop=app.jobIDstop;
            spkSort.info.percJobs=app.percJobs;
            spkSort.info.jobsList=app.jobsList;
            spkSort.info.jobEdges=app.jobEdges;
            spkSort.info.name=app.InitialsField.Value;
            spkSort.info.date=date;
            spkSort.info.expname=app.spkInfo.expname;
            spkSort.info.spikeFilesSuffix=app.tSuffix;
            spkSort.info.thresholdInfo=app.threshinfo;
            spkSort.info.artRej=app.ArtRejSwitch.Value;
            if strcmp(app.ArtRejSwitch.Value,'On')
                spkSort.info.artNCh=app.artNCh;
                spkSort.info.artThreshold=app.artThreshold;
            end

            %check whether tSuffix and suffix in edit field match - ask if
            %not
            if ~strcmp(app.tSuffix,app.SuffixField.Value)
                sel=uiconfirm(app.UISortGui,'Suffix of loaded file does not match suffix for saving. Proceed?','Confirm');
                if strcmp(sel,'Cancel')
                    return;
                end              
            end
           
            %local copy first (no longer adding info to id or other file; sort file
            %has all necessary information)
            if app.percJobs<100
                spkname=[app.filename '_p' num2str(app.spkInfo.probeId) '_partSpkSort'];
            else
                spkname=[app.filename '_p' num2str(app.spkInfo.probeId) '_spkSort'];
            end

            if ~isempty(app.SuffixField.Value)
                spkname=[spkname '_' app.SuffixField.Value];
            end
            save(fullfile(app.filepath,spkname),'spkSort');

           
            %save history of actions (for resorting)
            %since we load the history at the start (at least when loading
            %a sorted file), don't need to load it here again
            %we add initials here to avoid having to set them from the
            %beginning
            if app.percJobs<100
                actname=replace(spkname,'partSpkSort','partSortHist');
            else
                actname=replace(spkname,'spkSort','sortHist');
            end
            startA=max(app.sortActionsStart,1); %to deal with the -1 case for incomplete history
            for i=startA:app.sortActionsCount
                app.sortActions(i).name=spkSort.info.name;
            end
            sortHist=app.sortActions;
            if app.sortActionsStart==-1
                sortHist(1).warning='History incomplete';
            end
            save(fullfile(app.filepath,actname),'sortHist');


            %also copy to z if selected
            saveOnZ=0;
            if app.CopyZCheckBox.Value==1
                fpart=strsplit(app.filename,'_');
                zbase=fullfile('Z:\EphysNew\processedSpikes',fpart{1},app.filename);

                %check whether the file exists, and offer options if yes
                if exist(fullfile(zbase,spkname),'file')
                    sel=uiconfirm(app.UISortGui,'Sort file exists on Z.','Options for Z',...
                        'Options',{'Overwrite','Cancel save on Z'},...
                        'Icon','warning');

                    if ~strcmp(sel,'Cancel save on Z') %don't want to return here, rest can still get executed
                        save(fullfile(zbase,spkname),'spkSort');
                        save(fullfile(zbase,actname),'sortHist');
                        saveOnZ=1;
                    end
                else
                    saveOnZ=1;
                    save(fullfile(zbase,spkname),'spkSort');
                    save(fullfile(zbase,actname),'sortHist');
                end
            end

            uialert(app.UISortGui,'Sort file saved','Done','Icon','success');

            %if saved to z, force database entry
           % if saveOnZ==1 && app.LinkDBCheckBox.Value==0
           %     uialert(app.UISortGui,'Forced entry into database because saving on Z','Warning')
           %     app.LinkDBCheckBox.Value=1;
           % end

            %if database selected, also add entry there
            %select database connector
            % if app.LinkDBCheckBox.Value==1
            %     listDB=listDataSources();
            %     [index,tf]=listdlg('ListString',listDB.Name,'SelectionMode','single','PromptString','Select database connector');
            % 
            %     if tf==1 %selection was made
            %         %connect to database
            %         connSQL=database(listDB.Name{index},'','');
            %         if ~isempty(connSQL.Message)
            %             uialert(app.UISortGui,'Cannot open MySQL Database!','Error')
            %             return;
            %         end
            % 
            %         %find the correct experiment
            %         fpart=strsplit(app.filename,'_');
            %         selectquery=['SELECT experimentKey FROM tblexperiment ' ...
            %             'WHERE experimentId="' fpart{1} ...
            %             '" AND unitNr="' fpart{2}(2:4) ...
            %             '" AND experimentNr="' fpart{3} '"'];
            % 
            %         data=select(connSQL,selectquery);
            %         expkey=num2str(data.experimentKey);
            %         if isempty(expkey)
            %             uialert(app.UISortGui,'Experiment not yet entered in database','Error')
            %             return;
            %         end
            % 
            %         tableRec=table;
            %         tableRec.spikeSorted=1;
            %         tableRec.NSingleUnit=sum(strcmp(spkSort.unitinfo,'SU'));
            %         tableRec.NMultiUnit=sum(strcmp(spkSort.unitinfo,'MU'))+sum(strcmp(spkSort.unitinfo,'SU?'));
            % 
            %         %if there is an existing record, update the fields
            %         selectquery=['SELECT recordingKey FROM tblrecording ' ...
            %             'WHERE experimentKey="' expkey ...
            %             '" AND probeID="' num2str(app.probeID) '"'];
            % 
            %         dataR=select(connSQL,selectquery);
            %         if ~isempty(dataR)
            %             update(connSQL,'tblrecording',tableRec.Properties.VariableNames,tableRec,['WHERE recordingKey="' num2str(dataR.recordingKey) '"']);
            %         else %otherwise generate a new entry
            %             %add more info from id file since entry does not exist
            %             tableRec.experimentKey={double(data.experimentKey)};
            %             tableRec.recSite={id.probes(app.probeID).area};
            %             tableRec.recProbe={id.probes(app.probeID).type};
            %             tableRec.probeId={app.probeID};
            %             sqlwrite(connSQL,'tblrecording',tableRec);
            %         end
            %         close(connSQL);
            % 
            %         uialert(app.UISortGui,'Database updated','Done','Icon','success');
            % 
            %     end
            % end


        end

        % Value changed function: MarkerSzSpinner
        function MarkerSzSpinnerValueChanged(app, event)
            %update plot
            app.updateMainPlot();
        end

        % Value changed function: ArtRejSwitch
        function ArtRejSwitchValueChanged(app, event)

            if strcmp(app.ArtRejSwitch.Value,'On')
                settingsHandle=setArtefact(app,app.spkInfo,app.jobEdges,app.filepath,app.tSuffix);
                waitfor(settingsHandle);

                %find and label artefacts
                if ~isnan(app.artNCh)
                    if isnan(app.artThreshold) %only using channel criterion
                        artIdx = find(app.spkInfo.NDuplicate>=app.artNCh);
                    else %channel and threshold
                        if app.artThreshold<0
                            artIdx = find(app.spkInfo.NDuplicate>=app.artNCh & ...
                                app.spkInfo.AmpMinDet<=app.artThreshold);
                        else
                            artIdx = find(app.spkInfo.NDuplicate>=app.artNCh & ...
                                app.spkInfo.AmpMaxDet>=app.artThreshold);
                        end
                    end
                    %check whether this impacted units
                    if any(app.spkInfo.unitid(artIdx)>0)
                        sel=uiconfirm(app.UISortGui,'Artefact settings will impact sorted units. Proceed?','Confirm','Icon','Warning');
                        if strcmp(sel,'Cancel')
                            app.ArtRejSwitch.Value='Off';
                            return;
                        else
                            unitOvlp=unique(app.spkInfo.unitid(artIdx));
                            unitOvlp=unitOvlp(unitOvlp>0);
                        end
                    else
                        unitOvlp=[];
                    end
                    %set unitinfo to artefact
                    app.spkInfo.unitid(artIdx)=-1;

                    %record action
                    app.addRoiAction('artrejOn',[],[]);

                    %if units were affected, fix
                    if ~isempty(unitOvlp)
                        for i=1:length(unitOvlp)
                            if ~any(app.spkInfo.unitid==unitOvlp(i))
                                %unit was deleted entirely
                                remUnit=unitOvlp(i);

                                %renumber unit ids
                                app.removeUnit(remUnit);
                                app.removeUnitTable(remUnit);

                                app.nrUnits=app.nrUnits-1;
                                app.setButtonsUnits(app.nrUnits,app.nrUnits);
                                if app.nrUnits==0
                                    app.SaveButton.Enable=0;
                                end

                                %record as action - otherwise we will run
                                %into issues with propagating the sort
                                %history to jobs files
                                app.addRoiAction('rem',[],remUnit);

                            else
                                %recompute main channel
                                app.computeMainCh(unitOvlp(i));
                            end
                        end
                        %update unit table
                        app.updateUnitTable();
                    end

                    %update plots
                    app.updateMainPlot(app.plotmode);
                    if app.nrUnits>0
                        app.Unit2WfCheckBox.Value=0;
                        app.updateWfPlot();
                        app.updateFpPlot(1);
                    else
                        cla(app.WfAxes);
                        cla(app.FpAxes);
                    end

                else
                    app.ArtRejSwitch.Value='Off';
                    %record action
                    app.addRoiAction('artrejOff',[],[]);
                end

            elseif strcmp(app.ArtRejSwitch.Value,'Off')
                %remove all artefact labels (-1)
                tf=(app.spkInfo.unitid==-1);
                app.spkInfo.unitid(tf)=0;
                app.updateMainPlot(app.plotmode);
                %record action
                app.addRoiAction('artrejOff',[],[]);
            end

        end

        % Selection change function: ModeTabGroup
        function ModeTabGroupSelectionChanged(app, event)
            selectedTab = app.ModeTabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Probe'
                    app.plotmode=1;
                case 'Tetrode'
                    if app.probes.nChannels>1 %there is nothing to do here for single channel
                        app.plotmode=2;

                        if app.nrUnits>0
                            %tether the selected channels to the currently selected unit
                            curUnit=app.UnitSpinner.Value;
                            app.detChTet = app.unitInfo.Ch(curUnit);
                            app.setTetDropDowns();
                        end
                    end
                case 'Channel'
                    app.plotmode=3;

                    if app.nrUnits>0
                        %tether the selected channels to the currently selected unit
                        curUnit=app.UnitSpinner.Value;
                        if app.detChType==1 %plotting by sorted channel number
                            app.detChCh=find(app.probeSort(:,3)==app.unitInfo.Ch(curUnit));
                            app.ChDetChSpinner.Value=app.detChCh;
                        else %plotting by regular channel number
                            app.detChCh = app.unitInfo.Ch(curUnit);
                            app.ChDetChSpinner.Value=app.detChCh;
                        end
                    end
            end
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownChanged(app, event)
            app.xProp=app.XaxisDropDown.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownChanged(app, event)
            app.yProp=app.YaxisDropDown.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: TetDetChSpinner
        function TetDetChSpinnerValueChanged(app, event)
            app.detChTet = app.TetDetChSpinner.Value;
            app.setTetDropDowns();
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: TetInclNBCheckBox
        function TetInclNBCheckBoxValueChanged(app, event)
            app.detChNbTet=app.TetInclNBCheckBox.Value;
            app.updateMainPlot(app.plotmode);            
        end

        % Value changed function: TetXaxisDropDown
        function TetXaxisDropDownValueChanged(app, event)
            app.xPropTet = app.TetXaxisDropDown.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: TetYaxisDropDown
        function TetYaxisDropDownValueChanged(app, event)
            app.yPropTet = app.TetYaxisDropDown.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: TetChXDropDown
        function TetChXDropDownValueChanged(app, event)
            app.xChTet = str2num(app.TetChXDropDown.Value);
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: TetChYDropDown
        function TetChYDropDownValueChanged(app, event)
            app.yChTet = str2num(app.TetChYDropDown.Value);
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: ChTypeButton
        function ChTypeButtonValueChanged(app, event)
            if app.ChTypeButton.Value==0 %sort channel
                app.detChType=1;
                app.ChTypeButton.Text='Det Ch Sort';
                %tether so that we stay in the same channel (we are
                %switching from regular to sort here)
                app.detChCh=find(app.probeSort(:,3)==app.detChCh);
                app.ChDetChSpinner.Value=app.detChCh;
            else %regular channel
                app.detChType=2;
                app.ChTypeButton.Text='Det Ch';
                %switching form sort to regular
                app.detChCh=app.probeSort(app.detChCh,3);
                app.ChDetChSpinner.Value=app.detChCh;
            end
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: ChDetChSpinner
        function ChDetChSpinnerValueChanged(app, event)
            app.detChCh = app.ChDetChSpinner.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: ChInclNBCheckBox
        function ChInclNBCheckBoxValueChanged(app, event)
            app.detChNbCh=app.ChInclNBCheckBox.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: ChXaxisDropDown
        function ChXaxisDropDownValueChanged(app, event)
            app.xPropCh = app.ChXaxisDropDown.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: ChYaxisDropDown
        function ChYaxisDropDownValueChanged(app, event)
            app.yPropCh = app.ChYaxisDropDown.Value;
            app.updateMainPlot(app.plotmode);
        end

        % Button pushed function: LimitChButton
        function LimitChButtonPushed(app, event)
            settingsHandle=selectCh(app,app.probes,app.selectedCh);
            waitfor(settingsHandle)

            if ~isempty(app.selectedCh)
                chStr=num2str(app.selectedCh','%d, ');
                app.SelChLabel.Text=['Selected ch: ' chStr(1:end-1)];
            end
            app.updateMainPlot(app.plotmode);
        end

        % Button pushed function: ResetChButton
        function ResetChButtonPushed(app, event)
            app.selectedCh=[];
            app.SelChLabel.Text='';
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: ShowROICheckBox
        function ShowROICheckBoxValueChanged(app, event)
            %add roi to the plot
            if app.ShowROICheckBox.Value==1
                app.plotROI(app.plotmode);
            else
                delete(app.roihandle);
                app.roihandle=[];
            end
        end

        % Button pushed function: AddUnitButton
        function AddUnitButtonPushed(app, event)
            %turn all toolbar buttons off
            for i=2:6
                if strcmp(app.tbhandle.Children(i).Value,'on')
                    e = app.tbhandle.Children(i);
                    d = struct;
                    d.Source = e;
                    d.Axes = app.MainAxes;
                    d.EvenName = 'ValueChanged';
                    d.Value = 'off';
                    d.PreviousValue = 'on';
                    feval(app.tbhandle.Children(i).ValueChangedFcn,e,d);
                end
            end

            %save state for undo
            app.saveState();

            %draw roi
            newRoi=drawpolygon(app.MainAxes);
            wait(newRoi);

            %increment counter
            app.nrUnits=app.nrUnits+1;

            %assign units - needs roi to still be active
            app.assignUnit(app.plotmode,app.nrUnits,newRoi,1);

            %record action - also needs roi
            app.addRoiAction('new',newRoi,app.nrUnits);

            %save info for unit - this is a new unit, so need to add new
            %entries (and only single ROI present)
            %color info
            c=colororder;
            c=c(2:end,:); %first one reserved for unassigned units
            cidx=mod(app.nrUnits-1,size(c,1))+1;
            app.units(app.nrUnits).color=c(cidx,:);

            %add to table info
            val={app.nrUnits,NaN,1,'None'};
            if app.nrUnits==1
                tnames={'UnitId','Ch','Vis','Cat'};
                app.unitInfo=cell2table(val,'VariableNames',tnames);
                %add categories
                app.unitInfo.Cat=categorical(app.unitInfo.Cat,{'Noise','None','SU','MU','SU?'},'Protected',true);
                app.unitInfo.Vis=categorical(app.unitInfo.Vis,[0 1],'Protected',true);
            else
                %need to make a temp table first
                tnames={'UnitId','Ch','Vis','Cat'};
                tmpTbl=cell2table(val,'VariableNames',tnames);
                %add categories
                tmpTbl.Cat=categorical(tmpTbl.Cat,{'Noise','None','SU','MU','SU?'},'Protected',true);
                tmpTbl.Vis=categorical(tmpTbl.Vis,[0 1],'Protected',true);

                app.unitInfo=[app.unitInfo;tmpTbl];
            end


            app.updateMainPlot(app.plotmode);

            %compute main channel
            app.computeMainCh(app.nrUnits);

            %update table
            app.updateUnitTable();

            %delete roi for now
            delete(newRoi);

            %enable/update buttons
            app.setButtonsUnits(app.nrUnits,app.nrUnits);
            %app.ApplyViewButton.Enable=0;

            %update waveform plot
            app.updateWfPlot();

            %update footprint plot
            app.updateFpPlot(app.nrUnits);

            %now there are ROI that could be shown
            app.ShowROICheckBox.Enable=1;

            %allow undo
            app.UndoButton.Enable=1;

        end

        % Button pushed function: DeleteUnitButton
        function DeleteUnitButtonPushed(app, event)
            %save state for undo
            app.saveState();

            %identify selected unit
            remUnit=app.UnitSpinner.Value;

            %update unitid in spkInfo
            app.removeUnit(remUnit);

            %record action
            app.addRoiAction('rem',[],remUnit);

            %update plot
            app.updateMainPlot(app.plotmode);

            %update unittable
            app.removeUnitTable(remUnit);

            %change unit number
            app.nrUnits=app.nrUnits-1;

            %show
            app.updateUnitTable();

            %set unit spinner to last unit (need to pick something), disable things
            %as appopriate
            app.setButtonsUnits(app.nrUnits,app.nrUnits);
            if app.nrUnits==0
                app.SaveButton.Enable=0;
            end

            %change waveform and footprint plot to keep things consistent
            if app.nrUnits>0
                app.Unit2WfCheckBox.Value=0;
                app.updateWfPlot();
                app.updateFpPlot(1);
            else
                cla(app.WfAxes);
                cla(app.FpAxes);
            end


        end

        % Button pushed function: AddPROIButton
        function AddPROIButtonPushed(app, event)
            %turn all toolbar buttons off
            for i=2:6
                if strcmp(app.tbhandle.Children(i).Value,'on')
                    e = app.tbhandle.Children(i);
                    d = struct;
                    d.Source = e;
                    d.Axes = app.MainAxes;
                    d.EvenName = 'ValueChanged';
                    d.Value = 'off';
                    d.PreviousValue = 'on';
                    feval(app.tbhandle.Children(i).ValueChangedFcn,e,d);
                end
            end

            %save state for undo
            app.saveState();

            %get unit
            addUnit=app.UnitSpinner.Value;

            %add a positive ROI (to add units to a region)
            newRoi=drawpolygon(app.MainAxes);
            wait(newRoi);

            %assign units
            app.assignUnit(app.plotmode,addUnit,newRoi,1); %needs roi to still be active

            %record action
            app.addRoiAction('addP',newRoi,addUnit);


            %compute main channel
            app.computeMainCh(addUnit);

            %update table
            app.updateUnitTable();

            %update plot
            app.updateMainPlot(app.plotmode);

            %update waveform plot
            app.UnitWfSpinner.Value=addUnit;
            app.updateWfPlot();

            %update footprint plot
            app.updateFpPlot(addUnit);
            app.UnitFpSpinner.Value=addUnit;

            %delete roi
            delete(newRoi);

        end

        % Button pushed function: AddMROIButton
        function AddMROIButtonPushed(app, event)
            %turn all toolbar buttons off
            for i=2:6
                if strcmp(app.tbhandle.Children(i).Value,'on')
                    e = app.tbhandle.Children(i);
                    d = struct;
                    d.Source = e;
                    d.Axes = app.MainAxes;
                    d.EvenName = 'ValueChanged';
                    d.Value = 'off';
                    d.PreviousValue = 'on';
                    feval(app.tbhandle.Children(i).ValueChangedFcn,e,d);
                end
            end

            %save state for undo
            app.saveState();

            %get unit
            addUnit=app.UnitSpinner.Value;

            %add a positive ROI (to add units to a region)
            newRoi=drawpolygon(app.MainAxes,'StripeColor','g');
            wait(newRoi);

            %assign units
            app.assignUnit(app.plotmode,addUnit,newRoi,-1); %needs roi to still be active

            %record action
            app.addRoiAction('addM',newRoi,addUnit);

            %compute main channel
            app.computeMainCh(addUnit);

            %update table
            app.updateUnitTable();

            %update plot
            app.updateMainPlot(app.plotmode);

            %update waveform plot
            app.UnitWfSpinner.Value=addUnit;
            app.updateWfPlot();

            %update footprint plot
            app.updateFpPlot(addUnit);
            app.UnitFpSpinner.Value=addUnit;

            %delete roi
            delete(newRoi);

        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            %save state for undo
            app.saveState();

            %merge 2 units
            %we keep the smaller unit id, remove the larger one
            mergeUnit1=app.UnitSpinner.Value;
            mergeUnit2=app.MergeUnitSpinner.Value;
            delUnit=max(mergeUnit1,mergeUnit2);
            keepUnit=min(mergeUnit1,mergeUnit2);


            %update unitid in spkInfo
            app.mergeUnit(delUnit,keepUnit);

            %record action
            app.addRoiAction('merge',[],[delUnit keepUnit]);


            %update plot
            app.updateMainPlot(app.plotmode);

            %update unittable
            app.unitInfo.Cat(keepUnit)={'None'};
            app.computeMainCh(keepUnit);
            app.removeUnitTable(delUnit);
            app.nrUnits=app.nrUnits-1;
            app.updateUnitTable();

            %set unit spinner to remaining unit (need to pick something), disable things
            %as appopriate
            app.setButtonsUnits(app.nrUnits,keepUnit);
            app.Unit2WfCheckBox.Value=0;
            app.updateWfPlot();
            app.updateFpPlot(keepUnit);

        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            %copy info from history
            app.spkInfo.unitid=app.undoUnitId;
            app.unitInfo=app.undoUnitInfo;
            app.units=app.undoUnits;

            app.nrUnits=height(app.unitInfo);

            %update history
            lastAction=app.sortActions(app.sortActionsCount).type;
            app.sortActions(app.sortActionsCount)=[];
            app.sortActionsCount=app.sortActionsCount-1;

            %update roi if necessary
            if app.ShowROICheckBox.Value==1 && ...
                    ~strcmp(lastAction,'rem') && ~strcmp(lastAction,'merge')
                delete(app.roihandle(end));
                app.roihandle(end)=[];
            end

            %update plot and unit table
            app.updateUnitTable();
            app.updateMainPlot(app.plotmode);

            %set unit spinner to last unit (need to pick something), disable things
            %as appopriate
            app.setButtonsUnits(app.nrUnits,app.nrUnits);
            if app.nrUnits==0
                app.SaveButton.Enable=0;
                %app.ApplyViewButton.Enable=1;
            end

            %change waveform and footprint plot to keep things consistent
            if app.nrUnits>0
                app.Unit2WfCheckBox.Value=0;
                app.updateWfPlot();
                app.updateFpPlot(1);
            else
                cla(app.WfAxes);
                cla(app.FpAxes);
            end


        end

        % Button pushed function: ApplyHistoryButton
        function ApplyHistoryButtonPushed(app, event)
            %apply sort actions as saved in a history file

            %this will override all current assignments
            sel=uiconfirm(app.UISortGui,'Loading history will override all current unit assignments. Proceed?',...
                'Confirm','Icon','warning');

            if strcmp(sel,'OK')
                [f,p] = uigetfile('*.mat','Select history file');
                figure(app.UISortGui)

                if ~isequal(p,0)
                    %load file
                    load(fullfile(p,f)); %generates sortHist
                    app.sortActions=sortHist;
                    app.sortActionsStart=length(app.sortActions);
                    app.sortActionsCount=app.sortActionsStart;

                    %delete current unit assignment
                    app.units=struct;
                    app.spkInfo.unitid=zeros(size(app.spkInfo.detCh));
                    app.nrUnits=0;
                    app.unitInfo=table;

                    %apply history
                    app.applyHistory();

                    %revert settings back to current view - use drop down
                    %values to keep everything in sync
                    app.xProp=app.XaxisDropDown.Value;
                    app.yProp=app.YaxisDropDown.Value;
                    app.selectedCh=[];
                    app.SelChLabel.Text='';

                    app.xPropTet=app.TetXaxisDropDown.Value;
                    app.yPropTet=app.TetYaxisDropDown.Value;
                    app.detChTet=app.TetDetChSpinner.Value;
                    app.xChTet=app.TetChXDropDown.Value;
                    app.yChTet=app.TetChXDropDown.Value;

                    app.xPropCh=app.ChXaxisDropDown.Value;
                    app.yPropCh=app.ChYaxisDropDown.Value;
                    app.detChCh=app.ChDetChSpinner.Value;
                    app.detChNbCh=app.ChInclNBCheckBox.Value;
                    if app.ChTypeButton.Value==0 %sort channel
                        app.detChType=1;
                    else
                        app.detChType=2;
                    end

                    %update plot and unit table
                    for i=1:app.nrUnits %make everything visible and not categorized
                        app.unitInfo.Vis(i)=categorical(1);
                        app.unitInfo.Cat(i)='None';
                        app.computeMainCh(i);
                    end

                    app.updateUnitTable();
                    app.updateMainPlot(app.plotmode);
                    app.setButtonsUnits(app.nrUnits,1);
                    app.updateWfPlot();
                    app.updateFpPlot(1);
                    app.ShowROICheckBox.Enable=1;

                    %test whether artefacts were included
                    if any(app.spkInfo.unitid==-1)
                        app.ArtRejSwitch.Value='On';
                    else
                        app.ArtRejSwitch.Value='Off';
                    end

                end
            end


        end

        % Value changed function: UnitSpinner
        function UnitSpinnerValueChanged(app, event)
            nrUnit = app.UnitSpinner.Value;

            %if limited view, keep it but set it to the new unit in tethered mode
            if app.TetherPlotsCheckBox.Value==1 && app.PlotUnitOnlyCheckBox.Value==1
                app.PlotUnitOnlySpinner.Value=nrUnit;
            end

            %update plots
            switch app.plotmode
                case 2 %tetrode
                    app.detChTet = app.unitInfo.Ch(nrUnit);
                    app.setTetDropDowns();
                case 3 %channel
                    if app.detChType==1 %plotting by sorted channel number
                        app.detChCh=find(app.probeSort(:,3)==app.unitInfo.Ch(nrUnit));
                        app.ChDetChSpinner.Value=app.detChCh;
                    else %plotting by regular channel number
                        app.detChCh = app.unitInfo.Ch(nrUnit);
                        app.ChDetChSpinner.Value=app.detChCh;
                    end
            end
            app.updateMainPlot(app.plotmode);

            %update waveform and footprint plot if necessary
            if app.TetherPlotsCheckBox.Value==1
                %change unit for waveform plot
                app.UnitWfSpinner.Value=nrUnit;
                app.updateWfPlot();

                app.UnitFpSpinner.Value=nrUnit;
                app.updateFpPlot(nrUnit);
            end
        end

        % Selection changed function: UnitTable
        function UnitTableSelectionChanged(app, event)
            %selected row changes
            if ~isempty(event.Selection) && event.Selection(2)<3
                nrUnit=event.Selection(1);

                %update the unit spinner and related buttons
                app.UnitSpinner.Value=nrUnit;

                %if limited view, keep it but set it to the new unit in tethered mode
                if app.TetherPlotsCheckBox.Value==1 && app.PlotUnitOnlyCheckBox.Value==1
                    app.PlotUnitOnlySpinner.Value=nrUnit;
                end

                %update plots if necessary
                switch app.plotmode
                    case 2 %tetrode
                        app.detChTet = app.unitInfo.Ch(nrUnit);
                        app.setTetDropDowns();
                    case 3 %channel
                        if app.detChType==1 %plotting by sorted channel number
                            app.detChCh=find(app.probeSort(:,3)==app.unitInfo.Ch(nrUnit));
                            app.ChDetChSpinner.Value=app.detChCh;
                        else %plotting by regular channel number
                            app.detChCh = app.unitInfo.Ch(nrUnit);
                            app.ChDetChSpinner.Value=app.detChCh;
                        end
                end
                app.updateMainPlot(app.plotmode); %may not always be necessary for probe mode, but easier this way

                %update waveform and footprint plot if necessary
                if app.TetherPlotsCheckBox.Value==1
                    %change unit for waveform plot
                    app.UnitWfSpinner.Value=nrUnit;
                    app.updateWfPlot();

                    app.UnitFpSpinner.Value=nrUnit;
                    app.updateFpPlot(nrUnit);
                end
            end

        end

        % Cell edit callback: UnitTable
        function UnitTableCellEdit(app, event)
            %values have changes for visibility or category
            if event.Indices(2)==4 %category changed
                %update unit table
                app.unitInfo=app.UnitTable.Data;
            elseif event.Indices(2)==3 %visibility changed
                %update unit table
                app.unitInfo=app.UnitTable.Data;

                %change plot
                nrUnit=event.Indices(1);

                %update the unit spinner and related buttons
                app.UnitSpinner.Value=nrUnit;
                switch app.plotmode
                    case 1 %probe mode
                        app.updateMainPlot(app.plotmode);
                    case 2 %tetrode
                        app.detChTet = app.unitInfo.Ch(nrUnit);
                        app.setTetDropDowns();
                        app.updateMainPlot(app.plotmode);
                    case 3 %channel
                        if app.detChType==1 %plotting by sorted channel number
                            app.detChCh=find(app.probeSort(:,3)==app.unitInfo.Ch(nrUnit));
                            app.ChDetChSpinner.Value=app.detChCh;
                        else %plotting by regular channel number
                            app.detChCh = app.unitInfo.Ch(nrUnit);
                            app.ChDetChSpinner.Value=app.detChCh;
                        end
                        app.updateMainPlot(app.plotmode);
                end

                %update waveform and footprint plot if necessary
                if app.TetherPlotsCheckBox.Value==1
                    %change unit for waveform plot
                    app.UnitWfSpinner.Value=nrUnit;
                    app.updateWfPlot();

                    app.UnitFpSpinner.Value=nrUnit;
                    app.updateFpPlot(nrUnit);
                end
            end

        end

        % Button pushed function: UnitMetricsButton
        function UnitMetricsButtonPushed(app, event)
            app.computeMetrics();
            settingsHandle=showTableGUI(app.unitMetric);
            waitfor(settingsHandle);
            figure(app.UISortGui)
        end

        % Button pushed function: AutoCatButton
        function AutoCatButtonPushed(app, event)
            %use metrics to update unit categories

            app.computeMetrics(); %update metrics first
            %criterion: SU cannot have ISI violations (violation: <1.2ms)
            for i=1:app.nrUnits
                %we only want to check none and SU here, rest should stay
                %as is
                if strcmp(char(app.unitInfo.Cat(i)),'None') || strcmp(char(app.unitInfo.Cat(i)),'SU')
                    if app.unitMetric.ISIv(i)==0
                        app.unitInfo.Cat(i)='SU';
                    else
                        app.unitInfo.Cat(i)='MU';
                    end
                end
            end
            %update table
            app.updateUnitTable();

        end

        % Value changed function: UnitWfSpinner
        function UnitWfSpinnerValueChanged(app, event)
            %change unit for waveform plot
            app.updateWfPlot();
        end

        % Value changed function: Unit2WfCheckBox
        function Unit2WfCheckBoxValueChanged(app, event)
            app.updateWfPlot();
        end

        % Value changed function: Unit2WfSpinner
        function Unit2WfSpinnerValueChanged(app, event)
            if app.Unit2WfCheckBox.Value==1
                app.updateWfPlot();
            end
        end

        % Button pushed function: AddWfButton
        function AddWfButtonPushed(app, event)
            % get roi
            roi=drawpolygon(app.MainAxes);
            wait(roi) %waits for double click

            %plot waveforms
            app.addSelWf(roi);

            %delete roi
            delete(roi);

        end

        % Value changed function: WfReaderButton
        function WfReaderButtonValueChanged(app, event)
            value = app.WfReaderButton.Value;
            if value==1
                %block all other buttons
                app.setButtons(0);
                app.setButtonsUnits(0,0);
                %app.ApplyViewButton.Enable=0;

                %draw point
                app.singleWfRoi=drawpoint(app.MainAxes);
                wait(app.singleWfRoi) %waits for double click
                addlistener(app.singleWfRoi,'ROIMoved', @(varargin)app.singleWfEvent(app.singleWfRoi));
                %draw wf
                app.plotSingleWf(app.singleWfRoi);

            else
                %delete roi
                delete(app.singleWfRoi);
                delete(app.singleWfPlot);
                delete(app.singleFpPlot);
                app.singleWfPlot=[];
                app.singleFpPlot=[];
                app.setButtons(1);
                app.setButtonsUnits(-1,0);
                %                  if app.nrUnits==0
                %                      app.ApplyViewButton.Enable=1;
                %                  end
            end
        end

        % Value changed function: NrSpikesSpinner
        function NrSpikesSpinnerValueChanged(app, event)
            %change unit for waveform plot
            app.updateWfPlot();
        end

        % Button pushed function: RefreshWfButton
        function RefreshWfButtonPushed(app, event)
            %update waveform plot
            app.updateWfPlot();
        end

        % Value changed function: UnitFpSpinner
        function UnitFpSpinnerValueChanged(app, event)
            %update footprint plot
            nrUnit = app.UnitFpSpinner.Value;
            app.updateFpPlot(nrUnit);
        end

        % Value changed function: PlotUnitOnlyCheckBox
        function PlotUnitOnlyCheckBoxValueChanged(app, event)
            if app.TetherPlotsCheckBox.Value==1
                uID=app.PlotUnitOnlySpinner.Value;
                app.UnitSpinner.Value=uID;
                
                %change unit for waveform plot
                app.UnitWfSpinner.Value=uID;
                app.updateWfPlot();

                app.UnitFpSpinner.Value=uID;
                app.updateFpPlot(uID);
            end
            %update plot
            app.updateMainPlot(app.plotmode);
        end

        % Value changed function: PlotUnitOnlySpinner
        function PlotUnitOnlySpinnerValueChanged(app, event)
            if app.PlotUnitOnlyCheckBox.Value==1

                %if tethered mode, update unit for edit unit as well
                if app.TetherPlotsCheckBox.Value==1
                    uID=app.PlotUnitOnlySpinner.Value;
                    app.UnitSpinner.Value=uID;

                    %change unit for waveform plot
                    app.UnitWfSpinner.Value=uID;
                    app.updateWfPlot();

                    app.UnitFpSpinner.Value=uID;
                    app.updateFpPlot(uID);

                end
                %update plot
                app.updateMainPlot(app.plotmode);
            end

        end

        % Value changed function: HideAssignmentCheckBox
        function HideAssignmentCheckBoxValueChanged(app, event)
            %update plot
            app.updateMainPlot(app.plotmode);
        end

        % Close request function: UISortGui
        function UISortGuiCloseRequest(app, event)
            selection = uiconfirm(app.UISortGui, ...
                "Close GUI?","Confirm Close", ...
                "Icon","warning");
            if strcmp(selection,'OK')
                delete(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISortGui = uifigure('Visible', 'off');
            app.UISortGui.Name = 'MATLAB App';
            app.UISortGui.CloseRequestFcn = createCallbackFcn(app, @UISortGuiCloseRequest, true);
            app.MainAxes = uiaxes(app.UISortGui);
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            app.UnitfootprintPanel = uipanel(app.UISortGui);
            app.UnitfootprintPanel.Title = 'Unit footprint';
            app.FpAxes = uiaxes(app.UnitfootprintPanel);
            xlabel(app.FpAxes, 'X')
            ylabel(app.FpAxes, 'Y')
            app.UnitSpinner_2Label_2 = uilabel(app.UnitfootprintPanel);
            app.UnitSpinner_2Label_2.Text = 'Unit';
            app.UnitFpSpinner = uispinner(app.UnitfootprintPanel);
            app.UnitFpSpinner.Limits = [1 Inf];
            app.UnitFpSpinner.ValueChangedFcn = createCallbackFcn(app, @UnitFpSpinnerValueChanged, true);
            app.UnitFpSpinner.Enable = 'off';
            app.UnitFpSpinner.Value = 1;
            app.UnitwaveformsPanel = uipanel(app.UISortGui);
            app.UnitwaveformsPanel.Title = 'Unit waveforms';
            app.WfAxes = uiaxes(app.UnitwaveformsPanel);
            xlabel(app.WfAxes, 'Samples')
            ylabel(app.WfAxes, 'Amp')
            app.Unit1Label = uilabel(app.UnitwaveformsPanel);
            app.Unit1Label.Text = 'Unit 1';
            app.UnitWfSpinner = uispinner(app.UnitwaveformsPanel);
            app.UnitWfSpinner.Limits = [1 Inf];
            app.UnitWfSpinner.ValueChangedFcn = createCallbackFcn(app, @UnitWfSpinnerValueChanged, true);
            app.UnitWfSpinner.Enable = 'off';
            app.UnitWfSpinner.Value = 1;
            app.Unit2WfSpinner = uispinner(app.UnitwaveformsPanel);
            app.Unit2WfSpinner.ValueChangedFcn = createCallbackFcn(app, @Unit2WfSpinnerValueChanged, true);
            app.Unit2WfSpinner.Enable = 'off';
            app.Unit2WfSpinner.Value = 1;
            app.Unit2WfCheckBox = uicheckbox(app.UnitwaveformsPanel);
            app.Unit2WfCheckBox.ValueChangedFcn = createCallbackFcn(app, @Unit2WfCheckBoxValueChanged, true);
            app.Unit2WfCheckBox.Enable = 'off';
            app.Unit2WfCheckBox.Text = 'Unit 2';
            app.AddWfButton = uibutton(app.UnitwaveformsPanel, 'push');
            app.AddWfButton.ButtonPushedFcn = createCallbackFcn(app, @AddWfButtonPushed, true);
            app.AddWfButton.Enable = 'off';
            app.AddWfButton.Text = 'Add selected wf';
            app.NrSpikesSpinnerLabel = uilabel(app.UnitwaveformsPanel);
            app.NrSpikesSpinnerLabel.Text = 'Nr Spikes';
            app.NrSpikesSpinner = uispinner(app.UnitwaveformsPanel);
            app.NrSpikesSpinner.Limits = [1 Inf];
            app.NrSpikesSpinner.ValueChangedFcn = createCallbackFcn(app, @NrSpikesSpinnerValueChanged, true);
            app.NrSpikesSpinner.Enable = 'off';
            app.NrSpikesSpinner.Value = 25;
            app.RefreshWfButton = uibutton(app.UnitwaveformsPanel, 'push');
            app.RefreshWfButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshWfButtonPushed, true);
            app.RefreshWfButton.Enable = 'off';
            app.RefreshWfButton.Text = 'Refresh';
            app.WfReaderButton = uibutton(app.UnitwaveformsPanel, 'state');
            app.WfReaderButton.ValueChangedFcn = createCallbackFcn(app, @WfReaderButtonValueChanged, true);
            app.WfReaderButton.Enable = 'off';
            app.WfReaderButton.Text = 'Waveform reader';
            app.UnitsPanel = uipanel(app.UISortGui);
            app.UnitsPanel.Title = 'Units';
            app.AddUnitButton = uibutton(app.UnitsPanel, 'push');
            app.AddUnitButton.ButtonPushedFcn = createCallbackFcn(app, @AddUnitButtonPushed, true);
            app.AddUnitButton.Enable = 'off';
            app.AddUnitButton.Text = 'Add unit';
            app.UnitTable = uitable(app.UnitsPanel);
            app.UnitTable.ColumnName = {'Unit'; 'Ch'; 'Vis'; 'Cat'};
            app.UnitTable.RowName = {};
            app.UnitTable.ColumnEditable = [false false true true];
            app.UnitTable.CellEditCallback = createCallbackFcn(app, @UnitTableCellEdit, true);
            app.UnitTable.SelectionChangedFcn = createCallbackFcn(app, @UnitTableSelectionChanged, true);
            app.UnitTable.Multiselect = 'off';
            app.AutoCatButton = uibutton(app.UnitsPanel, 'push');
            app.AutoCatButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCatButtonPushed, true);
            app.AutoCatButton.Enable = 'off';
            app.AutoCatButton.Text = 'Auto category';
            app.TetherPlotsCheckBox = uicheckbox(app.UnitsPanel);
            app.TetherPlotsCheckBox.Text = 'Tether unit plots';
            app.TetherPlotsCheckBox.Value = true;
            app.EditunitPanel = uipanel(app.UnitsPanel);
            app.EditunitPanel.Title = 'Edit unit';
            app.AddPROIButton = uibutton(app.EditunitPanel, 'push');
            app.AddPROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddPROIButtonPushed, true);
            app.AddPROIButton.Enable = 'off';
            app.AddPROIButton.Text = 'Add + ROI';
            app.AddMROIButton = uibutton(app.EditunitPanel, 'push');
            app.AddMROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddMROIButtonPushed, true);
            app.AddMROIButton.Enable = 'off';
            app.AddMROIButton.Text = 'Add - ROI';
            app.UnitSpinnerLabel = uilabel(app.EditunitPanel);
            app.UnitSpinnerLabel.Text = 'Unit';
            app.UnitSpinner = uispinner(app.EditunitPanel);
            app.UnitSpinner.ValueChangedFcn = createCallbackFcn(app, @UnitSpinnerValueChanged, true);
            app.UnitSpinner.Enable = 'off';
            app.UnitSpinner.Value = 1;
            app.DeleteUnitButton = uibutton(app.EditunitPanel, 'push');
            app.DeleteUnitButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUnitButtonPushed, true);
            app.DeleteUnitButton.Enable = 'off';
            app.DeleteUnitButton.Text = 'Delete unit';
            app.MergeButton = uibutton(app.EditunitPanel, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Enable = 'off';
            app.MergeButton.Text = 'Merge';
            app.wunitSpinnerLabel = uilabel(app.EditunitPanel);
            app.wunitSpinnerLabel.Text = 'w/ unit';
            app.MergeUnitSpinner = uispinner(app.EditunitPanel);
            app.MergeUnitSpinner.Enable = 'off';
            app.MergeUnitSpinner.Value = 2;
            app.UndoButton = uibutton(app.UnitsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = 'Undo';
            app.UnitMetricsButton = uibutton(app.UnitsPanel, 'push');
            app.UnitMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @UnitMetricsButtonPushed, true);
            app.UnitMetricsButton.Enable = 'off';
            app.UnitMetricsButton.Text = 'Unit metrics';
            app.ApplyHistoryButton = uibutton(app.UnitsPanel, 'push');
            app.ApplyHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHistoryButtonPushed, true);
            app.ApplyHistoryButton.Enable = 'off';
            app.ApplyHistoryButton.Text = 'Apply history';
            app.PlotPanel = uipanel(app.UISortGui);
            app.PlotPanel.Title = 'Plot';
            app.MarkersizeSpinnerLabel = uilabel(app.PlotPanel);
            app.MarkersizeSpinnerLabel.Text = 'Marker size';
            app.MarkerSzSpinner = uispinner(app.PlotPanel);
            app.MarkerSzSpinner.Limits = [1 10];
            app.MarkerSzSpinner.ValueChangedFcn = createCallbackFcn(app, @MarkerSzSpinnerValueChanged, true);
            app.MarkerSzSpinner.Enable = 'off';
            app.MarkerSzSpinner.Value = 1;
            app.ModeTabGroup = uitabgroup(app.PlotPanel);
            app.ModeTabGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeTabGroupSelectionChanged, true);
            app.ProbeTab = uitab(app.ModeTabGroup);
            app.ProbeTab.Title = 'Probe';
            app.XaxisDropDownLabel = uilabel(app.ProbeTab);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.ProbeTab);
            app.XaxisDropDown.Items = {};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownChanged, true);
            app.XaxisDropDown.Enable = 'off';
            app.XaxisDropDown.Value = {};
            app.YaxisDropDownLabel = uilabel(app.ProbeTab);
            app.YaxisDropDownLabel.Text = 'Y axis';
            app.YaxisDropDown = uidropdown(app.ProbeTab);
            app.YaxisDropDown.Items = {};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownChanged, true);
            app.YaxisDropDown.Enable = 'off';
            app.YaxisDropDown.Value = {};
            app.LimitChButton = uibutton(app.ProbeTab, 'push');
            app.LimitChButton.ButtonPushedFcn = createCallbackFcn(app, @LimitChButtonPushed, true);
            app.LimitChButton.Enable = 'off';
            app.LimitChButton.Text = 'Limit channels';
            app.ResetChButton = uibutton(app.ProbeTab, 'push');
            app.ResetChButton.ButtonPushedFcn = createCallbackFcn(app, @ResetChButtonPushed, true);
            app.ResetChButton.Enable = 'off';
            app.ResetChButton.Text = 'Reset channels';
            app.SelChLabel = uilabel(app.ProbeTab);
            app.SelChLabel.Text = '';
            app.TetrodeTab = uitab(app.ModeTabGroup);
            app.TetrodeTab.Title = 'Tetrode';
            app.DetchannelSpinnerLabel = uilabel(app.TetrodeTab);
            app.DetchannelSpinnerLabel.Text = 'Det channel';
            app.XaxisDropDown_2Label = uilabel(app.TetrodeTab);
            app.XaxisDropDown_2Label.Text = 'X axis';
            app.TetXaxisDropDown = uidropdown(app.TetrodeTab);
            app.TetXaxisDropDown.Items = {};
            app.TetXaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @TetXaxisDropDownValueChanged, true);
            app.TetXaxisDropDown.Enable = 'off';
            app.TetXaxisDropDown.Value = {};
            app.YaxisDropDown_2Label = uilabel(app.TetrodeTab);
            app.YaxisDropDown_2Label.Text = 'Y axis';
            app.TetYaxisDropDown = uidropdown(app.TetrodeTab);
            app.TetYaxisDropDown.Items = {};
            app.TetYaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @TetYaxisDropDownValueChanged, true);
            app.TetYaxisDropDown.Enable = 'off';
            app.TetYaxisDropDown.Value = {};
            app.ChXLabel = uilabel(app.TetrodeTab);
            app.ChXLabel.Text = 'Ch X';
            app.TetChXDropDown = uidropdown(app.TetrodeTab);
            app.TetChXDropDown.Items = {};
            app.TetChXDropDown.ValueChangedFcn = createCallbackFcn(app, @TetChXDropDownValueChanged, true);
            app.TetChXDropDown.Enable = 'off';
            app.TetChXDropDown.Value = {};
            app.ChYLabel = uilabel(app.TetrodeTab);
            app.ChYLabel.Text = 'Ch Y';
            app.TetChYDropDown = uidropdown(app.TetrodeTab);
            app.TetChYDropDown.Items = {};
            app.TetChYDropDown.ValueChangedFcn = createCallbackFcn(app, @TetChYDropDownValueChanged, true);
            app.TetChYDropDown.Enable = 'off';
            app.TetChYDropDown.Value = {};
            app.TetDetChSpinner = uispinner(app.TetrodeTab);
            app.TetDetChSpinner.Limits = [1 Inf];
            app.TetDetChSpinner.ValueChangedFcn = createCallbackFcn(app, @TetDetChSpinnerValueChanged, true);
            app.TetDetChSpinner.Enable = 'off';
            app.TetDetChSpinner.Value = 1;
            app.TetInclNBCheckBox = uicheckbox(app.TetrodeTab);
            app.TetInclNBCheckBox.ValueChangedFcn = createCallbackFcn(app, @TetInclNBCheckBoxValueChanged, true);
            app.TetInclNBCheckBox.Text = 'Incl. neighb.';
            app.ChannelTab = uitab(app.ModeTabGroup);
            app.ChannelTab.Title = 'Channel';
            app.XaxisDropDown_2Label_2 = uilabel(app.ChannelTab);
            app.XaxisDropDown_2Label_2.Text = 'X axis';
            app.ChXaxisDropDown = uidropdown(app.ChannelTab);
            app.ChXaxisDropDown.Items = {};
            app.ChXaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ChXaxisDropDownValueChanged, true);
            app.ChXaxisDropDown.Enable = 'off';
            app.ChXaxisDropDown.Value = {};
            app.YaxisDropDown_2Label_2 = uilabel(app.ChannelTab);
            app.YaxisDropDown_2Label_2.Text = 'Y axis';
            app.ChYaxisDropDown = uidropdown(app.ChannelTab);
            app.ChYaxisDropDown.Items = {};
            app.ChYaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ChYaxisDropDownValueChanged, true);
            app.ChYaxisDropDown.Enable = 'off';
            app.ChYaxisDropDown.Value = {};
            app.ChDetChSpinner = uispinner(app.ChannelTab);
            app.ChDetChSpinner.Limits = [1 Inf];
            app.ChDetChSpinner.ValueChangedFcn = createCallbackFcn(app, @ChDetChSpinnerValueChanged, true);
            app.ChDetChSpinner.Enable = 'off';
            app.ChDetChSpinner.Value = 1;
            app.ChTypeButton = uibutton(app.ChannelTab, 'state');
            app.ChTypeButton.ValueChangedFcn = createCallbackFcn(app, @ChTypeButtonValueChanged, true);
            app.ChTypeButton.Enable = 'off';
            app.ChTypeButton.Text = 'Det Ch Sort';
            app.ChInclNBCheckBox = uicheckbox(app.ChannelTab);
            app.ChInclNBCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChInclNBCheckBoxValueChanged, true);
            app.ChInclNBCheckBox.Enable = 'off';
            app.ChInclNBCheckBox.Text = 'incl. neighbors';
            app.PlotUnitOnlySpinner = uispinner(app.PlotPanel);
            app.PlotUnitOnlySpinner.ValueChangedFcn = createCallbackFcn(app, @PlotUnitOnlySpinnerValueChanged, true);
            app.PlotUnitOnlySpinner.Enable = 'off';
            app.PlotUnitOnlySpinner.Value = 1;
            app.PlotUnitOnlyCheckBox = uicheckbox(app.PlotPanel);
            app.PlotUnitOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotUnitOnlyCheckBoxValueChanged, true);
            app.PlotUnitOnlyCheckBox.Enable = 'off';
            app.PlotUnitOnlyCheckBox.Text = 'Only show unit';
            app.HideAssignmentCheckBox = uicheckbox(app.PlotPanel);
            app.HideAssignmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideAssignmentCheckBoxValueChanged, true);
            app.HideAssignmentCheckBox.Enable = 'off';
            app.HideAssignmentCheckBox.Text = 'Hide unit assignment';
            app.ShowROICheckBox = uicheckbox(app.PlotPanel);
            app.ShowROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROICheckBoxValueChanged, true);
            app.ShowROICheckBox.Enable = 'off';
            app.ShowROICheckBox.Text = 'Show recorded ROI (incl. del.)';
            app.PreprocessingPanel = uipanel(app.UISortGui);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.ArtefactrejectionSwitchLabel = uilabel(app.PreprocessingPanel);
            app.ArtefactrejectionSwitchLabel.Text = 'Artefact rejection';
            app.ArtRejSwitch = uiswitch(app.PreprocessingPanel, 'slider');
            app.ArtRejSwitch.ValueChangedFcn = createCallbackFcn(app, @ArtRejSwitchValueChanged, true);
            app.ArtRejSwitch.Enable = 'off';
            app.FilePanel = uipanel(app.UISortGui);
            app.FilePanel.Title = 'File dialogs';
            app.FileTab = uitabgroup(app.FilePanel);
            app.LoadId = uitab(app.FileTab);
            app.LoadId.Title = 'Load ID file';
            app.LoadJobs = uibutton(app.LoadId, 'push');
            app.LoadJobs.ButtonPushedFcn = createCallbackFcn(app, @LoadJobsButtonPushed, true);
            app.LoadJobs.Enable = 'off';
            app.LoadJobs.Text = 'Load jobs';
            app.PercJobSpinner = uispinner(app.LoadId);
            app.PercJobSpinner.Limits = [1 100];
            app.PercJobSpinner.Enable = 'off';
            app.PercJobSpinner.Value = 100;
            app.JobStopSpinner = uispinner(app.LoadId);
            app.JobStopSpinner.Enable = 'off';
            app.JobStopSpinner.Value = 99;
            app.ProbeIDLabel = uilabel(app.LoadId);
            app.ProbeIDLabel.Text = 'Probe ID';
            app.JobsfromLabel = uilabel(app.LoadId);
            app.JobsfromLabel.Text = 'Jobs from';
            app.JobStartSpinner = uispinner(app.LoadId);
            app.JobStartSpinner.Enable = 'off';
            app.ProbeSpinner = uispinner(app.LoadId);
            app.ProbeSpinner.Value = 1;
            app.PercjobsLabel = uilabel(app.LoadId);
            app.PercjobsLabel.Text = 'Perc. jobs';
            app.LoadIDButton = uibutton(app.LoadId, 'push');
            app.LoadIDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIDButtonPushed, true);
            app.LoadIDButton.Text = 'Pick ID file';
            app.toLabel = uilabel(app.LoadId);
            app.toLabel.Text = 'to';
            app.LoadSort = uitab(app.FileTab);
            app.LoadSort.Title = 'Load sort file';
            app.LoadSortButton = uibutton(app.LoadSort, 'push');
            app.LoadSortButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSortButtonPushed, true);
            app.LoadSortButton.Text = 'Load sort file';
            app.SaveFile = uitab(app.FileTab);
            app.SaveFile.Title = 'Save sort';
            app.InitialsEditFieldLabel = uilabel(app.SaveFile);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.SaveButton = uibutton(app.SaveFile, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save file';
            app.CopyZCheckBox = uicheckbox(app.SaveFile);
            app.CopyZCheckBox.Text = 'Copy to Z';
            app.LinkDBCheckBox = uicheckbox(app.SaveFile);
            app.LinkDBCheckBox.Enable = 'off';
            app.LinkDBCheckBox.Text = 'Link to database';
            app.SuffixLabel = uilabel(app.SaveFile);
            app.SuffixLabel.Text = 'spkSort suffix';
            app.SuffixField = uieditfield(app.SaveFile, 'text');
            app.InitialsField = uieditfield(app.SaveFile, 'text');
            app.FileInfo = uitab(app.FileTab);
            app.FileInfo.Title = 'File info';
            app.FileTable = uitable(app.FileInfo);
            app.FileTable.ColumnName = {'Param'; 'Value'};
            app.FileTable.RowName = {};
            app.RefreshJobsButton = uibutton(app.FilePanel, 'push');
            app.RefreshJobsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshJobsButtonPushed, true);
            app.RefreshJobsButton.Enable = 'off';
            app.RefreshJobsButton.Text = 'Refresh jobs';
            % Show the figure after all components are created
            app.UISortGui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sortGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISortGui)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISortGui)
        end
    end
end