classdef thresholdGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThresholdUI                   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        PresetthresholdPanel          matlab.ui.container.Panel
        button_loadThresh             matlab.ui.control.Button
        toggle_startThresh            matlab.ui.control.RockerSwitch
        SegmentalignedtoSwitchLabel   matlab.ui.control.Label
        edit_lengthThresh             matlab.ui.control.NumericEditField
        SegmentlenghtsEditFieldLabel  matlab.ui.control.Label
        button_setThresh              matlab.ui.control.Button
        spinner_scaleThresh           matlab.ui.control.Spinner
        ScalefactorLabel              matlab.ui.control.Label
        FileoutputPanel               matlab.ui.container.Panel
        edit_suffix                   matlab.ui.control.EditField
        chkbox_suffix                 matlab.ui.control.CheckBox
        button_setNetw                matlab.ui.control.Button
        button_setLocal               matlab.ui.control.Button
        edit_netwPath                 matlab.ui.control.EditField
        edit_localPath                matlab.ui.control.EditField
        LocalpathEditFieldLabel       matlab.ui.control.Label
        chkbox_copyToZ                matlab.ui.control.CheckBox
        button_save                   matlab.ui.control.Button
        edit_initials                 matlab.ui.control.EditField
        InitialsEditFieldLabel        matlab.ui.control.Label
        SettingsPanel                 matlab.ui.container.Panel
        edit_area                     matlab.ui.control.EditField
        edit_lowpass                  matlab.ui.control.NumericEditField
        LowpassHzEditFieldLabel       matlab.ui.control.Label
        edit_highpass                 matlab.ui.control.NumericEditField
        HighpassHzEditFieldLabel      matlab.ui.control.Label
        dropdown_probe                matlab.ui.control.DropDown
        ProbeAreaLabel                matlab.ui.control.Label
        FileinputPanel                matlab.ui.container.Panel
        spinner_probeNr               matlab.ui.control.Spinner
        ProbeNrSpinnerLabel           matlab.ui.control.Label
        AmpfileLabel                  matlab.ui.control.Label
        IDfileLabel_3                 matlab.ui.control.Label
        button_makeId                 matlab.ui.control.Button
        button_getAmp                 matlab.ui.control.Button
        button_getId                  matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        edit_AmpFile                  matlab.ui.control.EditField
        IDfileLabel_2                 matlab.ui.control.Label
        edit_ExpName                  matlab.ui.control.EditField
        IDfileLabel                   matlab.ui.control.Label
        panel_probe                   matlab.ui.container.Panel
        axis_probe                    matlab.ui.control.UIAxes
        panel_signal                  matlab.ui.container.Panel
        ThvalueEditFieldLabel         matlab.ui.control.Label
        edit_threshold                matlab.ui.control.NumericEditField
        spinner_channel               matlab.ui.control.Spinner
        ChannelSpinnerLabel           matlab.ui.control.Label
        button_plussec                matlab.ui.control.Button
        button_minussec               matlab.ui.control.Button
        check_channel                 matlab.ui.control.CheckBox
        text_gotoTime                 matlab.ui.control.NumericEditField
        GotosEditFieldLabel           matlab.ui.control.Label
        text_maxThreshold             matlab.ui.control.NumericEditField
        text_minThreshold             matlab.ui.control.NumericEditField
        slider_time                   matlab.ui.control.Slider
        SegmentstartsLabel            matlab.ui.control.Label
        button_rejectChan             matlab.ui.control.Button
        slider_threshold              matlab.ui.control.Slider
        ThresholdSliderLabel          matlab.ui.control.Label
        axis_timecourse               matlab.ui.control.UIAxes
        axis_spikePreview             matlab.ui.control.UIAxes
        axis_snippet                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        exptFolder %folder for id file, we save here
        exptName
        ampFile
        probe
        sampleFreq
        nChannels %total channels in recording
        nProbeCh %number of channels in current probe
        nProbes
        thresholds
        badChannels
        chkChannels %has a channel been looked at
        nTotalSamples
        snippet %holds unfiltered waveform x channel
        butterParams
        snippetRMS %holds the RMS of the different channels
        timeCourse %holds long time course for current channel
        processedProbe %probes thresholded (for id file)
        processedDate %date for thresholding (for id file)
        processedName %name for thresholding (for id file)
        
        probeNr %current probe
        currentChannel
        currentDisplayChannel %to make it easier for multiple probes
        currentTc %filtered waveform for current channel
        currentStart
        
        thresholdLineHandle
        signalLineHandle
        channelMarkerHandle
        timeLineHandle
        
        offsetTC %offset to fix filter issue with time course
        lengthTC %length of tc to be displayed

        idInfo %id file info
    end
    
    methods (Access = private)
        
        
        function refreshSnippet(app,getNew)
            if getNew
                app.getNewSnippet();
            end
            
            cla(app.axis_snippet);
            tc=filter(app.butterParams.b1, app.butterParams.a1, app.snippet(app.currentChannel,:));
            app.currentTc = tc(app.offsetTC:end); %to avoid filter artefact at start
            app.signalLineHandle = plot(app.axis_snippet,linspace(0,1,app.sampleFreq-app.offsetTC+1)+app.slider_time.Value,app.currentTc,'k');
            hold(app.axis_snippet,'on')
            
            if app.badChannels(app.currentChannel)
                app.button_rejectChan.Text = 'Accept Channel';
                set(app.axis_snippet,'color',[0.6 0.3 0.3])
            else
                app.button_rejectChan.Text = 'Reject Channel';
                set(app.axis_snippet,'color','w')
            end
            app.plotThreshold()
            app.highlightChannel()
            app.previewSpikes()
        end
        
        function getNewSnippet(app)
            DataFile = fopen(app.ampFile,'r');
            frewind(DataFile);
            fseek(DataFile,2*floor(app.slider_time.Value*app.sampleFreq)*app.nChannels,'bof');
            %read all data
            data = fread(DataFile, [app.nChannels app.sampleFreq], 'int16');
            %reduce to the channels for the current probe if necessary
            if app.nProbes==2
                if app.probeNr==1
                    app.snippet=data(1:app.probe(1).nChannels,:);
                else
                    app.snippet=data(app.probe(1).nChannels+1:end,:);
                end
            else
                app.snippet=data;
            end
            fclose(DataFile);
        end
        
        
        function plotThreshold(app)
            if ~isempty(app.thresholdLineHandle)
                if isvalid(app.thresholdLineHandle)
                    delete(app.thresholdLineHandle);
                end
            end
            app.thresholdLineHandle = line(app.axis_snippet,[0 1000]+app.slider_time.Value,...
                [app.thresholds(app.currentChannel) app.thresholds(app.currentChannel)],'color',[0.7 0.3 0.2],'linewidth',2);
            app.previewSpikes();
            set(app.axis_snippet,'XLim',[0 1]+app.slider_time.Value);
            %set(app.axis_snippet,'YLim',sort(app.thresholds(app.currentChannel)*[2 -2]));
        end
        
        function refreshProbe(app)
            cla(app.axis_probe);
            if isfield(app.probe(app.probeNr),'type')
                if ~isempty(app.probe(app.probeNr).type)
                    probeTmp = app.probe(app.probeNr);
                    plot(app.axis_probe,probeTmp.x,probeTmp.z,'sqr', 'MarkerSize',11);
                    hold(app.axis_probe,"on");
                    for i=1:probeTmp.nChannels
                        text(probeTmp.x(i)-5,probeTmp.z(i),...
                            num2str(probeTmp.channels(i)+1),'FontSize',9,'parent',app.axis_probe);
                    end
                    axis(app.axis_probe,[min(probeTmp.x)-50 max(probeTmp.x)+50 min(probeTmp.z)-50 max(probeTmp.z)+50]);
                    axis(app.axis_probe,'equal');
                    set(app.axis_probe,'FontSize',10,'TickDir','out');
                    app.highlightChannel();
                end
            end
        end
        
        function refreshChannel(app)
            
            %check to make sure threshold is not out of range for
            %slider
            if app.thresholds(app.currentChannel)<app.slider_threshold.Limits(1)
                app.slider_threshold.Limits(1)=floor(app.thresholds(app.currentChannel)/100)*100;
                app.text_minThreshold.Value=app.slider_threshold.Limits(1);
            end
            if app.thresholds(app.currentChannel)>app.slider_threshold.Limits(2)
                app.slider_threshold.Limits(2)=ceil(app.thresholds(app.currentChannel)/100)*100;
                app.text_maxThreshold.Value=app.slider_threshold.Limits(2);
            end
            
            app.slider_threshold.Value = app.thresholds(app.currentChannel);
            app.check_channel.Value = app.chkChannels(app.currentChannel);
            app.edit_threshold.Value=app.thresholds(app.currentChannel);
            
        end
        
        function highlightChannel(app)
            if isfield(app.probe(app.probeNr),'type')
                if ~isempty(app.probe(app.probeNr).type)
                    if ~isempty(app.channelMarkerHandle)
                        delete(app.channelMarkerHandle);
                    end
                    probeTmp = app.probe(app.probeNr);
                    cc = app.currentChannel;
                    app.channelMarkerHandle = plot(app.axis_probe,probeTmp.x(cc),probeTmp.z(cc),'bo', 'MarkerSize',11,'LineWidth',2);
                end
            end
        end
        
        function previewSpikes(app)
            sig = app.currentTc;
            th = app.thresholds(app.currentChannel);
            
            %find crossings - we want the transitions
            if th<0
                crossings = find(sig(2:end)<th & sig(1:end-1)>=th);
            else
                crossings = find(sig(2:end)>th & sig(1:end-1)<=th);
            end
            
            
            crossings(crossings<61) = [];
            crossings(crossings+60>length(sig)) = [];
            tt = linspace(-60*1000/app.sampleFreq,60*1000/app.sampleFreq,121);
            
            cla(app.axis_spikePreview);
            if ~isempty(crossings)
                if length(crossings) > 100
                    crossings = crossings(randperm(length(crossings),100));
                end
                clip = nan(length(crossings),121);
                for ii=1:length(crossings)
                    clip(ii,:) = sig(crossings(ii)-60:crossings(ii)+60);
                end
                plot(app.axis_spikePreview,tt,clip','k');
                set(app.axis_spikePreview,'XLim',[tt(1) tt(end)])
                grid(app.axis_spikePreview,'on');
            end
        end
        
        
        function getNewTimeCourse(app)
            %load only data for the current channel
            DataFile = fopen(app.ampFile,'r');
            frewind(DataFile);
            %current channel value needs to incorporate other probe if
            %necessay
            if app.probeNr==2
                cc=app.currentChannel+app.probe(1).nChannels;
            else
                cc=app.currentChannel;
            end
            
            fseek(DataFile,2*floor(app.currentStart*app.sampleFreq)*app.nChannels+2*(cc-1),'bof');
            tc = fread(DataFile, app.lengthTC*app.sampleFreq, 'int16',2*(app.nChannels-1));
            tc=filter(app.butterParams.b1, app.butterParams.a1, tc);
            app.timeCourse=tc(app.offsetTC:end); %there is an artefact at the start because of the filtering
            clear tc;
            fclose(DataFile);
        end
        
        function refreshTimeCourse(app)
            cla(app.axis_timecourse);
            minTc=max(-2000,min(app.timeCourse)); %to keep within reasonable limits
            maxTc=min(2000,max(app.timeCourse));
            
            tp=linspace(app.currentStart,app.currentStart+app.lengthTC,app.lengthTC*app.sampleFreq-app.offsetTC+1);
            tcline=plot(app.axis_timecourse,tp,app.timeCourse,'k');
            set(tcline,'HitTest','off');
            set(app.axis_timecourse,'YLim',[minTc maxTc],'XLim',[app.currentStart app.currentStart+app.lengthTC]); %fix to something reasonable for now

            app.refreshTimeMarker();
        end
        
        function refreshTimeMarker(app)
            startTimeSnippet=app.slider_time.Value;
            minTc=max(-2000,min(app.timeCourse)); %to keep within reasonable limits
            maxTc=min(2000,max(app.timeCourse));
            
            if ~isempty(app.timeLineHandle)
                if isvalid(app.timeLineHandle)
                    delete(app.timeLineHandle);
                end
            end
            hold(app.axis_timecourse,'on')
            if startTimeSnippet>=app.currentStart && startTimeSnippet<=app.currentStart+app.lengthTC
                app.timeLineHandle=plot(app.axis_timecourse,[startTimeSnippet startTimeSnippet],[minTc maxTc],'r-');
            end
            
        end


    end
    
    methods (Access = public)
        
        
        function makeIdfile(app,id,expName,expFolder)
            app.idInfo=id;
            app.exptName=expName;
            app.exptFolder=expFolder;
            app.edit_ExpName.Value=expName;
            app.edit_localPath.Value=expFolder;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [sDir,~]=fileparts(mfilename('fullpath'));
            
            
            %addpath(fullfile(sDir,'microprobe_wiring'));
            %addpath(fullfile(sDir,'microprobe_wiring/headstage and pcb wiring'));
            addpath(fullfile(sDir,'util'))
            addpath(fullfile(sDir,'probeConfig'))

            %get possible probes
            pDir=dir(fullfile(sDir,'probeConfig','probeConfig*'));
            probeName{1}='select';
            for i=1:length(pDir)
                pTmp=strsplit(pDir(i).name,'_');
                probeName{i+1}=pTmp{2}(1:end-2); %need to get rid of the .m
            end
            app.dropdown_probe.Items=probeName;

            %global settings
            app.offsetTC=200;
            app.lengthTC=20; %sec
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ThresholdUI.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {632, 632};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: button_getId
        function button_getIdPushed(app, event)
            %get experiment folder and name
            [f,p] = uigetfile('*id.mat','Select ID file');
            if f==0
                return;
            end
            figure(app.ThresholdUI)
            
            app.edit_localPath.Value=p;
            app.exptFolder=p;
            fparts=strsplit(f,'_id');
            app.exptName=fparts{1};
            app.edit_ExpName.Value=app.exptName;
         
                        
            %to make bookkeeping easier
            app.probeNr=app.spinner_probeNr.Value;
            
            %load id file
            load(fullfile(p,f),'id');
            app.idInfo=id; %at the minimum, this generates idInfo.probes

            %general setup
            app.probe = app.idInfo.probes;
            app.nProbes= length(app.idInfo.probes);
            app.nChannels = sum([app.idInfo.probes.nChannels]);
            app.nProbeCh = app.idInfo.probes(app.probeNr).nChannels;

            app.processedProbe=zeros(1,app.nProbes);
            app.processedDate=cell(1,app.nProbes);
            app.processedName=cell(1,app.nProbes);

            
            % if isfield(app.idInfo,'threshold') %metadata for previous thresholding
            %     app.processedProbe=app.idInfo.threshold.processedProbe;
            %     app.processedDate=app.idInfo.threshold.date;
            %     app.processedName=app.idInfo.threshold.name;
            % 
            %     %if probe has been thresholded before, check whether
            %     %existing info is compatible with current state of gui
            %     if length(app.idInfo.threshold.name)>=app.probeNr &&  ~isempty(app.idInfo.threshold.name{app.probeNr})
            %         if isempty(app.edit_initials.Value)
            %             app.edit_initials.Value=app.idInfo.threshold.name{app.probeNr};
            %         else
            %             if ~strcmp(app.edit_initials.Value,app.idInfo.threshold.name{app.probeNr})
            %                 ButtonName = questdlg(['This file was processed by ' app.idInfo.threshold.name{app.probeNr} '. Change to your name?'],...
            %                     'Name Mismatch', 'Yes','No','Yes');
            %                 if strcmp(ButtonName,'No')
            %                     app.edit_initials = app.idInfo.threshold.name{app.probeNr};
            %                 end
            %             end
            %         end
            %     end
            % end

            if isfield(app.idInfo,'sampleFreq') 
                app.sampleFreq = app.idInfo.sampleFreq;
            else
                app.sampleFreq=NaN;
            end
            
            %set drop downs
            if ~isempty(app.idInfo.probes(app.probeNr).type)
                app.dropdown_probe.Value = app.idInfo.probes(app.probeNr).type;
                app.edit_area.Value = app.idInfo.probes(app.probeNr).area;
            end
           
            %enable selection of amplifier file
            app.button_getAmp.Enable=1;

            %disable probe selection
            app.spinner_probeNr.Enable=0;
                   
            
        end

        % Button pushed function: button_makeId
        function button_makeIdPushed(app, event)
            %call GUI to make new ID file
            settingsHandle=makeIDfile(app);
            waitfor(settingsHandle);
            figure(app.ThresholdUI)

            %to make bookkeeping easier
            app.probeNr=app.spinner_probeNr.Value;
            
            %general setup
            app.probe = app.idInfo.probes;
            app.nProbes= length(app.idInfo.probes);
            app.nChannels = sum([app.idInfo.probes.nChannels]);
            app.nProbeCh = app.idInfo.probes(app.probeNr).nChannels;

            app.processedProbe=zeros(1,app.nProbes);
            app.processedDate=cell(1,app.nProbes);
            app.processedName=cell(1,app.nProbes);

            app.sampleFreq=NaN;
            
            %set drop downs
            if ~isempty(app.idInfo.probes(app.probeNr).type)
                app.dropdown_probe.Value = app.idInfo.probes(app.probeNr).type;
                app.edit_area.Value = app.idInfo.probes(app.probeNr).area;
            end
           
            %enable selection of amplifier file
            app.button_getAmp.Enable=1;

            %disable probe selection
            app.spinner_probeNr.Enable=0;
        end

        % Button pushed function: button_getAmp
        function button_getAmpPushed(app, event)
            
            %get amplifier file
            [f,p] = uigetfile(fullfile(app.exptFolder,[app.exptName '_amplifier.dat']),'Select Amp file');
            if f==0
                return;
            end
            figure(app.ThresholdUI)
            app.ampFile=fullfile(p,f);
            app.edit_AmpFile.Value=app.ampFile;

            if isnan(app.sampleFreq)
                %read intan header (works even for blackrock files, once
                %the conversion has been run)
                headerName=replace(app.ampFile,'amplifier.dat','info.rhd');
                header=read_Intan_Header(headerName);
                app.sampleFreq = header.sample_rate;
            end
            
            %get duration data
            fileinfo = dir(app.ampFile);
            app.nTotalSamples = fileinfo.bytes/(2*app.nChannels);
            maxTime=floor((app.nTotalSamples-app.sampleFreq)/app.sampleFreq);
            app.slider_time.Limits=[0 maxTime];
            app.slider_time.MajorTicks=[0:100:maxTime];
            app.slider_time.Value=0;
            app.text_gotoTime.Limits=[0 maxTime];
            app.currentStart=0; %start for long time course [in sec]
            
            %initialize arrays
            app.badChannels = zeros(1,app.nProbeCh);
            app.thresholds = ones(1,app.nProbeCh).*(-200);
            app.chkChannels = zeros(1,app.nProbeCh);
            app.snippetRMS = [];
            
            %check whether file has already been thresholded - this allows for multiple threshold files to exist
            %if yes, load those settings
            threshFileName=fullfile(app.exptFolder,[app.exptName '_p' num2str(app.probeNr) '_threshold*.mat']);
            threshFiles=dir(threshFileName);
            
            if ~isempty(threshFiles)
                loadThresh=0;
                %more than 1 file?
                if length(threshFiles)>1
                    [f,p] = uigetfile('*_threshold*.mat','Select threshold file',app.exptFolder);
                    if ~isequal(p,0)
                        threshName=fullfile(p,f);
                        loadThresh=1;
                    end
                    figure(app.ThresholdUI)
                else
                    %ask whether we should load the existing file (maybe
                    %starting new?)
                    sel=uiconfirm(app.ThresholdUI,'Load existing threshold file?','Confirm',...
                        'Options',{'Yes','No'});
                    if strcmp(sel,'Yes')
                        threshName=fullfile(threshFiles(1).folder,threshFiles(1).name);
                        loadThresh=1;
                    end
                end

                if loadThresh==1
                    load(threshName,'thresholding')
                    app.thresholds = thresholding.thresholds;
                    app.badChannels = thresholding.badChannels;
                    app.butterParams = thresholding.butter;
                    app.chkChannels = thresholding.chkChannels;
                    app.edit_lowpass.Value = app.butterParams.lowPass;
                    app.edit_highpass.Value = app.butterParams.highPass;
                    %add initials (need to check in case of old threshold
                    %files)
                    if isfield(thresholding,'name')
                        app.edit_initials.Value=thresholding.name;
                    end
                    if isfield(thresholding,'suffix')
                        app.chkbox_suffix.Value=1;
                        app.edit_suffix.Value=thresholding.suffix;
                    end
                end
            end
            
            if ~isfield(app,'butterParams')
                app.butterParams.highPass = app.edit_highpass.Value;
                app.butterParams.lowPass = app.edit_lowpass.Value;
                [app.butterParams.b1, app.butterParams.a1] = butter(3, [app.butterParams.highPass/app.sampleFreq,app.butterParams.lowPass/app.sampleFreq]*2, 'bandpass');
            end


            %plot probe (if possible)
            app.currentChannel = 1;
            app.currentDisplayChannel = 1;
            app.refreshProbe();
            
            %plot snippets
            app.refreshSnippet(true);
            app.getNewTimeCourse();
            app.refreshTimeCourse();
            
            %disable reading new files
            app.button_makeId.Enable=0;
            app.button_getId.Enable=0;
            app.button_getAmp.Enable=0;

            %enable snippet selection etc
            app.slider_threshold.Value = app.thresholds(1);
            app.edit_threshold.Value = app.thresholds(1);
            app.check_channel.Value = app.chkChannels(1);
            app.slider_threshold.Enable=1;
            app.edit_threshold.Enable=1;
            app.text_minThreshold.Enable=1;
            app.text_maxThreshold.Enable=1;
            app.slider_time.Enable=1;
            if app.nProbeCh>1
                app.spinner_channel.Enable=1;
                app.spinner_channel.Limits=[1,app.nProbeCh];
                app.spinner_channel.Value=app.currentChannel;
            end
            app.button_rejectChan.Enable=1;
            app.button_save.Enable=1;
            app.button_setThresh.Enable=1;
            app.spinner_scaleThresh.Enable=1;
            app.edit_lengthThresh.Enable=1;
            app.edit_lengthThresh.Limits=[1,maxTime];
            app.toggle_startThresh.Enable=1;
            app.text_gotoTime.Enable=1;
            app.button_minussec.Enable=1;
            app.button_plussec.Enable=1;
            app.check_channel.Enable=1;
            %app.edit_lowpass.Enable=1;
            %app.edit_highpass.Enable=1;
            app.edit_area.Enable=1;
            app.dropdown_probe.Enable=1;
            app.button_loadThresh.Enable=1;
            app.button_setLocal.Enable=1;
            app.button_setNetw.Enable=1;

        end

        % Value changed function: dropdown_probe
        function dropdown_probeValueChanged(app, event)
            type = app.dropdown_probe.Value;

            if strcmp(type,'select')
                app.probe(app.probeNr) = [];
            else
                %run probe function to get info
                probewiring=feval(['probeConfig_' type]);
                app.probe(app.probeNr).channels = probewiring(:,1);
                app.probe(app.probeNr).x = probewiring(:,2);
                app.probe(app.probeNr).y = probewiring(:,3);
                app.probe(app.probeNr).z = probewiring(:,4);
                app.probe(app.probeNr).shaft = probewiring(:,5);

                app.probe(app.probeNr).type = type;
            end

            if ~isempty(app.probe)
                app.refreshProbe();
            else
                cla(app.axis_probe);
            end
        end

        % Value changed function: slider_threshold
        function slider_thresholdChanged(app, event)
            app.thresholds(app.currentChannel) = round(event.Value,2);
            app.edit_threshold.Value=app.thresholds(app.currentChannel);
            app.plotThreshold();
        end

        % Value changed function: edit_threshold
        function edit_thresholdValueChanged(app, event)
            app.thresholds(app.currentChannel) = event.Value;

            %make sure not to go out of bounds on the sliders
            if app.thresholds(app.currentChannel)<app.slider_threshold.Limits(1)
                app.slider_threshold.Limits(1)=floor(app.thresholds(app.currentChannel)/100)*100;
                app.text_minThreshold.Value=app.slider_threshold.Limits(1);
            end
            if app.thresholds(app.currentChannel)>app.slider_threshold.Limits(2)
                app.slider_threshold.Limits(2)=ceil(app.thresholds(app.currentChannel)/100)*100;
                app.text_maxThreshold.Value=app.slider_threshold.Limits(2);
            end

            app.slider_threshold.Value=event.Value;
            app.plotThreshold();
            
        end

        % Value changed function: text_minThreshold
        function text_minThresholdValueChanged(app, event)
            currentLimits=app.slider_threshold.Limits;
            currentLimits(1)=app.text_minThreshold.Value;
            tSteps=[round(currentLimits(1),-2):100:round(currentLimits(2),-2)]; %round with -2 rounds to 100
            app.slider_threshold.Limits=currentLimits;
            app.slider_threshold.MajorTicks=tSteps;
        end

        % Value changed function: text_maxThreshold
        function text_maxThresholdValueChanged(app, event)
            currentLimits=app.slider_threshold.Limits;
            currentLimits(2)=app.text_maxThreshold.Value;
            tSteps=[round(currentLimits(1),-2):100:round(currentLimits(2),-2)]; %round with -2 rounds to 100
            app.slider_threshold.Limits=currentLimits;
            app.slider_threshold.MajorTicks=tSteps;
        end

        % Value changed function: slider_time
        function slider_timeValueChanged(app, event)
            %update snippet
            app.refreshSnippet(true);
            
            %update text in 'go to' window
            app.text_gotoTime.Value=round(app.slider_time.Value);
            
            %figure out whether we need to renew the large time course
            if app.slider_time.Value<app.currentStart || app.slider_time.Value>app.currentStart+app.lengthTC
                %set start point to fall into the middle of the time course
                %window (only if no conflict with borders)
                app.currentStart=app.slider_time.Value-app.lengthTC/2;
                app.currentStart=max(0,app.currentStart);
                maxTime=floor((app.nTotalSamples-app.sampleFreq)/app.sampleFreq);
                app.currentStart=min(maxTime-app.lengthTC,app.currentStart);
                
                app.getNewTimeCourse();
                app.refreshTimeCourse();
            end
            app.refreshTimeMarker();
        end

        % Value changed function: text_gotoTime
        function text_gotoTimeValueChanged(app, event)
            app.slider_time.Value = app.text_gotoTime.Value;
            
            app.refreshSnippet(true);
            
            %figure out whether we need to renew the large time course
            if app.slider_time.Value<app.currentStart || app.slider_time.Value>app.currentStart+app.lengthTC
                %set start point to fall into the middle of the time course
                %window (only if no conflict with borders)
                app.currentStart=app.slider_time.Value-app.lengthTC/2;
                app.currentStart=max(0,app.currentStart);
                maxTime=floor((app.nTotalSamples-app.sampleFreq)/app.sampleFreq);
                app.currentStart=min(maxTime-app.lengthTC,app.currentStart);
                
                app.getNewTimeCourse();
                app.refreshTimeCourse();
            end
            app.refreshTimeMarker();
        end

        % Button down function: axis_timecourse
        function axis_timecourseButtonDown(app, event)
            newTP=round(app.axis_timecourse.CurrentPoint(1)); %move to new time point

            app.slider_time.Value=newTP; %update time slider
            app.text_gotoTime.Value=newTP;
            app.refreshSnippet(true); %update small snippet

            %figure out whether we need to renew the large time course
            if app.slider_time.Value<app.currentStart || app.slider_time.Value>app.currentStart+app.lengthTC
                %set start point to fall into the middle of the time course
                %window (only if no conflict with borders)
                app.currentStart=app.slider_time.Value-app.lengthTC/2;
                app.currentStart=max(0,app.currentStart);
                maxTime=floor((app.nTotalSamples-app.sampleFreq)/app.sampleFreq);
                app.currentStart=min(maxTime-app.lengthTC,app.currentStart);
                
                app.getNewTimeCourse();
                app.refreshTimeCourse();
            end
            app.refreshTimeMarker();

        end

        % Button pushed function: button_minussec
        function button_minussecButtonPushed(app, event)
            currentTime=app.slider_time.Value;
            newTime=max(currentTime-1,0);
            
            app.slider_time.Value=newTime;
            app.text_gotoTime.Value=round(newTime);
            
            app.refreshSnippet(true);
            
            %figure out whether we need to renew the large time course
            if app.slider_time.Value<app.currentStart || app.slider_time.Value>app.currentStart+app.lengthTC
                %set start point to fall into the middle of the time course
                %window (only if no conflict with borders)
                app.currentStart=app.slider_time.Value-app.lengthTC/2;
                app.currentStart=max(0,app.currentStart);
                maxTime=floor((app.nTotalSamples-app.sampleFreq)/app.sampleFreq);
                app.currentStart=min(maxTime-app.lengthTC,app.currentStart);
                
                app.getNewTimeCourse();
                app.refreshTimeCourse();
            end
            app.refreshTimeMarker();
        end

        % Button pushed function: button_plussec
        function button_plussecButtonPushed(app, event)
            currentTime=app.slider_time.Value;
            newTime=min(currentTime+1,app.slider_time.Limits(2));
            
            app.slider_time.Value=newTime;
            app.text_gotoTime.Value=round(newTime);
            
            app.refreshSnippet(true);
            
            %figure out whether we need to renew the large time course
            if app.slider_time.Value<app.currentStart || app.slider_time.Value>app.currentStart+app.lengthTC
                %set start point to fall into the middle of the time course
                %window (only if no conflict with borders)
                app.currentStart=app.slider_time.Value-app.lengthTC/2;
                app.currentStart=max(0,app.currentStart);
                maxTime=floor((app.nTotalSamples-app.sampleFreq)/app.sampleFreq);
                app.currentStart=min(maxTime-app.lengthTC,app.currentStart);
                
                app.getNewTimeCourse();
                app.refreshTimeCourse();
            end
            app.refreshTimeMarker();
        end

        % Value changed function: spinner_channel
        function spinner_channelValueChanged(app, event)
            app.currentChannel = app.spinner_channel.Value;
            app.refreshChannel();
            app.refreshSnippet(false);
            app.getNewTimeCourse();
            app.refreshTimeCourse();
        end

        % Value changed function: check_channel
        function check_channelValueChanged(app, event)
            app.chkChannels(app.currentChannel)=app.check_channel.Value;
        end

        % Button pushed function: button_rejectChan
        function button_rejectChanPushed(app, event)
            if app.badChannels(app.currentChannel)
                app.badChannels(app.currentChannel) = 0;
                app.button_rejectChan.Text = 'Reject Channel';
            else
                app.badChannels(app.currentChannel) = 1;
                app.button_rejectChan.Text = 'Accept Channel';
            end
            app.refreshSnippet(false)
        end

        % Button pushed function: button_setThresh
        function button_setThreshButtonPushed(app, event)
            %compute threshod for each channel, using segment length as
            %specified
            wb=uiprogressdlg(app.ThresholdUI,'Title','Please wait','Message','Computing thresholds','Indeterminate','on');
            
            segLength=round(app.edit_lengthThresh.Value*app.sampleFreq);
            if strcmp(app.toggle_startThresh.Value,'Start')
                startTime=0;
            else
                startTime=app.nTotalSamples-segLength;
            end
            
            %read data 
            DataFile = fopen(app.ampFile,'r');
            
            frewind(DataFile);
            fseek(DataFile,2*startTime*app.nChannels,'bof');
            data = fread(DataFile, [app.nChannels segLength], 'int16');
            
            if app.nProbes==2
                if app.probeNr==1
                    data=data(1:app.probe(1).nChannels,:);
                else
                    data=data(app.probe(1).nChannels+1:end,:);
                end
            end
            
            data=data';
            
            %filter
            tc=filter(app.butterParams.b1, app.butterParams.a1, data,[],1);
            tc=tc(app.offsetTC:end,:); %to avoid filter artefact at start
            
            %compute threshold
            chthresh = round(1.4826 * median(abs(tc - median(tc,1)),1));
            chthresh=-1*chthresh;
            
            fclose(DataFile);
            
            %now set thresholds
            app.thresholds=app.spinner_scaleThresh.Value.*chthresh;
            if app.thresholds(app.currentChannel)<app.slider_threshold.Limits(1)
                app.slider_threshold.Limits(1)=floor(app.thresholds(app.currentChannel)/100)*100;
            end
            if app.thresholds(app.currentChannel)>app.slider_threshold.Limits(2)
                app.slider_threshold.Limits(2)=ceil(app.thresholds(app.currentChannel)/100)*100;
            end
            app.slider_threshold.Value=app.thresholds(app.currentChannel);
            app.plotThreshold();
            close(wb);
        end

        % Button pushed function: button_loadThresh
        function button_loadThreshPushed(app, event)
            %load threshold from other (related) file
            [f,p] = uigetfile({'*_threshold.mat;*_threshold*.mat'},'Select threshold file',app.exptFolder);
            if p==0
                return;
            end
            figure(app.ThresholdUI)
            load(fullfile(p,f)); %generates variable thresholding
            app.thresholds=thresholding.thresholds;
            app.slider_threshold.Value=app.thresholds(app.currentChannel);
            app.plotThreshold();
        end

        % Button pushed function: button_setLocal
        function button_setLocalPushed(app, event)
            dirname=uigetdir(app.exptFolder);
            app.edit_localPath.Value=dirname;
            app.exptFolder=dirname;
        end

        % Button pushed function: button_setNetw
        function button_setNetwPushed(app, event)
            dirname=uigetdir(app.exptFolder);
            app.edit_netwPath.Value=dirname;
        end

        % Button pushed function: button_save
        function button_saveButtonPushed(app, event)
            %check that probe is fully set before saving
            if strcmp(app.dropdown_probe.Value,'select')
                uialert(app.ThresholdUI,'Set probe before saving','Error');
                return;
            end
            
            if isempty(app.edit_area.Value)
                uialert(app.ThresholdUI,'Set area before saving','Error');
                return;
            end
            
            if isempty(app.edit_initials.Value)
                uialert(app.ThresholdUI,'Set initials before saving','Error');
                return;
            end
            
            
            %this needs to be here (even if doesn't have anything to do
            %with spike sorting) because the next step may run multiple instances in parallel,
            %so cannot generate a directory
            if app.chkbox_suffix.Value==1
                spkFolder=['SpikeFiles_' app.edit_suffix.Value];
            else
                spkFolder='SpikeFiles';
            end
            if ~exist([app.exptFolder '/' spkFolder],'dir')
                mkdir([app.exptFolder '/' spkFolder]);
            end
            
            %save thresholds
            thresholding.thresholds = app.thresholds;
            thresholding.badChannels = app.badChannels;
            thresholding.chkChannels = app.chkChannels;
            
            %save settings
            thresholding.butter = app.butterParams;
            thresholding.name = app.edit_initials.Value;
            thresholding.date = date;
            thresholding.exptId=app.exptName;
            thresholding.probeNr=app.probeNr;
            if app.chkbox_suffix.Value==1
                thresholding.suffix=app.edit_suffix.Value;
            end
            

            %generate or update id file - id is generated here, app.idInfo
            %contains info from an id file if present before (could come
            %from database gui or from previous run of threshold gui)

            %copy into id first to make sure everything gets preserved
            id=struct;
            if ~isempty(app.idInfo)
                id=app.idInfo;
            end

            if ~isfield(id,'exptId') %this in principle has to exist, but to be on the safe side
                id.exptId = app.exptName;
            end
            
            %probes has to exist
            if ~strcmp(id.probes(app.probeNr).type,app.probe(app.probeNr).type)
                id.probes(app.probeNr)=app.probe(app.probeNr);
            end
           
            if ~isfield(id,'isBR')
                id.isBR = false;
            end
            if ~isfield(id,'sampleFreq')
                id.sampleFreq = app.sampleFreq;
            end 

            % id.threshold.name{app.probeNr} = app.edit_initials.Value;
            % id.threshold.processedProbe(app.probeNr)=1;
            % if isfield(id.threshold,'date') && ~iscell(id.threshold.date) %this is the case for some of the older data
            %     id.threshold=rmfield(id.threshold,'date');
            % end
            % id.threshold.date{app.probeNr}=date;
            if app.chkbox_suffix.Value==1
                outname=[app.exptName '_p' num2str(app.probeNr) '_threshold_' app.edit_suffix.Value '.mat'];
            else
                outname=[app.exptName '_p' num2str(app.probeNr) '_threshold.mat'];
            end

            save(fullfile(app.exptFolder,outname),'thresholding');
            save(fullfile(app.exptFolder,[app.exptName '_id.mat']),'id');
            if app.chkbox_copyToZ.Value==1
                fpart=strsplit(app.exptName,'_');
                zbase=app.edit_netwPath.Value;
                save(fullfile(zbase,fpart{1},app.exptName,[app.exptName '_id.mat']),'id');
                save(fullfile(zbase,fpart{1},app.exptName,outname),'thresholding');
            end
            
            msgbox('Thresholds and id saved.','Saved','non-modal')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThresholdUI = uifigure('Visible', 'off');
            app.ThresholdUI.Name = 'Threshold GUI';
            app.ThresholdUI.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ThresholdUI);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileinputPanel = uipanel(app.LeftPanel);
            app.FileinputPanel.Title = 'File Input';
            app.button_getId = uibutton(app.FileinputPanel, 'push');
            app.button_getId.ButtonPushedFcn = createCallbackFcn(app, @button_getIdPushed, true);
            app.button_getId.Text = 'Get';
            app.button_getAmp = uibutton(app.FileinputPanel, 'push');
            app.button_getAmp.ButtonPushedFcn = createCallbackFcn(app, @button_getAmpPushed, true);
            app.button_getAmp.Enable = 'off';
            app.button_getAmp.Text = 'Get';
            app.button_makeId = uibutton(app.FileinputPanel, 'push');
            app.button_makeId.ButtonPushedFcn = createCallbackFcn(app, @button_makeIdPushed, true);
            app.button_makeId.Text = 'Make';
            app.IDfileLabel_3 = uilabel(app.FileinputPanel);
            app.IDfileLabel_3.Text = 'ID file';
            app.AmpfileLabel = uilabel(app.FileinputPanel);
            app.AmpfileLabel.Text = 'Amp file';
            app.ProbeNrSpinnerLabel = uilabel(app.FileinputPanel);
            app.ProbeNrSpinnerLabel.Text = 'Probe Nr.';
            app.spinner_probeNr = uispinner(app.FileinputPanel);
            app.spinner_probeNr.Limits = [1 2];
            app.spinner_probeNr.Value = 1;
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.ProbeAreaLabel = uilabel(app.SettingsPanel);
            app.ProbeAreaLabel.Text = 'Probe/Area';
            app.dropdown_probe = uidropdown(app.SettingsPanel);
            app.dropdown_probe.Items = {};
            app.dropdown_probe.ValueChangedFcn = createCallbackFcn(app, @dropdown_probeValueChanged, true);
            app.dropdown_probe.Enable = 'off';
            app.dropdown_probe.Value = {};
            app.HighpassHzEditFieldLabel = uilabel(app.SettingsPanel);
            app.HighpassHzEditFieldLabel.Text = 'Highpass (Hz)';
            app.edit_highpass = uieditfield(app.SettingsPanel, 'numeric');
            app.edit_highpass.Limits = [1 Inf];
            app.edit_highpass.Enable = 'off';
            app.edit_highpass.Value = 250;
            app.LowpassHzEditFieldLabel = uilabel(app.SettingsPanel);
            app.LowpassHzEditFieldLabel.Text = 'Lowpass (Hz)';
            app.edit_lowpass = uieditfield(app.SettingsPanel, 'numeric');
            app.edit_lowpass.Limits = [100 Inf];
            app.edit_lowpass.Enable = 'off';
            app.edit_lowpass.Value = 5000;
            app.edit_area = uieditfield(app.SettingsPanel, 'text');
            app.edit_area.Enable = 'off';
            app.FileoutputPanel = uipanel(app.LeftPanel);
            app.FileoutputPanel.Title = 'File output';
            app.InitialsEditFieldLabel = uilabel(app.FileoutputPanel);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.edit_initials = uieditfield(app.FileoutputPanel, 'text');
            app.button_save = uibutton(app.FileoutputPanel, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @button_saveButtonPushed, true);
            app.button_save.Enable = 'off';
            app.button_save.Text = 'Save Thresholds';
            app.chkbox_copyToZ = uicheckbox(app.FileoutputPanel);
            app.chkbox_copyToZ.Text = 'Netw. base';
            app.chkbox_copyToZ.Value = true;
            app.LocalpathEditFieldLabel = uilabel(app.FileoutputPanel);
            app.LocalpathEditFieldLabel.Text = 'Local path';
            app.edit_localPath = uieditfield(app.FileoutputPanel, 'text');
            app.edit_netwPath = uieditfield(app.FileoutputPanel, 'text');
            app.edit_netwPath.Value = 'Z:\EphysNew\processedSpikes';
            app.button_setLocal = uibutton(app.FileoutputPanel, 'push');
            app.button_setLocal.ButtonPushedFcn = createCallbackFcn(app, @button_setLocalPushed, true);
            app.button_setLocal.Enable = 'off';
            app.button_setLocal.Text = 'Set';
            app.button_setNetw = uibutton(app.FileoutputPanel, 'push');
            app.button_setNetw.ButtonPushedFcn = createCallbackFcn(app, @button_setNetwPushed, true);
            app.button_setNetw.Enable = 'off';
            app.button_setNetw.Text = 'Set';
            app.chkbox_suffix = uicheckbox(app.FileoutputPanel);
            app.chkbox_suffix.Text = 'Add suffix';
            app.edit_suffix = uieditfield(app.FileoutputPanel, 'text');
            app.PresetthresholdPanel = uipanel(app.LeftPanel);
            app.PresetthresholdPanel.Title = 'Preset threshold';
            app.ScalefactorLabel = uilabel(app.PresetthresholdPanel);
            app.ScalefactorLabel.Text = 'Scale factor';
            app.spinner_scaleThresh = uispinner(app.PresetthresholdPanel);
            app.spinner_scaleThresh.Limits = [1 6];
            app.spinner_scaleThresh.Enable = 'off';
            app.spinner_scaleThresh.Value = 4;
            app.button_setThresh = uibutton(app.PresetthresholdPanel, 'push');
            app.button_setThresh.ButtonPushedFcn = createCallbackFcn(app, @button_setThreshButtonPushed, true);
            app.button_setThresh.Enable = 'off';
            app.button_setThresh.Text = 'Compute';
            app.SegmentlenghtsEditFieldLabel = uilabel(app.PresetthresholdPanel);
            app.SegmentlenghtsEditFieldLabel.Text = 'Segment lenght (s)';
            app.edit_lengthThresh = uieditfield(app.PresetthresholdPanel, 'numeric');
            app.edit_lengthThresh.Limits = [1 Inf];
            app.edit_lengthThresh.Enable = 'off';
            app.edit_lengthThresh.Value = 60;
            app.SegmentalignedtoSwitchLabel = uilabel(app.PresetthresholdPanel);
            app.SegmentalignedtoSwitchLabel.Text = 'Segment aligned to';
            app.toggle_startThresh = uiswitch(app.PresetthresholdPanel, 'rocker');
            app.toggle_startThresh.Items = {'Start', 'End'};
            app.toggle_startThresh.Enable = 'off';
            app.toggle_startThresh.Value = 'Start';
            app.button_loadThresh = uibutton(app.PresetthresholdPanel, 'push');
            app.button_loadThresh.ButtonPushedFcn = createCallbackFcn(app, @button_loadThreshPushed, true);
            app.button_loadThresh.Enable = 'off';
            app.button_loadThresh.Text = 'Load from file';
            app.RightPanel = uipanel(app.GridLayout);
            app.panel_signal = uipanel(app.RightPanel);
            app.axis_snippet = uiaxes(app.panel_signal);
            xlabel(app.axis_snippet, 'Time (s)')
            ylabel(app.axis_snippet, 'Signal (mV)')
            app.axis_spikePreview = uiaxes(app.panel_signal);
            xlabel(app.axis_spikePreview, 'Time (ms)')
            ylabel(app.axis_spikePreview, 'Signal (mV)')
            app.axis_timecourse = uiaxes(app.panel_signal);
            xlabel(app.axis_timecourse, 'Time (s)')
            ylabel(app.axis_timecourse, 'Signal')
            app.axis_timecourse.ButtonDownFcn = createCallbackFcn(app, @axis_timecourseButtonDown, true);
            app.ThresholdSliderLabel = uilabel(app.panel_signal);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.slider_threshold = uislider(app.panel_signal);
            app.slider_threshold.Limits = [-500 500];
            app.slider_threshold.ValueChangedFcn = createCallbackFcn(app, @slider_thresholdChanged, true);
            app.slider_threshold.Enable = 'off';
            app.slider_threshold.Value = -200;
            app.button_rejectChan = uibutton(app.panel_signal, 'push');
            app.button_rejectChan.ButtonPushedFcn = createCallbackFcn(app, @button_rejectChanPushed, true);
            app.button_rejectChan.Enable = 'off';
            app.button_rejectChan.Text = 'Reject Channel';
            app.SegmentstartsLabel = uilabel(app.panel_signal);
            app.SegmentstartsLabel.Text = {'Segment '; 'start (s)'};
            app.slider_time = uislider(app.panel_signal);
            app.slider_time.ValueChangedFcn = createCallbackFcn(app, @slider_timeValueChanged, true);
            app.slider_time.Enable = 'off';
            app.text_minThreshold = uieditfield(app.panel_signal, 'numeric');
            app.text_minThreshold.ValueChangedFcn = createCallbackFcn(app, @text_minThresholdValueChanged, true);
            app.text_minThreshold.Enable = 'off';
            app.text_minThreshold.Value = -500;
            app.text_maxThreshold = uieditfield(app.panel_signal, 'numeric');
            app.text_maxThreshold.ValueChangedFcn = createCallbackFcn(app, @text_maxThresholdValueChanged, true);
            app.text_maxThreshold.Enable = 'off';
            app.text_maxThreshold.Value = 500;
            app.GotosEditFieldLabel = uilabel(app.panel_signal);
            app.GotosEditFieldLabel.Text = 'Go to (s)';
            app.text_gotoTime = uieditfield(app.panel_signal, 'numeric');
            app.text_gotoTime.ValueChangedFcn = createCallbackFcn(app, @text_gotoTimeValueChanged, true);
            app.text_gotoTime.Enable = 'off';
            app.check_channel = uicheckbox(app.panel_signal);
            app.check_channel.ValueChangedFcn = createCallbackFcn(app, @check_channelValueChanged, true);
            app.check_channel.Enable = 'off';
            app.check_channel.Text = 'Checked channel';
            app.button_minussec = uibutton(app.panel_signal, 'push');
            app.button_minussec.ButtonPushedFcn = createCallbackFcn(app, @button_minussecButtonPushed, true);
            app.button_minussec.Enable = 'off';
            app.button_minussec.Text = '<';
            app.button_plussec = uibutton(app.panel_signal, 'push');
            app.button_plussec.ButtonPushedFcn = createCallbackFcn(app, @button_plussecButtonPushed, true);
            app.button_plussec.Enable = 'off';
            app.button_plussec.Text = '>';
            app.ChannelSpinnerLabel = uilabel(app.panel_signal);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.spinner_channel = uispinner(app.panel_signal);
            app.spinner_channel.Limits = [1 64];
            app.spinner_channel.ValueChangedFcn = createCallbackFcn(app, @spinner_channelValueChanged, true);
            app.spinner_channel.Value = 1;
            app.edit_threshold = uieditfield(app.panel_signal, 'numeric');
            app.edit_threshold.ValueChangedFcn = createCallbackFcn(app, @edit_thresholdValueChanged, true);
            app.edit_threshold.Enable = 'off';
            app.edit_threshold.Value = -200;
            app.ThvalueEditFieldLabel = uilabel(app.panel_signal);
            app.ThvalueEditFieldLabel.Text = 'Th. value';
            app.panel_probe = uipanel(app.RightPanel);
            app.axis_probe = uiaxes(app.panel_probe);
            xlabel(app.axis_probe, 'X')
            ylabel(app.axis_probe, 'Y')
            app.IDfileLabel = uilabel(app.RightPanel);
            app.IDfileLabel.Text = 'Exp name';
            app.edit_ExpName = uieditfield(app.RightPanel, 'text');
            app.edit_ExpName.Editable = 'off';
            app.IDfileLabel_2 = uilabel(app.RightPanel);
            app.IDfileLabel_2.Text = 'Amp file';
            app.edit_AmpFile = uieditfield(app.RightPanel, 'text');
            app.edit_AmpFile.Editable = 'off';
            % Show the figure after all components are created
            app.ThresholdUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thresholdGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThresholdUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThresholdUI)
        end
    end
end