classdef thresholdGuiSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        SettingsTable  matlab.ui.control.Table
        DoneButton     matlab.ui.control.Button
        LoadButton     matlab.ui.control.Button
        SaveButton     matlab.ui.control.Button
        NoteSavethesettingsfileinalocalfolderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        thresholdGui % handle to main app
        settings
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %get handle to main app
            app.thresholdGui=mainapp;
            
            %initialize settings
            app.settings.name='Nielsen Lab';
            app.settings.email='nielsenlabmbi@gmail.com';
            app.settings.remoteIP='172.30.6.202';
            app.settings.doRemote=1;
            app.settings.filterHighPass = 250;
            app.settings.filterLowPass = 5000;
            app.settings.expFolder = 'z:\ephys_new';


            %turn into table
            parname=fieldnames(app.settings);
            for i=1:length(parname)
                parval{i}=app.settings.(parname{i});
            end
           
            t=table(parname,parval');
            app.SettingsTable.Data=t;

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %return settings
            %get settings from table
            currSettings=app.SettingsTable.Data;
            
            %reconstruct structure
            pn=currSettings.parname;
            for i=1:length(pn)
                app.settings.(pn{i})=currSettings{i,2}{:};
            end
            %disp(app.settings)
            getSettings(app.thresholdGui,app.settings)
            
            %close app
            delete(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %load settings file
            [file,path]=uigetfile('*.mat','Select settings file');
            if ~isequal(file,0)
                load(fullfile(path,file));
                
                %should generate settings; set table accordingly
                app.settings=ThresholdSettings;
               
                parname=fieldnames(app.settings);
                for i=1:length(parname)
                    parval{i}=app.settings.(parname{i});
                end
                
                t=table(parname,parval');
                app.SettingsTable.Data=t;
                
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %save settings file
            [file,path] = uiputfile('*.mat','Save settings file');
            if ~isequal(file,0)
                %get settings and parse into structure
                currSettings=app.SettingsTable.Data;
                pn=currSettings.parname;
                ThresholdSettings=app.settings;
                for i=1:length(pn)
                    ThresholdSettings.(pn{i})=currSettings{i,2}{:};
                end
                save(fullfile(path,file),'ThresholdSettings');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsTable = uitable(app.UIFigure);
            app.SettingsTable.ColumnName = {'Setting'; 'Value'};
            app.SettingsTable.RowName = {};
            app.SettingsTable.ColumnEditable = [false true];
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load settings';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save settings';
            app.NoteSavethesettingsfileinalocalfolderLabel = uilabel(app.UIFigure);
            app.NoteSavethesettingsfileinalocalfolderLabel.Text = 'Note: Save the settings file in a local folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thresholdGuiSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end