classdef computeLatencies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LatencyUI                      matlab.ui.Figure
        InputPanel                     matlab.ui.container.Panel
        BinwidthmsEditFieldLabel       matlab.ui.control.Label
        Label1                         matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        FilenameEdit                   matlab.ui.control.EditField
        FilenameButton                 matlab.ui.control.Button
        FiletypeSwitch                 matlab.ui.control.Switch
        FiletypeSwitchLabel            matlab.ui.control.Label
        ExclOutlierSel                 matlab.ui.control.CheckBox
        ConditionsSwitch               matlab.ui.control.Switch
        ConditionstouseSwitchLabel     matlab.ui.control.Label
        NrBinSpin                      matlab.ui.control.Spinner
        BinWidthEdit                   matlab.ui.control.NumericEditField
        ComputeButton                  matlab.ui.control.Button
        NrofbinswincreaseSpinnerLabel  matlab.ui.control.Label
        FilenameEditFieldLabel         matlab.ui.control.Label
        InspectPanel                   matlab.ui.container.Panel
        Label3                         matlab.ui.control.Label
        LatencymsEditFieldLabel        matlab.ui.control.Label
        UnitSpin                       matlab.ui.control.Spinner
        LatencyEdit                    matlab.ui.control.NumericEditField
        EarlierButton                  matlab.ui.control.Button
        LaterButton                    matlab.ui.control.Button
        ExclUnitSel                    matlab.ui.control.CheckBox
        UnitorChLabel                  matlab.ui.control.Label
        PlotAxes                       matlab.ui.control.UIAxes
        CancelButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        spkTimes % spike times for all channels/units
        respLat %latency per channel/unit
        cumResp %cumulative response used to compute latency
        stdBase %std dev criterion
        binVec %time points
        unitExclude %exclude channel or unit; 1 = exclude
        
    end
    
    methods (Access = private)
        
        function plotChannel(app)
            chId=app.UnitSpin.Value;
            Ntrials=length(app.spkTimes{chId});

            cla(app.PlotAxes);
            for i=1:length(app.spkTimes{chId})
                plot(app.PlotAxes,app.spkTimes{chId}{i},ones(size(app.spkTimes{chId}{i}))*i,'k|');
                hold(app.PlotAxes,'on')
            end
            set(app.PlotAxes,'YLim',[0 Ntrials+1])
            plot(app.PlotAxes,app.binVec(1:end-1),app.cumResp(chId,:)/max(app.cumResp(chId,:))*(Ntrials+1),'ro-');
            xline(app.PlotAxes,app.respLat(chId));

            %update latency field
            if ~isnan(app.respLat(chId))
                app.LatencyEdit.Value=app.respLat(chId);
            else
                app.LatencyEdit.Value=-1;
            end

            %update exclusion marker
            app.ExclUnitSel.Value=app.unitExclude(chId);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FilenameButton
        function FilenameButtonPushed(app, event)
            %update filename
            [f,p] = uigetfile('*Trial.mat','Select data file');
            if f==0
                return;
            end
            figure(app.LatencyUI)
            app.FilenameEdit.Value=fullfile(p,f);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            %compute latency for every unit
            
            %load file
            load(app.FilenameEdit.Value);
            switch app.FiletypeSwitch.Value
                case 'SU'
                    if ~exist('SU','var')
                        uialert(app.LatencyUI,'SU not loaded','Error');
                        return;
                    end
                   
                    %never exclude trials for SU
                    trialExclude=zeros(size(SUinfo.triallist));

                    %transfer
                    data=SU;
                    dataInfo=SUinfo;
                    
                case 'MU'
                    if ~exist('MUThresh','var')
                        uialert(app.LatencyUI,'SU not loaded','Error');
                        return;
                    end

                    if app.ExclOutlierSel.Value==1
                        trialExclude=MUThreshFlagOutlier(MUThresh,MUThreshInfo,0);
                    else
                        trialExclude=zeros(size(MUThreshInfo.triallist));
                    end
                    
                    data=MUThresh;
                    dataInfo=MUThreshInfo;        
            end

            %limit unit/channel spinner
            app.UnitSpin.Limits=[1,length(data)];

            %initialize exclusion vector
            app.unitExclude=zeros(length(data),1);

            %time vector
            binWidth=app.BinWidthEdit.Value; % bin width in ms
            startBin=ceil(-dataInfo.baseTime*1000/binWidth)*binWidth; %need multiple of binWidth to make 0 an edge
            stopBin=floor(dataInfo.stimTime*1000/binWidth)*binWidth;
            app.binVec=startBin:binWidth:stopBin;

    
            %if necessary, determine average response for different
            %conditions (to determine best for every channel)
            if strcmp(app.ConditionsSwitch.Value,'Preferred') & ~isfield(data,'avgRateCond')
                %get lists of conditions
                condId=unique(dataInfo.triallist);
                %remove blank from options
                condId=condId(condId~=dataInfo.blankId);
                for i=1:length(condId)
                    %find trials
                    idx=find(dataInfo.triallist==condId(i) & trialExclude~=1);

                    for u=1:length(data)
                        data(u).avgRateCond(i)=mean(data(u).stimFrate(idx)-data(u).baseFrate(idx));
                    end
                end
            end

            %loop through units/channels and determine latency
            app.respLat=NaN(length(data),1);
            app.cumResp=zeros(length(data),length(app.binVec)-1);
            app.stdBase=NaN(length(data),1);
            for u=1:length(data)
                %figure out which trials
                switch app.ConditionsSwitch.Value
                    case 'All'
                        trialidx=find(dataInfo.triallist~=dataInfo.blankId & trialExclude~=1);
                    case 'Preferred'
                        [~,maxCond]=max(data(u).avgRateCond); %channel specific preferred condition
                        trialidx=find(dataInfo.triallist==maxCond & trialExclude~=1);
                end
                NTrials=length(trialidx);

                %compute timecourse for best condition
                app.spkTimes{u}={};
                N=zeros(NTrials,length(app.binVec)-1);
                for i=1:NTrials
                    app.spkTimes{u}{i}=data(u).spktimes{trialidx(i)};
                    N(i,:)=histcounts(app.spkTimes{u}{i},app.binVec);
                end

                %mean and std values for bins for baseline
                avgN=mean(N,1)/(binWidth/1000); %in spikes/s
                avgBase=mean(avgN(app.binVec<0));
                app.stdBase(u)=std(avgN(app.binVec<0));

                %cum sum and its derivative
                cumN=cumsum(avgN-avgBase); %cumsum(1): value 1 in input; length(cumsum)=length(input)
                app.cumResp(u,:)=cumN; %copy for later
                diffN=diff(cumN); %diff(1): difference between values 2 and 1 in the input; length = length(input)-1
                cumN=cumN(1:end-1); %reduce by one to match diff
            
                %latency: cumsum over threshold, followed by N increasing
                %bins; example for 2 bins: cumN>thresh + diffN>0 + diffN(2:end)>0 
                %(no offset needed for first diffN since diffN(1) contains
                %difference between cumN(1) and cumN(2); next one needs to
                %be shifted to get one more increasing bin
                %to keep all matrices the same, we use circshift
                %to generate all of the shifted copies
                %also add that time has to be larger than 0 to clean up
                NShift=app.NrBinSpin.Value;
                diffNshift=zeros(NShift,length(diffN));
                for i=0:NShift-1 %first one is not shifted, see above
                    diffNshift(i+1,:)=circshift(diffN,-i);
                end
                latBin=find(cumN>2*app.stdBase(u) & all(diffNshift>0,1) & app.binVec(1:end-2)>0,1); %return only first instance
                
                if ~isempty(latBin)
                    app.respLat(u)=app.binVec(latBin);
                end
                
            end
           
            %plot
            app.plotChannel();

        end

        % Value changed function: UnitSpin
        function UnitSpinValueChanged(app, event)
            app.plotChannel();
            
        end

        % Button pushed function: LaterButton
        function LaterButtonPushed(app, event)
            chId=app.UnitSpin.Value;
            
            %find the next possible option for latency (if any)
            cumN=app.cumResp(chId,:);
            diffN=diff(cumN); %diff(1): difference between values 2 and 1 in the input; length = length(input)-1
            cumN=cumN(1:end-1); %reduce by one to match diff

            NShift=app.NrBinSpin.Value;
            diffNshift=zeros(NShift,length(diffN));
            for i=0:NShift-1 %first one is not shifted, see above
                diffNshift(i+1,:)=circshift(diffN,-i);
            end
            oldLatBin=find(app.binVec==app.respLat(chId));
            latBin=find(cumN>2*app.stdBase(chId) & all(diffNshift>0,1) & app.binVec(1:end-2)>oldLatBin,1); %return only first instance past the current one

            if ~isempty(latBin)
                app.respLat(chId)=app.binVec(latBin);
            end

            %plot
            app.plotChannel();
        end

        % Button pushed function: EarlierButton
        function EarlierButtonPushed(app, event)
             chId=app.UnitSpin.Value;
            
            %find the next possible option for latency (if any)
            cumN=app.cumResp(chId,:);
            diffN=diff(cumN); %diff(1): difference between values 2 and 1 in the input; length = length(input)-1
            cumN=cumN(1:end-1); %reduce by one to match diff

            NShift=app.NrBinSpin.Value;
            diffNshift=zeros(NShift,length(diffN));
            for i=0:NShift-1 %first one is not shifted, see above
                diffNshift(i+1,:)=circshift(diffN,-i);
            end
            oldLatBin=find(app.binVec==app.respLat(chId));
            latBin=find(cumN>2*app.stdBase(chId) & all(diffNshift>0,1) & app.binVec(1:end-2)<oldLatBin,1,'last'); %return only first instance past the current one

            if ~isempty(latBin)
                app.respLat(chId)=app.binVec(latBin);
            end

            %plot
            app.plotChannel();
        end

        % Value changed function: ExclUnitSel
        function ExclUnitSelValueChanged(app, event)
            chId=app.UnitSpin.Value;
            app.unitExclude(chId)=app.ExclUnitSel.Value;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            selection = uiconfirm(app.LatencyUI, ...
                "Close GUI?","Confirm Close", ...
                "Icon","warning");
            if strcmp(selection,'OK')
                delete(app)
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            switch app.FiletypeSwitch.Value
                case 'SU'
                    fname=replace(app.FilenameEdit.Value,'Trial','Latency');
                    
                    SUlatency=app.respLat;
                    SUlatency(app.unitExclude==1)=NaN;
                    SUlatencyInfo.Condition=app.ConditionsSwitch.Value;
                    SUlatencyInfo.NrBins=app.NrBinSpin.Value;
                    SUlatencyInfo.BinWidth=app.BinWidthEdit.Value;
                   
                    save(fname,'SUlatency','SUlatencyInfo');
                case 'MU'
                    fname=replace(app.FilenameEdit.Value,'ThreshTrial','Latency');
                    
                    MUlatency=app.respLat;
                    MUlatency(app.unitExclude==1)=NaN;
                    MUlatencyInfo.Condition=app.ConditionsSwitch.Value;
                    MUlatencyInfo.NrBins=app.NrBinSpin.Value;
                    MUlatencyInfo.BinWidth=app.BinWidthEdit.Value;
                    MUlatencyInfo.ExcludeOutliers=app.ExclOutlierSel.Value;

                    save(fname,'MUlatency','MUlatencyInfo');                   
            end
            uialert(app.LatencyUI,'Latencies saved!','Saved','Icon','success')
           
        end

        % Close request function: LatencyUI
        function LatencyUICloseRequest(app, event)
            selection = uiconfirm(app.LatencyUI, ...
                "Close GUI?","Confirm Close", ...
                "Icon","warning");
            if strcmp(selection,'OK')
                delete(app)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LatencyUI = uifigure('Visible', 'off');
            app.LatencyUI.Name = 'Latency';
            app.LatencyUI.CloseRequestFcn = createCallbackFcn(app, @LatencyUICloseRequest, true);
            app.SaveButton = uibutton(app.LatencyUI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.LatencyUI, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.InspectPanel = uipanel(app.LatencyUI);
            app.InspectPanel.Title = 'Inspect';
            app.PlotAxes = uiaxes(app.InspectPanel);
            xlabel(app.PlotAxes, 'time (ms)')
            ylabel(app.PlotAxes, 'Trials')
            zlabel(app.PlotAxes, 'Z')
            app.UnitorChLabel = uilabel(app.InspectPanel);
            app.UnitorChLabel.Text = 'Unit or Ch';
            app.ExclUnitSel = uicheckbox(app.InspectPanel);
            app.ExclUnitSel.ValueChangedFcn = createCallbackFcn(app, @ExclUnitSelValueChanged, true);
            app.ExclUnitSel.Text = 'Exclude';
            app.LaterButton = uibutton(app.InspectPanel, 'push');
            app.LaterButton.ButtonPushedFcn = createCallbackFcn(app, @LaterButtonPushed, true);
            app.LaterButton.Text = '>';
            app.EarlierButton = uibutton(app.InspectPanel, 'push');
            app.EarlierButton.ButtonPushedFcn = createCallbackFcn(app, @EarlierButtonPushed, true);
            app.EarlierButton.Text = '<';
            app.LatencyEdit = uieditfield(app.InspectPanel, 'numeric');
            app.LatencyEdit.Editable = 'off';
            app.UnitSpin = uispinner(app.InspectPanel);
            app.UnitSpin.Limits = [1 Inf];
            app.UnitSpin.ValueChangedFcn = createCallbackFcn(app, @UnitSpinValueChanged, true);
            app.UnitSpin.Value = 1;
            app.LatencymsEditFieldLabel = uilabel(app.InspectPanel);
            app.LatencymsEditFieldLabel.Text = 'Latency (ms)';
            app.Label3 = uilabel(app.InspectPanel);
            app.Label3.Text = 'Move to next candidate';
            app.InputPanel = uipanel(app.LatencyUI);
            app.InputPanel.Title = 'Input';
            app.FilenameEditFieldLabel = uilabel(app.InputPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.NrofbinswincreaseSpinnerLabel = uilabel(app.InputPanel);
            app.NrofbinswincreaseSpinnerLabel.Text = 'Nr of bins w/ increase';
            app.ComputeButton = uibutton(app.InputPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.BinWidthEdit = uieditfield(app.InputPanel, 'numeric');
            app.BinWidthEdit.Limits = [1 Inf];
            app.BinWidthEdit.Value = 10;
            app.NrBinSpin = uispinner(app.InputPanel);
            app.NrBinSpin.Limits = [1 Inf];
            app.NrBinSpin.Value = 2;
            app.ConditionstouseSwitchLabel = uilabel(app.InputPanel);
            app.ConditionstouseSwitchLabel.Text = 'Conditions to use';
            app.ConditionsSwitch = uiswitch(app.InputPanel, 'slider');
            app.ConditionsSwitch.Items = {'All', 'Preferred'};
            app.ConditionsSwitch.Value = 'Preferred';
            app.ExclOutlierSel = uicheckbox(app.InputPanel);
            app.ExclOutlierSel.Text = 'Exclude outlier trials';
            app.ExclOutlierSel.Value = true;
            app.FiletypeSwitchLabel = uilabel(app.InputPanel);
            app.FiletypeSwitchLabel.Text = 'File type';
            app.FiletypeSwitch = uiswitch(app.InputPanel, 'slider');
            app.FiletypeSwitch.Items = {'SU', 'MU'};
            app.FiletypeSwitch.Value = 'MU';
            app.FilenameButton = uibutton(app.InputPanel, 'push');
            app.FilenameButton.ButtonPushedFcn = createCallbackFcn(app, @FilenameButtonPushed, true);
            app.FilenameButton.Text = 'Find';
            app.FilenameEdit = uieditfield(app.InputPanel, 'text');
            app.Label2 = uilabel(app.InputPanel);
            app.Label2.Text = {'Note: Only SUTrial or'; 'MUThreshTrial files'};
            app.Label1 = uilabel(app.InputPanel);
            app.Label1.Text = 'For MU:';
            app.BinwidthmsEditFieldLabel = uilabel(app.InputPanel);
            app.BinwidthmsEditFieldLabel.Text = 'Bin width (ms)';
            % Show the figure after all components are created
            app.LatencyUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = computeLatencies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LatencyUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LatencyUI)
        end
    end
end